classdef FSPModelReducer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridSizeEditField               matlab.ui.control.NumericEditField
        GridSizeEditFieldLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ErrorVersusTimeTab              matlab.ui.container.Tab
        reductionTimeTable              matlab.ui.control.Table
        errorVsTime                     matlab.ui.control.UIAxes
        MarginalDistributionsTab        matlab.ui.container.Tab
        TimetoshowSlider                matlab.ui.control.Slider
        TimetoshowSliderLabel           matlab.ui.control.Label
        SpeciestoshowListBox            matlab.ui.control.ListBox
        SpeciestoshowListBoxLabel       matlab.ui.control.Label
        marginalDistributions           matlab.ui.control.UIAxes
        ErrorVersusParameterTab         matlab.ui.container.Tab
        valueLabel                      matlab.ui.control.Label
        rangemaxEditField               matlab.ui.control.NumericEditField
        rangemaxEditFieldLabel          matlab.ui.control.Label
        rangeminEditField               matlab.ui.control.NumericEditField
        rangeminEditFieldLabel          matlab.ui.control.Label
        ParametertoTestDropDown         matlab.ui.control.DropDown
        ParametertoTestDropDownLabel    matlab.ui.control.Label
        SweepOverModelParametersButton  matlab.ui.control.Button
        errorVsPars                     matlab.ui.control.UIAxes
        plotStyle                       matlab.ui.control.DropDown
        PlotStyleDropDownLabel          matlab.ui.control.Label
        HoldFiguresCheckBox             matlab.ui.control.CheckBox
        ErrorTypeDropDown               matlab.ui.control.DropDown
        ErrorTypeDropDownLabel          matlab.ui.control.Label
        CompareAllButton                matlab.ui.control.Button
        TryModelReductionButton         matlab.ui.control.Button
        NumberofBasisVectorsEditField   matlab.ui.control.NumericEditField
        NumberofBasisVectorsEditFieldLabel  matlab.ui.control.Label
        ModelReductionTypeDropDown      matlab.ui.control.DropDown
        ModelReductionTypeDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SSITapp;
        fspSoln = [];
        reducedSolutions = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSITapp)
            app.SSITapp = SSITapp;
            app.ParametertoTestDropDown.Items = ...
                SSITapp.ReactionsTabOutputs.parameters(:,1);
            app.ParametertoTestDropDown.Value = ...
                SSITapp.ReactionsTabOutputs.parameters(1,1);
            app.valueLabel.Text = [app.ParametertoTestDropDown.Value,' = ',...
                num2str(SSITapp.ReactionsTabOutputs.parameters{1,2})];
            app.rangemaxEditField.Value = SSITapp.ReactionsTabOutputs.parameters{1,2}*2;
            app.rangeminEditField.Value = SSITapp.ReactionsTabOutputs.parameters{1,2}/2;          
            app.GridSizeEditField.Enable = 0;
            app.NumberofBasisVectorsEditField.Enable = 1;

        end

        % Value changed function: ModelReductionTypeDropDown
        function ModelReductionTypeDropDownValueChanged(app, event)
            switch app.ModelReductionTypeDropDown.Value
                case {'Eigen Decomposition','Eigen Decomposition Initial','Proper Orthogonal Decomposition'...
                        'Balanced Model Truncation (HSV)','Dynamic Mode Decomposition',...
                        'Radial Basis Functions'}
                    app.GridSizeEditField.Enable = 0;
                    app.NumberofBasisVectorsEditField.Enable = 1;
                case {'Linear State Lumping','Logarithmic State Lumping','Log Lump QSSA'}
                    app.GridSizeEditField.Enable = 1;
                    app.NumberofBasisVectorsEditField.Enable = 0;
            end

        end

        % Button pushed function: TryModelReductionButton
        function TryModelReductionButtonPushed(app, event)
            j = find(strcmp(app.ModelReductionTypeDropDown.Items,app.ModelReductionTypeDropDown.Value));
            app.reducedSolutions{j} = ssit.fsp_model_reduction.fsp_model_reduction(app,{app.ModelReductionTypeDropDown.Value});
        end

        % Value changed function: HoldFiguresCheckBox
        function HoldFiguresCheckBoxValueChanged(app, event)
            if app.HoldFiguresCheckBox.Value
                hold(app.errorVsTime,'on')
                hold(app.marginalDistributions,'on')
                hold(app.errorVsPars,'on')
            else
                hold(app.errorVsTime,'off')
                hold(app.marginalDistributions,'off')                
                hold(app.errorVsPars,'off')                
            end       
        end

        % Button pushed function: CompareAllButton
        function CompareAllButtonPushed(app, event)
            cla(app.errorVsTime)
            hold(app.errorVsTime,'on')
            hold(app.marginalDistributions,'on')  
            approaches = app.ModelReductionTypeDropDown.Items;
            ssit.fsp_model_reduction.fsp_model_reduction(app,approaches);
            legend(app.errorVsTime,approaches,'Location','eastoutside')
        end

        % Button pushed function: SweepOverModelParametersButton
        function SweepOverModelParametersButtonPushed(app, event)
            approaches = app.ModelReductionTypeDropDown.Value;
            ssit.fsp_model_reduction.fsp_model_reduction(app,{approaches},...
                app.ParametertoTestDropDown.Value,...
                app.rangeminEditField.Value,...
                app.rangemaxEditField.Value);
         
        end

        % Value changed function: ParametertoTestDropDown
        function ParametertoTestDropDownValueChanged(app, event)
            parNames = app.SSITapp.ReactionsTabOutputs.parameters(:,1);
            sweepParNumber = find(strcmp(parNames,app.ParametertoTestDropDown.Value));
            app.valueLabel.Text = [app.ParametertoTestDropDown.Value,' = ',...
                num2str(app.SSITapp.ReactionsTabOutputs.parameters{sweepParNumber,2})];
            app.rangemaxEditField.Value = app.SSITapp.ReactionsTabOutputs.parameters{sweepParNumber,2}*2;
            app.rangeminEditField.Value = app.SSITapp.ReactionsTabOutputs.parameters{sweepParNumber,2}/2;                      
        end

        % Value changed function: plotStyle
        function plotStyleValueChanged(app, event)
            switch(app.plotStyle.Value)
                case 'linear'
                    app.errorVsTime.YScale = 'linear';
                case 'logarithmic'
                    app.errorVsTime.YScale = 'log';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelReductionTypeDropDownLabel = uilabel(app.UIFigure);
            app.ModelReductionTypeDropDownLabel.Text = 'Model Reduction Type';
            app.ModelReductionTypeDropDown = uidropdown(app.UIFigure);
            app.ModelReductionTypeDropDown.Items = {'Eigen Decomposition', 'Eigen Decomposition Initial', 'Linear State Lumping', 'Logarithmic State Lumping', 'Log Lump QSSA', 'Proper Orthogonal Decomposition', 'Dynamic Mode Decomposition', 'Radial Basis Functions'};
            app.ModelReductionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelReductionTypeDropDownValueChanged, true);
            app.ModelReductionTypeDropDown.Value = 'Eigen Decomposition';
            app.NumberofBasisVectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBasisVectorsEditFieldLabel.Text = 'Number of Basis Vectors';
            app.NumberofBasisVectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBasisVectorsEditField.Limits = [1 Inf];
            app.NumberofBasisVectorsEditField.RoundFractionalValues = 'on';
            app.NumberofBasisVectorsEditField.Value = 20;
            app.TryModelReductionButton = uibutton(app.UIFigure, 'push');
            app.TryModelReductionButton.ButtonPushedFcn = createCallbackFcn(app, @TryModelReductionButtonPushed, true);
            app.TryModelReductionButton.Text = 'Try Model Reduction';
            app.CompareAllButton = uibutton(app.UIFigure, 'push');
            app.CompareAllButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllButtonPushed, true);
            app.CompareAllButton.Text = 'Compare All';
            app.ErrorTypeDropDownLabel = uilabel(app.UIFigure);
            app.ErrorTypeDropDownLabel.Text = 'Error Type';
            app.ErrorTypeDropDown = uidropdown(app.UIFigure);
            app.ErrorTypeDropDown.Items = {'One Norm', 'KLD'};
            app.ErrorTypeDropDown.Value = 'One Norm';
            app.HoldFiguresCheckBox = uicheckbox(app.UIFigure);
            app.HoldFiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldFiguresCheckBoxValueChanged, true);
            app.HoldFiguresCheckBox.Text = 'Hold Figures';
            app.PlotStyleDropDownLabel = uilabel(app.UIFigure);
            app.PlotStyleDropDownLabel.Text = 'Plot Style';
            app.plotStyle = uidropdown(app.UIFigure);
            app.plotStyle.Items = {'linear', 'logarithmic'};
            app.plotStyle.ValueChangedFcn = createCallbackFcn(app, @plotStyleValueChanged, true);
            app.plotStyle.Value = 'linear';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ErrorVersusTimeTab = uitab(app.TabGroup);
            app.ErrorVersusTimeTab.Title = 'Error Versus Time';
            app.errorVsTime = uiaxes(app.ErrorVersusTimeTab);
            title(app.errorVsTime, 'Error Versus Time')
            xlabel(app.errorVsTime, 'time')
            ylabel(app.errorVsTime, 'error')
            zlabel(app.errorVsTime, 'Z')
            app.reductionTimeTable = uitable(app.ErrorVersusTimeTab);
            app.reductionTimeTable.ColumnName = {'Approach'; 'Reduction Time'; 'Solution Time'; 'Speed up'; 'Accuracy'};
            app.reductionTimeTable.RowName = {};
            app.MarginalDistributionsTab = uitab(app.TabGroup);
            app.MarginalDistributionsTab.Title = 'Marginal Distributions';
            app.marginalDistributions = uiaxes(app.MarginalDistributionsTab);
            title(app.marginalDistributions, 'Marginal Distributions')
            xlabel(app.marginalDistributions, 'Species Count')
            ylabel(app.marginalDistributions, 'Probability')
            zlabel(app.marginalDistributions, 'Z')
            app.SpeciestoshowListBoxLabel = uilabel(app.MarginalDistributionsTab);
            app.SpeciestoshowListBoxLabel.Text = 'Species to show';
            app.SpeciestoshowListBox = uilistbox(app.MarginalDistributionsTab);
            app.SpeciestoshowListBox.Multiselect = 'on';
            app.SpeciestoshowListBox.Value = {'Item 1'};
            app.TimetoshowSliderLabel = uilabel(app.MarginalDistributionsTab);
            app.TimetoshowSliderLabel.Text = 'Time to show ';
            app.TimetoshowSlider = uislider(app.MarginalDistributionsTab);
            app.ErrorVersusParameterTab = uitab(app.TabGroup);
            app.ErrorVersusParameterTab.Title = 'Error Versus Parameter';
            app.errorVsPars = uiaxes(app.ErrorVersusParameterTab);
            title(app.errorVsPars, 'Error Versus Parameter')
            xlabel(app.errorVsPars, 'parameter')
            ylabel(app.errorVsPars, 'error')
            zlabel(app.errorVsPars, 'Z')
            app.SweepOverModelParametersButton = uibutton(app.ErrorVersusParameterTab, 'push');
            app.SweepOverModelParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SweepOverModelParametersButtonPushed, true);
            app.SweepOverModelParametersButton.Text = 'Sweep Over Model Parameters';
            app.ParametertoTestDropDownLabel = uilabel(app.ErrorVersusParameterTab);
            app.ParametertoTestDropDownLabel.Text = 'Parameter to Test';
            app.ParametertoTestDropDown = uidropdown(app.ErrorVersusParameterTab);
            app.ParametertoTestDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametertoTestDropDownValueChanged, true);
            app.rangeminEditFieldLabel = uilabel(app.ErrorVersusParameterTab);
            app.rangeminEditFieldLabel.Text = 'range min';
            app.rangeminEditField = uieditfield(app.ErrorVersusParameterTab, 'numeric');
            app.rangemaxEditFieldLabel = uilabel(app.ErrorVersusParameterTab);
            app.rangemaxEditFieldLabel.Text = 'range max';
            app.rangemaxEditField = uieditfield(app.ErrorVersusParameterTab, 'numeric');
            app.valueLabel = uilabel(app.ErrorVersusParameterTab);
            app.valueLabel.Text = 'value = ';
            app.GridSizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridSizeEditFieldLabel.Text = 'Grid Size';
            app.GridSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GridSizeEditField.Limits = [1 Inf];
            app.GridSizeEditField.RoundFractionalValues = 'on';
            app.GridSizeEditField.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSPModelReducer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end