classdef FittingGraphs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        parameter2DropDownLabel         matlab.ui.control.Label
        parameter2DropDown              matlab.ui.control.DropDown
        parameter1DropDownLabel         matlab.ui.control.Label
        parameter1DropDown              matlab.ui.control.DropDown
        MetropolisHastingGraphingLabel  matlab.ui.control.Label
        ConfidenceLevelEditFieldLabel   matlab.ui.control.Label
        ConfidenceLevelEditField        matlab.ui.control.NumericEditField
        ShowCovarianceCheckBox          matlab.ui.control.CheckBox
        ChainDropDownLabel              matlab.ui.control.Label
        ChainDropDown                   matlab.ui.control.DropDown
        UIObjFunAxes                    matlab.ui.control.UIAxes
        UIParamAxes                     matlab.ui.control.UIAxes
        UIObjFunHistAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SSIT_GUI
        Samples
        Values
        ParamNames
        Chains
        Mean
        Cov
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSIT_GUI_Fitting, SSIT_GUI_Param, appProps)
            addpath(genpath('src'));
            app.SSIT_GUI=SSIT_GUI_Fitting;
            app.ParamNames=SSIT_GUI_Param(:,1);
            if app.SSIT_GUI.fitOptions.props.numChains==1
                app.Values={app.SSIT_GUI.mhValue};
                app.Samples={app.SSIT_GUI.mhSamples};
            else
                app.Values=app.SSIT_GUI.mhValue;
                app.Samples=app.SSIT_GUI.mhSamples;
            end
            app.Chains=cellstr(num2str((1:app.SSIT_GUI.fitOptions.props.numChains)'));
            app.Chains{app.SSIT_GUI.fitOptions.props.numChains+1}='all';
            app.parameter1DropDown.Items=app.ParamNames;
            app.parameter2DropDown.Items=app.ParamNames;
            app.ChainDropDown.Items=app.Chains;
            for i=1:app.SSIT_GUI.fitOptions.props.numChains
                if app.ChainDropDown.Value==app.Chains{i}
                    plot(app.UIObjFunAxes,app.Values{i});
                    histogram(app.UIObjFunHistAxes,app.Values{i},'Normalization','probability');
                    histogram(app.UIParamAxes,app.Samples{i}(:,1),'Normalization','probability');
                    set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{1},')'))
                    set(get(app.UIParamAxes, 'ylabel'), 'string', 'Frequency')
                    set(get(app.UIParamAxes, 'title'), 'string', append('Marginal Posterior Distribution of ',app.ParamNames{1}))
                    
                end
            end
        end

        % Callback function
        function parameter2DropDownValueChanged(app, event)
            
            if app.parameter1DropDown.Value == app.parameter2DropDown.Value
                hold(app.UIParamAxes, 'off');
                Loc=find([app.ParamNames{:}]==app.parameter2DropDown.Value);
                histogram(app.UIParamAxes,app.Samples(:,Loc),'Normalization','probability');
                set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{Loc},')'))
                set(get(app.UIParamAxes, 'ylabel'), 'string', 'Frequency')
                set(get(app.UIParamAxes, 'title'), 'string', append('Marginal Posterior Distribution of ',app.ParamNames{Loc}))
            else
                xLoc=find([app.ParamNames{:}]==app.parameter1DropDown.Value);
                yLoc=find([app.ParamNames{:}]==app.parameter2DropDown.Value);
                plot(app.UIParamAxes,app.Samples(:,xLoc),app.Samples(:,yLoc),'ro');
                set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{xLoc},')'))
                set(get(app.UIParamAxes, 'ylabel'), 'string', append('log(',app.ParamNames{yLoc},')'))
                set(get(app.UIParamAxes, 'title'), 'string', append('Joint Distribution of ',app.ParamNames{yLoc},' vs. ',app.ParamNames{xLoc}))
                if app.ShowCovarianceCheckBox.Value==1
                    Means=[app.Mean(xLoc) app.Mean(yLoc)];
                    cmat=ssit.parest.chiqf(app.ConfidenceLevelEditField.Value,2)*app.Cov([xLoc yLoc],[xLoc yLoc]);
                    hold(app.UIParamAxes, 'on');
                    [x,y]=ssit.parest.ellipse(Means,cmat);
                    plot(app.UIParamAxes,x,y,'b','linewidth',2)
                    hold(app.UIParamAxes, 'off');
                end
            end
        end

        % Value changed function: ChainDropDown, 
        % ConfidenceLevelEditField, ShowCovarianceCheckBox, 
        % parameter1DropDown, parameter2DropDown
        function ChainDropDownValueChanged(app, event)
            hold(app.UIObjFunAxes,'off')
            hold(app.UIObjFunHistAxes,'off')
            hold(app.UIParamAxes,'off')
            
            for i=1:app.SSIT_GUI.fitOptions.props.numChains
                
                if app.ChainDropDown.Value==app.Chains{i}
                    plot(app.UIObjFunAxes,app.Values{i});
                    histogram(app.UIObjFunHistAxes,app.Values{i},'Normalization','probability');
                    if app.parameter1DropDown.Value == app.parameter2DropDown.Value
                        hold(app.UIParamAxes, 'off');
                        Loc=find([app.ParamNames{:}]==app.parameter1DropDown.Value);
                        histogram(app.UIParamAxes,app.Samples{i}(:,Loc),'Normalization','probability');
                        set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{Loc},')'))
                        set(get(app.UIParamAxes, 'ylabel'), 'string', 'Frequency')
                        set(get(app.UIParamAxes, 'title'), 'string', append('Marginal Posterior Distribution of ',app.ParamNames{Loc}))
                    else
                        xLoc=find(strcmp(app.ParamNames,app.parameter1DropDown.Value));
                        yLoc=find(strcmp(app.ParamNames,app.parameter2DropDown.Value));
                        plot(app.UIParamAxes,app.Samples{i}(:,xLoc),app.Samples{i}(:,yLoc),'ro');
                        set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{xLoc},')'))
                        set(get(app.UIParamAxes, 'ylabel'), 'string', append('log(',app.ParamNames{yLoc},')'))
                        set(get(app.UIParamAxes, 'title'), 'string', append('Joint Distribution of ',app.ParamNames{yLoc},' vs. ',app.ParamNames{xLoc}))
                        if app.ShowCovarianceCheckBox.Value==1
                            app.Mean=mean(app.Samples{i});
                            app.Cov=cov(app.Samples{i});
                            Means=[app.Mean(xLoc) app.Mean(yLoc)];
                            cmat=ssit.parest.chiqf(app.ConfidenceLevelEditField.Value,2)*app.Cov([xLoc yLoc],[xLoc yLoc]);
                            hold(app.UIParamAxes, 'on');
                            [x,y]=ssit.parest.ellipse(Means,cmat);
                            plot(app.UIParamAxes,x,y,'b','linewidth',2)
                            hold(app.UIParamAxes, 'off');
                        end
                    end
                end
                
                if app.ChainDropDown.Value=='all'
                    plot(app.UIObjFunAxes,app.Values{i}); hold(app.UIObjFunAxes,'on')
                    histogram(app.UIObjFunHistAxes,app.Values{i},'Normalization','probability'); hold(app.UIObjFunHistAxes,'on')
                    if app.parameter1DropDown.Value == app.parameter2DropDown.Value
                        Loc=find(strcmp(app.ParamNames,app.parameter1DropDown.Value));
                        histogram(app.UIParamAxes,app.Samples{i}(:,Loc),'Normalization','probability'); hold(app.UIParamAxes,'on')
                        set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{Loc},')'))
                        set(get(app.UIParamAxes, 'ylabel'), 'string', 'Frequency')
                        set(get(app.UIParamAxes, 'title'), 'string', append('Marginal Posterior Distribution of ',app.ParamNames{Loc}))
                    else
                        xLoc=find(strcmp(app.ParamNames,app.parameter1DropDown.Value));
                        yLoc=find(strcmp(app.ParamNames,app.parameter2DropDown.Value));
                        plot(app.UIParamAxes,app.Samples{i}(:,xLoc),app.Samples{i}(:,yLoc),'o'); hold(app.UIParamAxes,'on')
                        set(get(app.UIParamAxes, 'xlabel'), 'string', append('log(',app.ParamNames{xLoc},')'))
                        set(get(app.UIParamAxes, 'ylabel'), 'string', append('log(',app.ParamNames{yLoc},')'))
                        set(get(app.UIParamAxes, 'title'), 'string', append('Joint Distribution of ',app.ParamNames{yLoc},' vs. ',app.ParamNames{xLoc}))
                        if app.ShowCovarianceCheckBox.Value==1
                            app.Mean=mean(app.Samples{i});
                            app.Cov=cov(app.Samples{i});
                            Means=[app.Mean(xLoc) app.Mean(yLoc)];
                            cmat=ssit.parest.chiqf(app.ConfidenceLevelEditField.Value,2)*app.Cov([xLoc yLoc],[xLoc yLoc]);
                            [x,y]=ssit.parest.ellipse(Means,cmat);
                            plot(app.UIParamAxes,x,y,'linewidth',2)
                        end
                    end
                end
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.parameter2DropDownLabel = uilabel(app.UIFigure);
            app.parameter2DropDownLabel.Text = 'parameter 2';
            app.parameter2DropDown = uidropdown(app.UIFigure);
            app.parameter2DropDown.ValueChangedFcn = createCallbackFcn(app, @ChainDropDownValueChanged, true);
            app.parameter2DropDown.Tooltip = {'Choose the same parameter as above to create plot of marginal distribution'};
            app.parameter1DropDownLabel = uilabel(app.UIFigure);
            app.parameter1DropDownLabel.Text = 'parameter 1';
            app.parameter1DropDown = uidropdown(app.UIFigure);
            app.parameter1DropDown.ValueChangedFcn = createCallbackFcn(app, @ChainDropDownValueChanged, true);
            app.MetropolisHastingGraphingLabel = uilabel(app.UIFigure);
            app.MetropolisHastingGraphingLabel.Text = 'Metropolis Hasting Graphing';
            app.ConfidenceLevelEditFieldLabel = uilabel(app.UIFigure);
            app.ConfidenceLevelEditFieldLabel.Text = 'Confidence Level';
            app.ConfidenceLevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceLevelEditField.ValueChangedFcn = createCallbackFcn(app, @ChainDropDownValueChanged, true);
            app.ConfidenceLevelEditField.Value = 0.9;
            app.ShowCovarianceCheckBox = uicheckbox(app.UIFigure);
            app.ShowCovarianceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChainDropDownValueChanged, true);
            app.ShowCovarianceCheckBox.Text = 'Show Covariance';
            app.ChainDropDownLabel = uilabel(app.UIFigure);
            app.ChainDropDownLabel.Text = 'Chain';
            app.ChainDropDown = uidropdown(app.UIFigure);
            app.ChainDropDown.ValueChangedFcn = createCallbackFcn(app, @ChainDropDownValueChanged, true);
            app.UIObjFunAxes = uiaxes(app.UIFigure);
            title(app.UIObjFunAxes, 'Log Likelihood vs. Iteration')
            xlabel(app.UIObjFunAxes, 'Iteration')
            ylabel(app.UIObjFunAxes, 'Log Likelihood')
            app.UIParamAxes = uiaxes(app.UIFigure);
            xlabel(app.UIParamAxes, 'parameter 1')
            ylabel(app.UIParamAxes, 'parameter 2')
            app.UIObjFunHistAxes = uiaxes(app.UIFigure);
            title(app.UIObjFunHistAxes, 'Distribution of MH log-likelihoods')
            xlabel(app.UIObjFunHistAxes, 'Log Likelihood')
            ylabel(app.UIObjFunHistAxes, 'Frequency')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingGraphs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end