classdef PropEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        CloseButton  matlab.ui.control.Button
        SaveButton   matlab.ui.control.Button
        propTable    matlab.ui.control.Table
    end

    properties (Access = public)
        appProperties % Description
        SSIT_GUI
        appPropsName
        appPropertyClasses
        OutputData
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSIT_GUI, appProps, headers, multiCol)
            if nargin>3
                app.propTable.ColumnName = headers;
            end
            if nargin<5
                multiCol = 0;
            end
            
            app.SSIT_GUI = SSIT_GUI;
            app.appPropsName = appProps;
            app.appProperties = app.SSIT_GUI.(appProps);
            fldNames = fieldnames(app.appProperties);
            app.propTable.Data = {};
            NCol = length(app.appProperties.(fldNames{1}));
            app.appPropertyClasses = {};
            for i=1:length(fldNames)                
                app.propTable.Data(i,1) = fldNames(i);               
                if multiCol
                    for j = 1:NCol
                        app.propTable.ColumnEditable(j+1)=true;
                        try
                            app.propTable.Data(i,1+j) = {app.appProperties.(fldNames{i})(j)};
                            app.appPropertyClasses(i,1+j) = {class(app.appProperties.(fldNames{i})(j))};
                        catch
                        end
                    end
                else
                    try
                        app.propTable.Data(i,2) = {app.appProperties.(fldNames{i})};
                        app.appPropertyClasses(i,2) = {class(app.appProperties.(fldNames{i}))};
                    catch
                        try
                            app.propTable.Data(i,1+j) = {func2str(app.appProperties.(fldNames{i}))};
                            app.appPropertyClasses(i,1+j) = {class(app.appProperties.(fldNames{i})(j))};
                        catch
                        end
                    end
                end
            end
        end

        % Callback function: SaveButton, propTable
        function SaveButtonPushed(app, event)
            fldNames = fieldnames(app.appProperties);
            for i=1:length(fldNames)
                try
%                     app.appProperties.(fldNames{i}) = eval(app.propTable.Data{i,2:end});
                    app.appProperties.(fldNames{i}) = [app.propTable.Data{i,2:end}];
                catch
                    try
                        app.appProperties.(fldNames{i}) = [app.propTable.Data{i,2:end}];
                    catch ME
%                         app.appProperties.(fldNames{i}) = app.appProperties.(fldNames{i});
%                         ME.message
                    end
                end
            end
            app.SSIT_GUI.(app.appPropsName)=app.appProperties;
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            app.OutputData = app.SSIT_GUI.(app.appPropsName);
            delete(app)

        end

        % Callback function
        function propTableCellEdit(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.propTable = uitable(app.UIFigure);
            app.propTable.ColumnName = {'Property'; 'Value'};
            app.propTable.RowName = {};
            app.propTable.ColumnEditable = [false true];
            app.propTable.CellEditCallback = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end