classdef NStateModelMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpdateButton                  matlab.ui.control.Button
        ModelAddReactionButton        matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        ModelReactionTable            matlab.ui.control.Table
        BitsforBinaryModelEditField   matlab.ui.control.NumericEditField
        BitsforBinaryModelEditFieldLabel  matlab.ui.control.Label
        TemplateTypeDropDown          matlab.ui.control.DropDown
        TemplateTypeDropDownLabel     matlab.ui.control.Label
        UpdateReactionsinSSITandCloseButton  matlab.ui.control.Button
        StateBEFOREReactionLabel      matlab.ui.control.Label
        StateAFTERReactionLabel       matlab.ui.control.Label
        TransM                        matlab.ui.control.Table
        ProductionVectorsmaybefunctionsofx2andx3toincludefeedbackLabel  matlab.ui.control.Label
        StateTransitionMatrixLabel    matlab.ui.control.Label
        ProdV                         matlab.ui.control.Table
        NumberofStatesEditField       matlab.ui.control.NumericEditField
        NumberofStatesEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        NStates = 1; % Number of states
        STM = {}; % Template for State Transition Matrix
        TRA = {};
        BSN = {};
        SSIT = {};
    end

    methods (Access = private)

        function UpdateGUI(app)
            % This function updates the GUI to reflect changes to the
            % template type or the number of states.
            app.NStates = app.NumberofStatesEditField.Value;
            app.ProdV.Data = {};
            app.TransM.Data = {};
            app.ProdV.ColumnName{1} = '';
            app.ProdV.Data{1,1} = 'x2';
            app.ProdV.Data{2,1} = 'x3';
            app.TransM.ColumnName{1} = '';
            for i = 1:app.NStates
                % Add Column and row names to table
                if isempty(app.BSN)
                    app.ProdV.ColumnName{i+1} = ['S',num2str(i)];
                    app.TransM.ColumnName{i+1} = ['S',num2str(i)];
                    app.TransM.Data{i,1} = ['S',num2str(i)];
                else
                    app.ProdV.ColumnName{i+1} = app.BSN{i};
                    app.TransM.ColumnName{i+1} = app.BSN{i};
                    app.TransM.Data{i,1} = app.BSN{i};
                end

                % Add Reaction Rates to table
                app.ProdV.Data{1,i+1} = '0';
                app.ProdV.Data{2,i+1} = '0';
                for j = 1:app.NStates
                    if isempty(app.STM)
                        app.TransM.Data(i,j+1) = {'0'};
                        app.ProdV.Data(1:2,j+1) = {'0'};
                    else
                        app.TransM.Data(i,j+1) = app.STM(i,j);
                    end
                end
                if isempty(app.TRA)
                    app.ProdV.Data(1:2,i+1) = {'0'};
                else
                    app.ProdV.Data(1:2,i+1) = app.TRA(1:2,i);
                end
                app.TransM.Data{i,i+1} = '-';
            end

            % Allow user to edit table.
            app.ProdV.ColumnEditable = [false,ones(1,app.NStates,'logical')];
            app.TransM.ColumnEditable = [false,ones(1,app.NStates,'logical')];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSIT)
            try
                app.SSIT=SSIT;
                if ~isempty(SSIT.ReactionsTabOutputs.NStateModel)
                    NAMES = {'NStates';...
                        'ProdV.Data';...
                        'TransM.Data';...
                        'ModelReactionTable.Data'};
                    for i=1:length(NAMES)
                        eval(['app.',NAMES{i},'=app.SSIT.ReactionsTabOutputs.NStateModel.',NAMES{i},';']);
                    end
                    app.ProdV.ColumnEditable = [false,ones(1,app.NStates,'logical')];
                    app.TransM.ColumnEditable = [false,ones(1,app.NStates,'logical')];
                else
                    disp('Starting with defaults.')
                    app.ModelReactionTable.Data = {1,'x2(1)','-','g2*x2','n';...
                        1,'x3(1)','-','g3*x3','n'};
                end
            catch
                disp('Starting with defaults.')
                app.ModelReactionTable.Data = {1,'x2(1)','-','g2*x2','n';...
                    1,'x3(1)','-','g3*x3','n'};
            end
        end

        % Value changed function: NumberofStatesEditField
        function NumberofStatesEditFieldValueChanged(app, event)
            app.NStates = app.NumberofStatesEditField.Value;
            if ~strcmp(event,'DD')
                TemplateTypeDropDownOpening(app);
            end
            UpdateGUI(app)
        end

        % Value changed function: TemplateTypeDropDown
        function TemplateTypeDropDownOpening(app, event)
            app.STM  = {};
            switch app.TemplateTypeDropDown.Value
                case 'None'
                    app.NumberofStatesEditField.Enable = 1;
                    app.BSN ={};
                    app.TransM.Enable = 'on';
                    app.ProdV.Enable = 'on';
                    app.BitsforBinaryModelEditField.Enable = 0;
                case 'Linear Chain'
                    app.NumberofStatesEditField.Enable = 1;
                    app.BSN ={};
                    app.TransM.Enable = 'on';
                    app.ProdV.Enable = 'on';
                    app.BitsforBinaryModelEditField.Enable = 0;
                    app.STM(1:app.NStates,1:app.NStates) = {'0'};
                    app.TRA(1:2,1:app.NStates) = {'0'};
                    for i=1:app.NStates-1
                        app.STM(i+1,i) = {['K',num2str(i),'_',num2str(i+1)]};
                        app.STM(i,i+1) = {['K',num2str(i+1),'_',num2str(i)]};
                        %                         app.TRA(1,i+1) = {['R1_',num2str(i)]};
                        %                         app.TRA(2,i+1) = {['R2_',num2str(i)]};
                    end
                    for i=1:app.NStates
                        app.TRA(1,i) = {['R1_',num2str(i)]};
                        app.TRA(2,i) = {['R2_',num2str(i)]};
                    end
                case 'Closed Loop'
                    app.NumberofStatesEditField.Enable = 1;
                    app.BSN ={};
                    app.TransM.Enable = 'on';
                    app.ProdV.Enable = 'on';
                    app.BitsforBinaryModelEditField.Enable = 0;
                    app.STM(1:app.NStates,1:app.NStates) = {'0'};
                    app.TRA(1:2,1:app.NStates) = {'0'};
                    for i=1:app.NStates-1
                        app.STM(i+1,i) = {['K',num2str(i),'_',num2str(i+1)]};
                        %                         app.TRA(1,i+1) = {['R1_',num2str(i)]};
                        %                         app.TRA(2,i+1) = {['R2_',num2str(i)]};
                    end
                    app.STM(1,app.NStates) = {['K',num2str(app.NStates),'_1']};
                    for i=1:app.NStates
                        app.TRA(1,i) = {['R1_',num2str(i)]};
                        app.TRA(2,i) = {['R2_',num2str(i)]};
                    end
                case 'Binary'
                    app.BitsforBinaryModelEditField.Enable = 1;
                    app.BitsforBinaryModelEditField.Editable = 1;
                    app.TransM.Enable = 'off';
                    app.ProdV.Enable = 'off';
                    app.NumberofStatesEditField.Enable = 0;
                    return
            end
            NumberofStatesEditFieldValueChanged(app,'DD')

        end

        % Value changed function: BitsforBinaryModelEditField
        function BitsforBinaryModelEditFieldValueChanged(app, event)
            NBits = app.BitsforBinaryModelEditField.Value;
            app.NStates = 2^NBits;
            app.NumberofStatesEditField.Value = app.NStates;
            app.STM(1:app.NStates,1:app.NStates) = {'0'};
            app.TRA(1:2,1:app.NStates) = {'0'};
            for i=1:NBits
                stp1 = 2^(i-1);
                stp2 = 2^(i);
                str = 1:2^(i-1);
                nstp = 2^(NBits-i);
                for k=1:nstp
                    for j=1:length(str)
                        i1 = str(j) + (k-1)*stp2;
                        i2 = str(j) + (k-1)*stp2 + stp1;
                        app.STM(i2,i1) = {['b',num2str(i)]};
                        app.STM(i1,i2) = {['u',num2str(i)]};
                    end
                end
            end

            app.BSN = {};
            for i=1:app.NStates
                app.BSN{1,i} = repmat('1',1,NBits);
                holes = app.STM(i:end,i);
                inds = log2(find(contains(holes,'b'))-1)+1;
                app.BSN{1,i}(inds) = '0';
                app.TRA(1,i) = {['R1_',num2str(i)]};
                app.TRA(2,i) = {['R2_',num2str(i)]};
            end
            app.TransM.Enable = 'on';
            app.ProdV.Enable = 'on';
            NumberofStatesEditFieldValueChanged(app,'DD')

        end

        % Callback function: UIFigure, 
        % UpdateReactionsinSSITandCloseButton
        function UIFigureCloseRequest(app, event)
            RXNS = {};
            k = 0;
            for i = 1:app.NStates
                for j = i+1:app.NStates
                    if ~strcmp(app.TransM.Data{j,i+1},'0')
                        k=k+1;
                        RXNS(k,:) = {k,'-',...
                            ['x1(',num2str(j-i),')'],...
                            [app.TransM.Data{j,i+1},'*(x1==',num2str(i),')'],'n'};
                    end
                    if ~strcmp(app.TransM.Data{i,j+1},'0')
                        k=k+1;
                        RXNS(k,:) = {k,...
                            ['x1(',num2str(j-i),')'],'-',...
                            [app.TransM.Data{i,j+1},'*(x1==',num2str(j),')'],'n'};
                    end
                end
            end
            for i = 1:app.NStates
                if ~strcmp(app.ProdV.Data{1,i+1},'0')
                    k=k+1;
                    RXNS(k,:) = {k,'-',...
                        ['x2(1)'],...
                        [app.ProdV.Data{1,i+1},'*(x1==',num2str(i),')'],'n'};
                end
                if ~strcmp(app.ProdV.Data{2,i+1},'0')
                    k=k+1;
                    RXNS(k,:) = {k,'-',...
                        ['x3(1)'],...
                        [app.ProdV.Data{2,i+1},'*(x1==',num2str(i),')'],'n'};
                end
            end
            for i = 1:size(app.ModelReactionTable.Data,1)
                app.ModelReactionTable.Data(i,1) = {k+i};
            end
            RXNS(end+1:end+size(app.ModelReactionTable.Data,1),:) = app.ModelReactionTable.Data;


            NAMES = {'NStates';...
                'ProdV.Data';...
                'TransM.Data';...
                'ModelReactionTable.Data'};
            for i=1:length(NAMES)
                eval(['app.SSIT.ReactionsTabOutputs.NStateModel.',NAMES{i},' = app.',NAMES{i},';']);
            end

            app.SSIT.ModelReactionTable.Data = RXNS;
            updateModel(app.SSIT);
            app.SSIT.ModelCitationforPresetExamplesTextArea.Value = 'Model Created Using N-State Model Maker.';

            try
                figure(app.SSIT.UIFigure);
            catch
            end
            delete(app)

        end

        % Value changed function: ModelAddReactionButton
        function ModelAddReactionButtonValueChanged(app, event)
            % Add another reaction between x2 and x3.
            N = size(app.ModelReactionTable.Data,1);
            M = app.ModelReactionTable.Data{end,1};
            app.ModelReactionTable.Data(N+1,:) =...
                {M+1,'x2(1),x3(1)','-','gBiMol*x2*x3','n'};
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            IND = ~strcmp(app.ModelReactionTable.Data(:,end),'y');
            app.ModelReactionTable.Data = app.ModelReactionTable.Data(IND,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States';
            app.NumberofStatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofStatesEditField.Limits = [1 Inf];
            app.NumberofStatesEditField.RoundFractionalValues = 'on';
            app.NumberofStatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditFieldValueChanged, true);
            app.NumberofStatesEditField.Value = 1;
            app.ProdV = uitable(app.UIFigure);
            app.ProdV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProdV.RowName = {};
            app.ProdV.Tooltip = {'Enter the rate of production of x2 (top) and x3 (bottom) for each state. Use variables starting with ''I'' to denote time varying rates. These rates can be functions of ''x2'' and ''x3'' to reflect feedback mechanisms.'};
            app.StateTransitionMatrixLabel = uilabel(app.UIFigure);
            app.StateTransitionMatrixLabel.Text = 'State Transition Matrix (may be functions of ''x2'' and ''x3'' to include feedback. Use ''I*'' to denote input signals.)';
            app.ProductionVectorsmaybefunctionsofx2andx3toincludefeedbackLabel = uilabel(app.UIFigure);
            app.ProductionVectorsmaybefunctionsofx2andx3toincludefeedbackLabel.Text = 'x2 and x3 Production Vectors (may be functions of ''x2'' and ''x3'' to include feedback. Use ''I*'' to denote input signals.)';
            app.TransM = uitable(app.UIFigure);
            app.TransM.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TransM.RowName = {};
            app.TransM.Tooltip = {'Enter the rate of transition of Si (columns) to Sj (rows) for each state. Use variables starting with ''I'' to denote time varying rates. These rates can be functions of ''x2'' and ''x3'' to reflect feedback mechanisms.'};
            app.StateAFTERReactionLabel = uilabel(app.UIFigure);
            app.StateAFTERReactionLabel.Text = 'State AFTER Reaction';
            app.StateBEFOREReactionLabel = uilabel(app.UIFigure);
            app.StateBEFOREReactionLabel.Text = 'State BEFORE Reaction';
            app.UpdateReactionsinSSITandCloseButton = uibutton(app.UIFigure, 'push');
            app.UpdateReactionsinSSITandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UpdateReactionsinSSITandCloseButton.Text = 'Update Reactions in SSIT and Close';
            app.TemplateTypeDropDownLabel = uilabel(app.UIFigure);
            app.TemplateTypeDropDownLabel.Text = 'Template Type';
            app.TemplateTypeDropDown = uidropdown(app.UIFigure);
            app.TemplateTypeDropDown.Items = {'None', 'Linear Chain', 'Closed Loop', 'Binary'};
            app.TemplateTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateTypeDropDownOpening, true);
            app.TemplateTypeDropDown.Value = 'None';
            app.BitsforBinaryModelEditFieldLabel = uilabel(app.UIFigure);
            app.BitsforBinaryModelEditFieldLabel.Enable = 'off';
            app.BitsforBinaryModelEditFieldLabel.Text = '# Bits for Binary Model';
            app.BitsforBinaryModelEditField = uieditfield(app.UIFigure, 'numeric');
            app.BitsforBinaryModelEditField.Limits = [1 5];
            app.BitsforBinaryModelEditField.RoundFractionalValues = 'on';
            app.BitsforBinaryModelEditField.ValueChangedFcn = createCallbackFcn(app, @BitsforBinaryModelEditFieldValueChanged, true);
            app.BitsforBinaryModelEditField.Editable = 'off';
            app.BitsforBinaryModelEditField.Enable = 'off';
            app.BitsforBinaryModelEditField.Value = 1;
            app.ModelReactionTable = uitable(app.UIFigure);
            app.ModelReactionTable.ColumnName = {'Reaction Number'; 'Reactants'; 'Products'; 'Propensity'; 'Remove (y/n)'};
            app.ModelReactionTable.RowName = {};
            app.ModelReactionTable.ColumnEditable = [false true true true true];
            app.ModelReactionTable.Tooltip = {'List number of reactants consumed or produced (e.g. ''x1(2)'' corresponds to 2 molecules of S1). Separate multiple reactant/products with comma.'; ''; 'For propensities use x1 x2 x3 for the three species. You may use arbitrary parameter names and regular expressions (e.g. ''cos'' ''exp''  ''log''). '; ''; 'Free parameters that start with ''I'' (e.g. ''I1'' or  ''In2'') will be treated as inputs signals.'; ''; 'If you wish to delete a reaction'; ' enter ''y'' in the final column.'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Degradation and Interactions between Products';
            app.ModelAddReactionButton = uibutton(app.UIFigure, 'state');
            app.ModelAddReactionButton.ValueChangedFcn = createCallbackFcn(app, @ModelAddReactionButtonValueChanged, true);
            app.ModelAddReactionButton.Tooltip = {'Use this button to add another reaction to the model.'};
            app.ModelAddReactionButton.Text = 'Add Reaction';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NStateModelMaker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end