classdef SSITGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        IncludeGUIDatainSaveSwitch      matlab.ui.control.Switch
        IncludeGUIDatainSaveSwitchLabel  matlab.ui.control.Label
        FileModelLabel                  matlab.ui.control.Label
        ModelSaveButton_2               matlab.ui.control.Button
        ModelSaveButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ModelLoadingandBuildingTab      matlab.ui.container.Tab
        RefreshFoldersButton            matlab.ui.control.Button
        NewModelButton                  matlab.ui.control.Button
        RenameSpeciesDropDown           matlab.ui.control.DropDown
        RenameSpeciesDropDownLabel      matlab.ui.control.Label
        SpeciesTable                    matlab.ui.control.Table
        ParametersLabel_3               matlab.ui.control.Label
        ChooseSSITModelLabel            matlab.ui.control.Label
        ChooseSSITModel                 matlab.ui.control.DropDown
        DeleteInputDropDown             matlab.ui.control.DropDown
        DeleteInputDropDownLabel        matlab.ui.control.Label
        AddInputButton                  matlab.ui.control.StateButton
        AbouttheModelLabel              matlab.ui.control.Label
        DeleteSpeciesDropDown           matlab.ui.control.DropDown
        DeleteSpeciesDropDownLabel      matlab.ui.control.Label
        AddSpeciesButton                matlab.ui.control.StateButton
        DeleteReactionDropDown          matlab.ui.control.DropDown
        DeleteReactionDropDown_2Label   matlab.ui.control.Label
        LoadSBMLModelButton             matlab.ui.control.Button
        SubFolderLabel                  matlab.ui.control.Label
        FontSizeLabelRxn                matlab.ui.control.Label
        DecreaseFontSizeRxn             matlab.ui.control.Button
        IncreaseFontSizeRxn             matlab.ui.control.Button
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelAbout                      matlab.ui.control.TextArea
        ModelLoadButton                 matlab.ui.control.Button
        ModelUsePresetExampleTypeDropDown  matlab.ui.control.DropDown
        ModelFolderLabel                matlab.ui.control.Label
        DefineTimeVaryingInputSignalsLabel  matlab.ui.control.Label
        ModelInputTable                 matlab.ui.control.Table
        ParametersLabel                 matlab.ui.control.Label
        ModelParameterTable             matlab.ui.control.Table
        ModelUpdateButton               matlab.ui.control.Button
        ModelhasnotbeenupdatedLabel     matlab.ui.control.Label
        ModelAddReactionButton          matlab.ui.control.StateButton
        ModelReactionTable              matlab.ui.control.Table
        ReactionTextAxes                matlab.ui.control.UIAxes
        DistortionandHybridModelingTab  matlab.ui.container.Tab
        UpstreamSpeciesListBox          matlab.ui.control.ListBox
        UpstreamSpeciesListBox_2Label   matlab.ui.control.Label
        ObservableSpeciesListBox_2      matlab.ui.control.ListBox
        ObservableSpeciesListBox_2Label  matlab.ui.control.Label
        UseHybridModelSwitch            matlab.ui.control.Switch
        UseHybridModelLabel             matlab.ui.control.Label
        HybridModelOptionsLabel         matlab.ui.control.Label
        SolutionTimeDropDown            matlab.ui.control.DropDown
        SolutionTimeDropDownLabel       matlab.ui.control.Label
        SpeciesDropDown                 matlab.ui.control.DropDown
        SpeciesDropDownLabel            matlab.ui.control.Label
        DistortionTypeDropDown          matlab.ui.control.DropDown
        DistortionTypeDropDown_2Label   matlab.ui.control.Label
        UpdatePDOParametersButton       matlab.ui.control.Button
        DistortionParameterGuessesandFitsLabel  matlab.ui.control.Label
        pdo_parameters_table            matlab.ui.control.Table
        ShowDistortionPlotButton        matlab.ui.control.Button
        SetDistortionParametersButton   matlab.ui.control.Button
        IncludeDataDistortionsinModelLabel  matlab.ui.control.Label
        PDO_Axis2                       matlab.ui.control.UIAxes
        PDO_Axis                        matlab.ui.control.UIAxes
        StochasticSimulationTab         matlab.ui.container.Tab
        SsaGPUCheckBox                  matlab.ui.control.CheckBox
        SpeciestoShowListBox            matlab.ui.control.ListBox
        SpeciestoShowListBoxLabel       matlab.ui.control.Label
        SsaShowOdeCheckBox              matlab.ui.control.CheckBox
        FontSizeLabelSsa                matlab.ui.control.Label
        IncreaseFontSizeSsa             matlab.ui.control.Button
        DecreaseFontSizeSSA             matlab.ui.control.Button
        SsaExportExcelButton            matlab.ui.control.Button
        SsaSignalUpdateRateField        matlab.ui.control.EditField
        SignalUpdateRateEditFieldLabel  matlab.ui.control.Label
        SsaNumSimToPlotField            matlab.ui.control.NumericEditField
        NSimsEditFieldLabel             matlab.ui.control.Label
        SSA_hist_YminEditField          matlab.ui.control.NumericEditField
        YminLabel_2                     matlab.ui.control.Label
        SSA_hist_XminEditField          matlab.ui.control.NumericEditField
        XminLabel_2                     matlab.ui.control.Label
        SSA_hist_YmaxEditField          matlab.ui.control.NumericEditField
        YmaxLabel_2                     matlab.ui.control.Label
        SSA_hist_XmaxEditField          matlab.ui.control.NumericEditField
        XmaxLabel_2                     matlab.ui.control.Label
        SsaNonParallelCheckBox          matlab.ui.control.CheckBox
        SsaParallelCheckBox             matlab.ui.control.CheckBox
        SSAYminEditField                matlab.ui.control.NumericEditField
        YminLabel                       matlab.ui.control.Label
        SSAXminEditField                matlab.ui.control.NumericEditField
        XminLabel                       matlab.ui.control.Label
        SSAYmaxEditField                matlab.ui.control.NumericEditField
        YmaxLabel                       matlab.ui.control.Label
        SSAXmaxEditField                matlab.ui.control.NumericEditField
        XmaxLabel                       matlab.ui.control.Label
        SsaSolutionsattimeLabel         matlab.ui.control.Label
        SsaExportHistogramButton        matlab.ui.control.Button
        SsaExportTrajectoriesButton     matlab.ui.control.Button
        SsaExportFiguresButton          matlab.ui.control.Button
        SsaTimeSlider                   matlab.ui.control.Slider
        timeSliderLabel                 matlab.ui.control.Label
        SsaUpdatePlotsButton            matlab.ui.control.Button
        SsaRunStatus                    matlab.ui.control.Label
        showLabel                       matlab.ui.control.Label
        PrintTimesEditField             matlab.ui.control.EditField
        PrintTimesEditFieldLabel        matlab.ui.control.Label
        SsaInitCondField                matlab.ui.control.EditField
        InitialConditionsLabel          matlab.ui.control.Label
        SsaRunButton                    matlab.ui.control.Button
        SsaNumSimField                  matlab.ui.control.NumericEditField
        NumberofSimulationsEditFieldLabel  matlab.ui.control.Label
        SsaHistAxes                     matlab.ui.control.UIAxes
        SsaTrajAxes                     matlab.ui.control.UIAxes
        FiniteStateProjectionTab        matlab.ui.container.Tab
        initApproxSS                    matlab.ui.control.CheckBox
        SpeciestoShowListBox_2          matlab.ui.control.ListBox
        SpeciestoShowListBox_2Label     matlab.ui.control.Label
        ExportFSPDatatoWorkspaceForManualPlotsButton  matlab.ui.control.Button
        FspIntegratorTolerance          matlab.ui.control.DropDown
        ODEToleranceDropDownLabel       matlab.ui.control.Label
        FontSizeLabel_3                 matlab.ui.control.Label
        IncreaseFontSizeFsp             matlab.ui.control.Button
        DecreaseFontSizeFsp             matlab.ui.control.Button
        FspPiecewiseCheckBox            matlab.ui.control.CheckBox
        FspApproximateSteadyStateButton  matlab.ui.control.Button
        FspAutoExpandCheckBox           matlab.ui.control.CheckBox
        FSPYmaxEditField                matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        FSPYminEditField                matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        FSPXmaxEditField                matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        FSPXminEditField                matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        FspJointDistributionPanel       matlab.ui.container.Panel
        vsLabel                         matlab.ui.control.Label
        JointSp2                        matlab.ui.control.ListBox
        JointSp1                        matlab.ui.control.ListBox
        FspJointCreateMovCheckBox       matlab.ui.control.CheckBox
        PlotJointDistributionsButton    matlab.ui.control.Button
        FspMeshCheckBox                 matlab.ui.control.CheckBox
        FspContourCheckBox              matlab.ui.control.CheckBox
        SelectPlotsLabel_3              matlab.ui.control.Label
        SelectSpeciesLabel_4            matlab.ui.control.Label
        FspMeanandVariancePanel         matlab.ui.container.Panel
        SpeciestoShowListBoxMeans       matlab.ui.control.ListBox
        FspMeanVarShowOdeCheckBox       matlab.ui.control.CheckBox
        FspMeanVarPlotButton            matlab.ui.control.Button
        FspMeanVarShowVarianceCheckBox  matlab.ui.control.CheckBox
        SelectPlotsLabel_2              matlab.ui.control.Label
        SelectSpeciesLabel_3            matlab.ui.control.Label
        FspMarginalsOverTimePanel       matlab.ui.container.Panel
        SpeciestoShowListBoxMargFSPvT   matlab.ui.control.ListBox
        FspMarginalTimeCreateMovieCheckBox  matlab.ui.control.CheckBox
        SelectSpeciesLabel_2            matlab.ui.control.Label
        FspMarginalVecField             matlab.ui.control.EditField
        FspPlotMarginalsOverTimeButton  matlab.ui.control.Button
        InputTimePointsLabel            matlab.ui.control.Label
        ChoosePlotsLabel                matlab.ui.control.Label
        FspMarginalsPanel               matlab.ui.container.Panel
        SpeciestoShowListBoxMargFSP     matlab.ui.control.ListBox
        FspPlotMarginalsButton          matlab.ui.control.Button
        SelectSpeciesLabel              matlab.ui.control.Label
        FspSolutionAtTimeLabel          matlab.ui.control.Label
        FspAddConstraintButton          matlab.ui.control.Button
        FspDefaultButton                matlab.ui.control.Button
        FspConstraintsLabel             matlab.ui.control.Label
        FspConstraintTable              matlab.ui.control.Table
        InitialConditionLabel           matlab.ui.control.Label
        FspTimeSlider                   matlab.ui.control.Slider
        timeSlider_2Label               matlab.ui.control.Label
        FspPrintTimesField              matlab.ui.control.EditField
        PrintTimesEditField_2Label      matlab.ui.control.Label
        FspErrorTolField                matlab.ui.control.NumericEditField
        ErrorToleranceEditFieldLabel    matlab.ui.control.Label
        FspUpdatePlotButton             matlab.ui.control.Button
        FspRunningStatus                matlab.ui.control.Label
        FspInitCondField                matlab.ui.control.EditField
        FspRunButtom                    matlab.ui.control.Button
        FspAxes                         matlab.ui.control.UIAxes
        SensitivityAnalysisTab          matlab.ui.container.Tab
        ExportSensitivityDatatoWorkspaceForManualPlotsButton  matlab.ui.control.Button
        FontSizeLabel_4                 matlab.ui.control.Label
        IncreaseFontSizeSens            matlab.ui.control.Button
        DecreaseFontSizeSens            matlab.ui.control.Button
        SensParDropDown                 matlab.ui.control.DropDown
        withrespecttoparameterDropDownLabel  matlab.ui.control.Label
        DisplaySpeciesPanel             matlab.ui.container.Panel
        SpeciesForSensPlot              matlab.ui.control.ListBox
        SensRunButton                   matlab.ui.control.Button
        SensPlotTimeSlider              matlab.ui.control.Slider
        TimeSelectorSliderLabel         matlab.ui.control.Label
        SensPrintTimesEditField         matlab.ui.control.EditField
        PrintTimesEditField_3Label      matlab.ui.control.Label
        SensErrorTolEditField           matlab.ui.control.NumericEditField
        ErrorToleranceEditField_2Label  matlab.ui.control.Label
        InitialConditionLabel_2         matlab.ui.control.Label
        SensInitCondField               matlab.ui.control.EditField
        NoteforValuesTextArea           matlab.ui.control.TextArea
        SelectParameterstobeIncludedinSensitivityAnalysisLabel  matlab.ui.control.Label
        SensParameterSelectionTable     matlab.ui.control.Table
        SelectAnalysisTypeforSensitivityButtonGroup  matlab.ui.container.ButtonGroup
        FiniteDifferenceButton          matlab.ui.control.ToggleButton
        SensitivityFunctionButton       matlab.ui.control.ToggleButton
        SensDerivativeAxes              matlab.ui.control.UIAxes
        SensProbAxes                    matlab.ui.control.UIAxes
        FisherInformationTab            matlab.ui.container.Tab
        HoldOnCheckBox                  matlab.ui.control.CheckBox
        ModelUncertaintyDropDown        matlab.ui.control.DropDown
        ModelUncertaintyDropDownLabel   matlab.ui.control.Label
        FIMNumMC                        matlab.ui.control.NumericEditField
        NumberMCSamplesoverPriorEditFieldLabel  matlab.ui.control.Label
        PlotStlyeDropDown               matlab.ui.control.DropDown
        PlotStlyeDropDownLabel          matlab.ui.control.Label
        FIMLogTransformCheckBox         matlab.ui.control.CheckBox
        EstimateMLEUncertaintyButton    matlab.ui.control.Button
        PlotInformationvsTimeButton     matlab.ui.control.Button
        SpecifyPriorButton              matlab.ui.control.Button
        ParametersLabel_2               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EstimateFisherInformationLabel  matlab.ui.control.Label
        FIMParameter2                   matlab.ui.control.DropDown
        FIMParameter1                   matlab.ui.control.DropDown
        CellsEditField                  matlab.ui.control.NumericEditField
        CellsEditFieldLabel             matlab.ui.control.Label
        foratotalofLabel                matlab.ui.control.Label
        OptimizeButton                  matlab.ui.control.Button
        orLabel                         matlab.ui.control.Label
        ManuallyAllocateMeasurementsperTimePointButton  matlab.ui.control.Button
        ObservableSpeciesListBox        matlab.ui.control.ListBox
        ObservableSpeciesListBoxLabel   matlab.ui.control.Label
        FIMMetricorParameterDropDown    matlab.ui.control.DropDown
        FIMMetricorParameterDropDownLabel  matlab.ui.control.Label
        NoneLabel                       matlab.ui.control.Label
        AddProbabilisticDistortionEffectsButton  matlab.ui.control.Button
        ListofMeasurementTimesEditField  matlab.ui.control.EditField
        ListofMeasurementTimesEditFieldLabel  matlab.ui.control.Label
        FontSizeLabel_8                 matlab.ui.control.Label
        IncreaseFontSizeData_2          matlab.ui.control.Button
        DecreaseFontSizeData_2          matlab.ui.control.Button
        FIMEllipseAxes                  matlab.ui.control.UIAxes
        plotFIMvsTime                   matlab.ui.control.UIAxes
        DataLoadingandFittingTab        matlab.ui.container.Tab
        DataFileNoneSelectedLabel       matlab.ui.control.Label
        Species9Label                   matlab.ui.control.Label
        DataSpecies9                    matlab.ui.control.DropDown
        Species8Label                   matlab.ui.control.Label
        DataSpecies8                    matlab.ui.control.DropDown
        SolveandPlotButton              matlab.ui.control.Button
        NumberafterConstraintsLabel     matlab.ui.control.Label
        TotalCellsInDataLabel           matlab.ui.control.Label
        FieldsinDataTextArea            matlab.ui.control.TextArea
        FieldsinDataTextAreaLabel       matlab.ui.control.Label
        ClearConstraintsButton          matlab.ui.control.Button
        DataConstrText6                 matlab.ui.control.EditField
        DataLogical6                    matlab.ui.control.DropDown
        DataConstrChoice6               matlab.ui.control.DropDown
        AndOr6                          matlab.ui.control.DropDown
        DataConstrText5                 matlab.ui.control.EditField
        DataLogical5                    matlab.ui.control.DropDown
        DataConstrChoice5               matlab.ui.control.DropDown
        AndOr5                          matlab.ui.control.DropDown
        DataConstrText4                 matlab.ui.control.EditField
        DataLogical4                    matlab.ui.control.DropDown
        DataConstrChoice4               matlab.ui.control.DropDown
        AndOr4                          matlab.ui.control.DropDown
        DataConstrText3                 matlab.ui.control.EditField
        DataLogical3                    matlab.ui.control.DropDown
        DataConstrChoice3               matlab.ui.control.DropDown
        AndOr3                          matlab.ui.control.DropDown
        DataConstrText2                 matlab.ui.control.EditField
        DataLogical2                    matlab.ui.control.DropDown
        DataConstrChoice2               matlab.ui.control.DropDown
        AndOr2                          matlab.ui.control.DropDown
        DataConstrText1                 matlab.ui.control.EditField
        DataLogical1                    matlab.ui.control.DropDown
        DataConstrChoice1               matlab.ui.control.DropDown
        ConstrainDataLabel              matlab.ui.control.Label
        LinkTimeLabel                   matlab.ui.control.Label
        DataTime                        matlab.ui.control.DropDown
        Species7Label                   matlab.ui.control.Label
        DataSpecies7                    matlab.ui.control.DropDown
        Species6Label                   matlab.ui.control.Label
        DataSpecies6                    matlab.ui.control.DropDown
        Species5Label                   matlab.ui.control.Label
        DataSpecies5                    matlab.ui.control.DropDown
        Species4Label                   matlab.ui.control.Label
        DataSpecies4                    matlab.ui.control.DropDown
        Species3Label                   matlab.ui.control.Label
        DataSpecies3                    matlab.ui.control.DropDown
        Species2Label                   matlab.ui.control.Label
        DataSpecies2                    matlab.ui.control.DropDown
        Species1Label                   matlab.ui.control.Label
        DataSpecies1                    matlab.ui.control.DropDown
        LinkSpeciesLabel                matlab.ui.control.Label
        PriorTypeDropDown               matlab.ui.control.DropDown
        PriorTypeDropDownLabel          matlab.ui.control.Label
        SetPriorButton                  matlab.ui.control.Button
        SuppressFSPExpansionfasterbutmaybelessaccurateCheckBox  matlab.ui.control.CheckBox
        GraphPosteriorDistributionsButton  matlab.ui.control.Button
        SettingsButton                  matlab.ui.control.Button
        ParameterFittingandUncertaintyQuantificationLabel  matlab.ui.control.Label
        FittingAlgorithmDropDown        matlab.ui.control.DropDown
        FittingAlgorithmDropDownLabel   matlab.ui.control.Label
        FitModelButton                  matlab.ui.control.StateButton
        ParameterGuessesandFitsLabel    matlab.ui.control.Label
        selectallButton                 matlab.ui.control.Button
        ParEstFitTimesList              matlab.ui.control.ListBox
        TimesforFITTINGLabel            matlab.ui.control.Label
        DataLoadingPanel                matlab.ui.container.Panel
        ParEstLoadDataButton            matlab.ui.control.Button
        SpeciestoshowinplotsPanel       matlab.ui.container.Panel
        SpeciesForFitPlot               matlab.ui.control.ListBox
        FontSizeLabel_6                 matlab.ui.control.Label
        IncreaseFontSizeData            matlab.ui.control.Button
        DecreaseFontSizeData            matlab.ui.control.Button
        fit_parameters_table            matlab.ui.control.Table
        LoadandConstrainDataButton      matlab.ui.control.Button
        LaunchFSPModelReductionToolButton  matlab.ui.control.Button
        CreateNewNStateModelButton      matlab.ui.control.Button
        ChooseSSITModelLabel_2          matlab.ui.control.Label
        ChooseSSITModel_2               matlab.ui.control.DropDown
        LaunchMultiDataFittingToolButton  matlab.ui.control.Button
        ofmodelanddataLabel             matlab.ui.control.Label
        loadapreviouscombinationLabel   matlab.ui.control.Label
        LoadPreviousProjectButton       matlab.ui.control.Button
        FitResultsNameText              matlab.ui.control.Label
        FitResultsNameLabel             matlab.ui.control.Label
        RefreshResultsButton            matlab.ui.control.StateButton
        LoadPreviousResultsDropDown     matlab.ui.control.DropDown
        LoadPreviousResultsDropDownLabel  matlab.ui.control.Label
        RunFitsinBackgroundLabel        matlab.ui.control.Label
        FitinBackgroundmaclinuxonlyButton  matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        ReactionsTabOutputs = struct( ...
            'stoichMatrix', [], ... % Stores the stoichiometery matrix
            'parameters', [], ... % Stores the parameters
            'presetParameters', [], ... % Stores pre-set parameters from preset models
            'presetInputs', [], ... % Stores pre-set inputs from preset models
            'citations', [], ... % Stores citations
            'initialCondition', [], ... % Initial condition form preset model
            'loadParams', [], ... % Store selected parameter set form additional dropdown
            'paramVal', [],... % Store parameters for the load_parameter_popup_Callback
            'inputs', [], ... % Stores the inputs
            'propensities', [], ... % Stores the propensity functions
            'NStateModel', [], ... % Structure for N-State Model Building
            'modelInfo', []) % String for model information, to be copied into ModelAbout box

        StochasticSimulationTabOutputs = struct( ...
            'samples', []... % Stores the SSA results
            )

        FspTabOutputs = struct( ...
            'bounds', [], ... % Stores the bounds for the FSP calculation
            'fConstraints', [], ... % Stores the boundary function for the FSP
            'solutions', [], ... % Stores the FSP results
            'tOde',[],...% Stores the times for the ODE solutions
            'odeSolutions',[]...% Stores the ODE solutions
            )

        SensFspTabOutputs = struct( ...
            'solutions', []...  % FSP sensitivity results
            )

        DataLoadingAndFittingTabOutputs= struct( ...
            'boundIndex', [], ... % Stores the previous length of bounds to check if something needs to be deleted
            'S_Params', [],... % Stores the parameters function of the Sensitivity analysis has '.nm' for name and '.va' for value
            'S_Inputs', [],... % Stores the new values for the time-varying functions of the Sensitivity analysis has '.nm' for name and '.va' for value
            'S_logical', [],... % Stores a logical vector of which parameters and inputs are included in the Sensitivity analysis
            'S_Propens', [],... % Stores the propensity functions of the Sensitivity Analysis evaluated at the specified values
            'J_LogLk', [],...
            'dataTable', [],... % Table of data loaded by the user.
            'fittingOptions', [],...
            'dataMatrix', [],... % all data loaded by user in matrix format.
            'filteredDataMatrix', [],... % data matrix after filtering for chosen conditions.
            'dataTensor', [] ,... % data in tensor format (Time, x1, x2, x3)
            'fitResults', [] ,...
            'fitOptions', [] ,...    % options for fitting codes (structure depends on optimizer).
            'priorOptions', [], ...  % options for prior (structure depends on the prior type)
            'columns',[], ... % names of columns in loaded data file.
            'backgroundFitDir','BackgroundFits',...
            'backgroundFitFile','Untitled')


        MomentTabOutputs = struct(...
            'moments',[]... % stores the moment solutions
            )

        FIMTabOutputs = struct(...
            'FIMMatrices',[],...
            'FIMTimes',[],...
            'CellsPerTimePoint',[],...
            'NcOptimized',[],...
            'FIMPrior',[],...
            'PDOProperties',[],...
            'distortionOperator',[])

        SSITModel
        ModelFile
        UnsavedChanges = true
    end
    
    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Set Paths
            app.UIFigure.Position(1:2) = [20 150];
            addpath(genpath('src'));
            clc
            initializeApp(app);
            ModelUsePresetExampleTypeDropDownValueChanged(app);   % This runs the next drop down callback
            ModelDropDownValueChanged(app,[]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {705, 705};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ModelAddReactionButton
        function ModelAddReactionButtonValueChanged(app, event)
            addReactionToReactionsTab(app);
            ModelReactionTableCellEdit(app, event)
            app.UnsavedChanges = true;
        end

        % Cell edit callback: ModelReactionTable
        function ModelReactionTableCellEdit(app, event)
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated.'; % This updates the text by the Update Model Button to notify the user that the model parameters have not been updated

            % When the main table is edited, it clears all graphs within the GUI
            cla(app.SsaTrajAxes);            % Empties the trajectories graph in the SSA tab
            cla(app.SsaHistAxes);           % Empties the histogram in the SSA tab
            cla(app.FspAxes);           % Empties the marginals in the FSP tab

            summarizeModelReactions(app.SSITModel.species,app.ModelReactionTable.Data,app.ModelInputTable.Data,app.ReactionTextAxes)
            app.UnsavedChanges = true;
        end

        % Button pushed function: ModelUpdateButton
        function ModelUpdateButtonPushed(app, event)
            updateModel(app);
        end

        % Button pushed function: SsaRunButton
        function SsaRunButtonPushed(app, event)
            if app.UnsavedChanges
                disp('Model changes not saved -- updating model.')
                updateModel(app);
            end
            runSsa(app); % Runs SSA analysis
            runOde(app);
            % Runs the following functions to finalize plots and print times
            updateTimeSliderFsp(app)
            UpdatePlotsButtonPushed(app);
        end

        % Callback function: SsaNumSimToPlotField, SsaShowOdeCheckBox, 
        % ...and 2 other components
        function UpdatePlotsButtonPushed(app, event)
            plotSsaInGui(app); % Plots the SSA Results within the app
        end

        % Callback function: ModelUsePresetExampleTypeDropDown, 
        % ...and 1 other component
        function ModelUsePresetExampleTypeDropDownValueChanged(app, event)
            AA = dir('Models/');
            v2 = {AA.name};
            I = zeros(1,length(v2),'logical');
            for i=1:length(v2)
                if AA(i).name(1) ~= '.'
                    I(i)=1;
                end
            end
            v2 = v2(I);
            app.ModelUsePresetExampleTypeDropDown.Items = v2;
            selectPresetExampleDropDown(app);
            % ModelDropDownValueChanged(app);
        end

        % Callback function
        function PrintTimesEditFieldValueChanged(app, event)
            % Updates the Time Slider for the graphs when the print times are changed in the SSA tab
            updateTimeSliderFsp(app,event)
        end

        % Button pushed function: FspRunButtom
        function FspRunButtomPushed(app, event)
            if app.UnsavedChanges
                disp('Model changes not saved -- updating model.')
                updateModel(app);
            end

            if isempty(app.FspConstraintTable.Data)
                FspDefaultButtonPushed(app);
            end
            runFsp(app);
            %             runOde(app);
            app.FspTimeSlider.Value = app.FspTimeSlider.Limits(2);
            updatePlotsInFsp(app);
        end

        % Button pushed function: FspDefaultButton
        function FspDefaultButtonPushed(app, event)
            if app.UnsavedChanges
                disp('Model changes not saved -- updating model.')
                updateModel(app);
            end
            makeDefaultConstraints(app);
            readConstraintsForAdaptiveFsp(app);
        end

        % Cell edit callback: FspConstraintTable
        function FspConstraintTableCellEdit(app, event)
            app.FspTabOutputs.stateSpace = [];
            readConstraintsForAdaptiveFsp(app);
        end

        % Callback function: FspTimeSlider, FspTimeSlider, 
        % ...and 2 other components
        function UpdatePlotsButton_2Pushed(app, event)
            updatePlotsInFsp(app);
        end

        % Callback function
        function timeSlider_2ValueChanged(app, event)
            %%% This callback is unused, remove it???
        end

        % Button pushed function: FspAddConstraintButton
        function FspAddConstraintButtonPushed(app, event)
            createNewConstraint(app);
            FspConstraintTableCellEdit(app); % Updates the bounds with the new added constraint
        end

        % Callback function
        function FspPrintTimesFieldValueChanged(app, event)
            updateTimeSliderFsp(app,event);
        end

        % Button pushed function: ModelSaveButton
        function ModelSaveButtonPushed(app, event)
            % Function saves the edits created to a Preset model or your own model
            % saveModelBP(app);
            saveGUI(app); % Call function to add GUI elements to save file if desired.
            
            dlgtitle = 'Choose File to Save or Append Model';
            [fileName,filePath] = uiputfile('*.mat',dlgtitle,app.ModelFile.fileName);

            dlgtitle = 'Save Model';
            dims = [1 50];  % [height width] of input field
            % Check if any variable is of class 'SSIT'
            if exist([filePath,fileName],'file')
                info = whos('-file', [filePath,fileName]);
                if any(strcmp({info.class}, 'SSIT'))
                    Models = {info(strcmp({info.class}, 'SSIT')).name};
                    k = length(Models);
                    % defaultInput = {['Model',num2str(k+1)],'yes'};
                    defaultInput = {app.ModelFile.modelName,'yes'};
                    prompt = {['Model Name (Existing Models:',Models{:}],'Append (yes=append/no=overwrite)'};
                    saveSettings = inputdlg(prompt, dlgtitle, dims, defaultInput);
                    
                    eval([saveSettings{1},'=app.SSITModel;'])
                    if strcmp(saveSettings{2},'yes')
                        save([filePath,fileName],saveSettings{1},'-append')
                    else
                        save([filePath,fileName],saveSettings{1})
                    end
                else
                    error('File exists and does not contain SSIT models.')
                end
                app.ModelFile.fileName = [filePath,fileName];
                app.ModelFile.modelName = saveSettings{1};
            else
                defaultInput = {['Model',],'yes'};
                prompt = {'Model Name'};
                saveSettings = inputdlg(prompt, dlgtitle, dims, defaultInput);
                eval([saveSettings{1},'=app.SSITModel;'])
                save([filePath,fileName],saveSettings{1});
                app.ModelFile.fileName = [filePath,fileName];
                app.ModelFile.modelName = saveSettings{1};
            end
            app.FileModelLabel.Text = {['File: ',app.ModelFile.fileName];['Model: ',app.ModelFile.modelName]};
        end

        % Button pushed function: LoadPreviousProjectButton, 
        % ...and 1 other component
        function ModelLoadButtonPushed(app, event)
            app.ModelFile.propFileName = []; % Clear name of propensity functions.
            loadModelBP(app,event);
        end

        % Callback function
        function ButtonPushed(app, event)
            v = dir('Models');
            v = v(3:end);
            app.ModelUsePresetExampleTypeDropDown.Items = {v.name};
        end

        % Cell edit callback: ModelParameterTable
        function ModelParameterTableCellEdit(app, event)
            app.ReactionsTabOutputs.presetParameters = app.ModelParameterTable.Data(:,2);
            app.SSITModel.parameters = app.ModelParameterTable.Data;
            app.SensParameterSelectionTable.Data = app.SSITModel.parameters;
            fitChoices = app.fit_parameters_table.Data(:,3);
            app.fit_parameters_table.Data ={};
            for iPar = 1:size(app.SensParameterSelectionTable.Data,1)
                app.SensParameterSelectionTable.Data{iPar,3} = 'y';
                app.fit_parameters_table.Data(iPar,1) = app.SSITModel.parameters(iPar,1);
                app.fit_parameters_table.Data(iPar,2) = app.SSITModel.parameters(iPar,2);
                try
                    app.fit_parameters_table.Data{iPar,3} = fitChoices{iPar};
                catch
                    app.fit_parameters_table.Data{iPar,3} = 'y';
                end
            end
            
            % Any update or change to the Parameters table will result in a cleared graphs in the other tabs
            cla(app.SsaTrajAxes);
            cla(app.SsaHistAxes);
            cla(app.FspAxes);
        end

        % Cell edit callback: ModelInputTable
        function ModelInputTableCellEdit(app, event)
            app.ReactionsTabOutputs.presetInputs = app.ModelInputTable.Data(:,2);
            % Any update or change to the Inputs table will result in a cleared graphs in the other tabs
            cla(app.SsaTrajAxes);
            cla(app.SsaHistAxes);
            cla(app.FspAxes);
            updateModel(app)
            % selectSensitivityParametersTable(app); % Updates the Sensitivity Analysis Table
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            % This call back opens the selected model file.  If it is a
            % .mat file that contains SSIT models, then it populates a
            % dropdown list with the available models.  If there is just on
            % in the file, it opens that model.  
            % If the file is a .mat or .m script with different format, it
            % creates the SSIT model.
            
            fName = ['Models/',app.ModelUsePresetExampleTypeDropDown.Value,'/',app.ModelDropDown.Value];            
            app.ModelFile.propFileName = []; % Clear name of propensity functions.

            % Check if file contains SSIT model
            if strcmp(fName(end-2:end),'mat')
                loadModelBP(app, event, fName);
                %
                % info = whos('-file', fName);
                % % Check if any variable is of class 'SSIT'
                % if any(strcmp({info.class}, 'SSIT'))
                %     app.ModelFile.fileName = fName;
                %     app.ChooseSSITModel.Items = {info(strcmp({info.class}, 'SSIT')).name};
                %     app.ChooseSSITModel.Visible = 'on';
                %     app.ChooseSSITModelLabel.Visible = 'on';
                %     % Load the mosel if there is just one.
                %     if length(app.ChooseSSITModel.Items)==1
                %         Model = load(fName,app.ChooseSSITModel.Items{1});
                %         app.SSITModel = Model.(app.ChooseSSITModel.Items{1});
                %         app.ModelFile.modelName = app.ChooseSSITModel.Items{1};
                %         updateAppFromSSIT(app);
                %     end
                return
                % end
            end

            % If no SSIT Model, call function to create SSIT model.
            app.ChooseSSITModel.Visible = 'off';
            app.ChooseSSITModelLabel.Visible = 'off';
            updateModelswithinDropDown(app);
        end

        % Callback function
        function ModelEmptyParametersandInputsButtonPushed(app, event)
            % This clears all of the current parameter values within the app
            ModelUpdateButtonPushed(app);
            ModelParameterTableCellEdit(app);
            ModelInputTableCellEdit(app);
        end

        % Button pushed function: SsaExportFiguresButton
        function SsaExportFiguresButtonPushed(app, event)
            exportBothFiguresSsa(app,1,1); % This exports figures into a Matlab figure
        end

        % Button pushed function: SsaExportTrajectoriesButton
        function SsaExportTrajectoriesButtonPushed(app, event)
            exportBothFiguresSsa(app,1,0); % This exports figures into a Matlab figure
        end

        % Button pushed function: SsaExportHistogramButton
        function SsaExportHistogramButtonPushed(app, event)
            exportBothFiguresSsa(app,0,1); % This exports figures into a Matlab figure
        end

        % Callback function
        function ModelCitationforPresetExamplesTextAreaValueChanged(app, event)
            %             value = app.ModelCitationforPresetExamplesTextArea.Value;
            %%% This text area cannot be changed, so I dont think we need this!
        end

        % Button pushed function: FspPlotMarginalsButton
        function FspPlotMarginalsButtonPushed(app, event)
            makeMarginalsFsp(app);
            % This creates the marginal plots for FSP from the function cited
        end

        % Button pushed function: FspPlotMarginalsOverTimeButton
        function FspPlotMarginalsOverTimeButtonPushed(app, event)
            makeTimeMarginalsFsp(app); % This creates time varying marginals in a matlab figure
        end

        % Button pushed function: FspMeanVarPlotButton
        function FspMeanVarPlotButtonPushed(app, event)
            makeMeanAndVarianceFsp(app); % Creates mean and variance plots in a matlab figure
        end

        % Value changed function: FspContourCheckBox
        function FspContourCheckBoxValueChanged(app, event)
            value = app.FspContourCheckBox.Value; % This function disables the Mesh plot if the contour plot is selected
            if value == 1
                app.FspMeshCheckBox.Value = 0;
                app.FspMeshCheckBox.Enable = 'off';
            elseif value == 0
                app.FspMeshCheckBox.Enable = 'on';
            end

        end

        % Value changed function: FspMeshCheckBox
        function FspMeshCheckBoxValueChanged(app, event)
            value = app.FspMeshCheckBox.Value; % This function disables the contour plot if the mesh plot is selected
            if value == 1
                app.FspContourCheckBox.Value = 0;
                app.FspContourCheckBox.Enable = 'off';
            elseif value == 0
                app.FspContourCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: PlotJointDistributionsButton
        function PlotJointDistributionsButtonPushed(app, event)
            makeJointDistributionsFsp(app); % Creates the joint distribution as a matlab figure
        end

        % Callback function
        function SelectParametersDropDownValueChanged(app, event)
            % For the sensitivity tab %%% Can I remove this? No longer in use
            value = app.SelectParametersDropDown.Value;
            st = char(app.ParametersSelectedTextArea.Value);
            %             keyboard
            if ~isempty(st)
                mynewvalue = [st,',',value];
            elseif isempty(st)
                mynewvalue = value;
            end

            app.ParametersSelectedTextArea.Value = mynewvalue;

        end

        % Callback function
        function SelectAllParametersCheckBoxValueChanged(app, event)
            % For the sensitivity tab %%% Can I remove this? No longer in use
            value = app.SelectAllParametersCheckBox.Value;

            if  isequal(value,1)
                char_vec = char(app.SelectParametersDropDown.Items);
                mynewvalue = char_vec(1,:);
                for i = 2:length(char_vec)
                    mynewvalue = [mynewvalue,',',char_vec(i,:)];
                end
                app.ParametersSelectedTextArea.Value = mynewvalue;
            else
                return
            end

        end

        % Callback function
        function ClearSelectedParametersButtonPushed(app, event)
            app.ParametersSelectedTextArea.Value = '';
        end

        % Cell edit callback: SensParameterSelectionTable
        function SensParameterSelectionTableCellEdit(app, event)
            editSelectParametersTable(app);
        end

        % Callback function
        function SensInputTableCellEdit(app, event)
            editSelectParametersTable(app);
        end

        % Value changed function: FspPrintTimesField, 
        % ...and 3 other components
        function SensPrintTimesEditFieldValueChanged(app, event)
            updateTimeSliderFsp(app,event)
        end

        % Callback function
        function TimePointsEditFieldValueChanged(app, event)
            FIMTableEdit(app);
        end

        % Callback function
        function PerfectCheckBoxValueChanged(app, event)
            value = app.PerfectCheckBox.Value;
            if value == 1
                app.ofBinsEditField.Enable = 'off';
                app.BinEdgesEditField.Enable = 'off';
                app.UniformCheckBox.Value = 0;
                app.SpecifiedCheckBox.Value = 0;
            end
        end

        % Callback function
        function UniformCheckBoxValueChanged(app, event)
            value = app.UniformCheckBox.Value;
            if value == 1
                app.ofBinsEditField.Enable = 'on';
                app.BinEdgesEditField.Enable = 'off';
                app.PerfectCheckBox.Value = 0;
                app.SpecifiedCheckBox.Value = 0;
            end
        end

        % Callback function
        function SpecifiedCheckBoxValueChanged(app, event)
            value = app.SpecifiedCheckBox.Value;
            if value == 1
                app.ofBinsEditField.Enable = 'off';
                app.BinEdgesEditField.Enable = 'on';
                app.PerfectCheckBox.Value = 0;
                app.UniformCheckBox.Value = 0;
            end
        end

        % Callback function
        function ImageProcessingErrorsDropDownValueChanged(app, event)
            x1TabChange(app);
        end

        % Callback function
        function ImageProcessingErrorsDropDown_4ValueChanged(app, event)
            x2TabChange(app);
        end

        % Callback function
        function ImageProcessingErrorsDropDown_5ValueChanged(app, event)
            x3TabChange(app);
        end

        % Callback function
        function UniformProcessingButtonPushed(app, event)
            UniformProcessingX1(app);
        end

        % Callback function
        function UniformProcessingButton_2Pushed(app, event)
            UniformProcessingX2(app);
        end

        % Callback function
        function UniformProcessingButton_3Pushed(app, event)
            UniformProcessingX3(app);
        end

        % Callback function
        function ButtonPushed2(app, event)
            StoreCEquation(app);
        end

        % Callback function
        function LoadandPlotDansDataButtonPushed(app, event)
            Update_Dans_Plots(app);
        end

        % Callback function
        function UpdateModelandSolveButtonPushed(app, event)
            app.ModelParameterTable.Data=app.tab_pars_Dan.Data;
            app.ModelInputTable.Data=app.tab_inp_Dan.Data;
            ModelParameterTableCellEdit(app);
            ModelInputTableCellEdit(app);
            FspRunButtomPushed(app);
            LoadandPlotDansDataButtonPushed(app);
        end

        % Callback function
        function tab_pars_DanCellEdit(app, event)
            app.ModelParameterTable.Data=app.tab_pars_Dan.Data;
            app.ModelInputTable.Data=app.tab_inp_Dan.Data;
            ModelParameterTableCellEdit(app);
            ModelInputTableCellEdit(app);
        end

        % Callback function
        function SlowFitAutomaticButtonValueChanged(app, event)
            random_greedy_fit(app);
        end

        % Callback function
        function DistributionTypeButtonGroupSelectionChanged(app, event)
            Update_Dans_Plots(app);
        end

        % Callback function
        function LoadPreviousModelButtonPushed(app, event)
            ssit.parest.LoadOldProject(app);
            app.SaveCurrentProjectButton.Enable=1;
            LoadandConstrainDataButtonPushed(app, event);
        end

        % Callback function
        function SaveCurrentParameterSetButtonPushed(app, event)
            Update_Dans_Plots(app);
            [~,~,par_name]=SaveModelBP(app,event,app.NameForModelSavingEditField.Value);
            app.SaveParSetName.Text = ['saved as ',par_name];
        end

        % Button pushed function: ParEstLoadDataButton
        function ParEstLoadDataButtonPushed(app, event)
            app.DataLoadingAndFittingTabOutputs.dataTensor =[];
            loadDataFromFile(app);
            % app.LinkDatandModelButton.Enable = 1;
            % marginalizeDataGui(app);
            selectallButtonPushed(app, event)
            % LoadandConstrainDataButtonPushed(app, event)
            % app.SaveCurrentProjectButton.Enable=1;
        end

        % Callback function
        function x1_data_CheckBoxValueChanged(app, event)
            defineDataInTermsOfModel(app);
        end

        % Callback function
        function ShowPlotsButtonPushed(app, event)
            makeSeparatePlotOfData(app);
        end

        % Button pushed function: LoadandConstrainDataButton
        function LoadandConstrainDataButtonPushed(app, event)
            parseDataConstraints(app)
            % ssit.parest.updateModelSolveAndCompareToData(app);            
            % makeSeparatePlotOfData(app);
            % figure(app.UIFigure);
        end

        % Callback function
        function SaveCurrentParameterSetButton_2Pushed(app, event)
            updateModelSolveAndCompareToData(app);
            %         app.name_of_fit_save_model.Value
            [~,~,par_name]=SaveModelBP(app,event,app.name_of_fit_save_model.Value);
            app.SaveParSetName_2.Text = ['saved as ',par_name];
        end

        % Value changed function: FitModelButton
        function FitModelButtonValueChanged(app, event)
            % fitFileStr = ['Results_',app.FitResultsNameText.Text,datestr(now,'yyyy_mm_dd_HH_MM_SS'),'.mat'];

            fitAlgorithm = app.FittingAlgorithmDropDown.Value;
            try
                fitOptions = app.DataLoadingAndFittingTabOutputs.fitOptions.props;
            catch
                fitOptions =[];
            end
            
            if ~isempty(fitOptions)
                fields = fieldnames(fitOptions);
                for ifield = 1:length(fields)
                    field = fields{ifield};
                    if isstring(fitOptions.(field))||ischar(fitOptions.(field))
                        if strcmp(fitOptions.(field)(1),'@')
                            fitOptions.(field) = eval(fitOptions.(field));
                        end
                    end
                end
            end

            app.SSITModel.fittingOptions.modelVarsToFit = find(strcmp(app.fit_parameters_table.Data(:,3),'y'));

            [pars,likelihood,otherResults,app.SSITModel] = app.SSITModel.maximizeLikelihood([],fitOptions,fitAlgorithm);

            updateAppFromSSIT(app,false,false);

            % ssit.parest.fitModel2Data(app);
            % if strcmp(app.FittingAlgorithmDropDown.Value,'Metropolis Hastings')
            %     set(app.GraphPosteriorDistributionsButton,'Enable','on');
            % else
            %     set(app.GraphPosteriorDistributionsButton,'Enable','off');
            % end
            % ssit.parest.SaveCurrentProject(app,app.DataLoadingAndFittingTabOutputs.backgroundFitDir,fitFileStr);

        end

        % Callback function
        function FilteronConditionCheckBoxValueChanged(app, event)
            userSelectCondition(app);
        end

        % Value changed function: FitinBackgroundmaclinuxonlyButton
        function FitinBackgroundmaclinuxonlyButtonValueChanged(app, event)
            if ispc
                warndlg('Background fitting not yet supported on Windows.','Background Warning');
                return
            end
            
            fitResultsFileStr = ['Results_',app.FitResultsNameText.Text,datestr(now,'yyyy_mm_dd_HH_MM_SS'),'.mat'];
            fitDataFileStr = ['Data_',app.FitResultsNameText.Text,'.mat'];
            fitFileName = [app.DataLoadingAndFittingTabOutputs.backgroundFitDir,'/',fitDataFileStr];
            resultName =  [app.DataLoadingAndFittingTabOutputs.backgroundFitDir,'/',fitResultsFileStr];
            ssit.parest.fitModel2Data(app,fitFileName);
            ssit.parest.runBackgroundFit(fitFileName,resultName);
        end

        % Value changed function: SSAXmaxEditField
        function SSAXmaxEditFieldValueChanged(app, event)
            value = app.SSAXmaxEditField.Value;
            app.SsaTrajAxes.XLim(2) = value;
        end

        % Value changed function: SSAYmaxEditField
        function SSAYmaxEditFieldValueChanged(app, event)
            value = app.SSAYmaxEditField.Value;
            app.SsaTrajAxes.YLim(2) = value;
        end

        % Value changed function: SSAXminEditField
        function SSAXminEditFieldValueChanged(app, event)
            value = app.SSAXminEditField.Value;
            app.SsaTrajAxes.XLim(1) = value;
        end

        % Callback function
        function SSAYminEditFieldValueChanged(app, event)
            value = app.SSAYminEditField.Value;
            app.SsaTrajAxes.YLim(1) = value;
        end

        % Value changed function: SsaNonParallelCheckBox
        function SsaNonParallelCheckBoxValueChanged(app, event)
            if app.SsaNonParallelCheckBox.Value
                app.SsaParallelCheckBox.Value = 0;
            end
        end

        % Value changed function: SsaParallelCheckBox
        function SsaParallelCheckBoxValueChanged(app, event)
            if app.SsaParallelCheckBox.Value
                app.SsaNonParallelCheckBox.Value = 0;
            end
        end

        % Value changed function: FSPXminEditField
        function FSPXminEditFieldValueChanged(app, event)
            value = app.FSPXminEditField.Value;
            app.FspAxes.XLim(1) = value;
        end

        % Value changed function: FSPXmaxEditField
        function FSPXmaxEditFieldValueChanged(app, event)
            value = app.FSPXmaxEditField.Value;
            app.FspAxes.XLim(2) = value;
        end

        % Value changed function: FSPYminEditField
        function FSPYminEditFieldValueChanged(app, event)
            value = app.FSPYminEditField.Value;
            app.FspAxes.YLim(1) = value;
        end

        % Value changed function: FSPYmaxEditField
        function FSPYmaxEditFieldValueChanged(app, event)
            value = app.FSPYmaxEditField.Value;
            app.FspAxes.YLim(2) = value;
        end

        % Callback function
        function SSA_hist_XminEditFieldValueChanged(app, event)

        end

        % Callback function
        function SSA_hist_XmaxEditFieldValueChanged(app, event)

        end

        % Callback function
        function SSA_hist_YminEditFieldValueChanged(app, event)

        end

        % Value changed function: SSAYminEditField, 
        % ...and 4 other components
        function SSA_hist_YmaxEditFieldValueChanged(app, event)
            app.SsaHistAxes.YLim = [app.SSA_hist_YminEditField.Value,app.SSA_hist_YmaxEditField.Value];
            app.SsaHistAxes.XLim = [app.SSA_hist_XminEditField.Value, app.SSA_hist_XmaxEditField.Value];
            app.SsaTrajAxes.YLim = [app.SSAYminEditField.Value,app.SSAYmaxEditField.Value];
            app.SsaTrajAxes.XLim = [app.SSAXminEditField.Value, app.SSAXmaxEditField.Value];
        end

        % Button pushed function: SensRunButton
        function SensRunButtonPushed(app, event)
            if app.UnsavedChanges
                disp('Model changes not saved -- updating model.')
                updateModel(app);
            end
            if isempty(app.FspConstraintTable.Data)
                FspDefaultButtonPushed(app);
            end
            runSensitivity(app);
        end

        % Value changed function: SensPlotTimeSlider, SpeciesForSensPlot
        function SensPlotTimeSliderValueChanged(app, event)
            updatePlotsInSensFsp(app);
        end

        % Value changed function: SensParDropDown
        function SensParDropDownValueChanged(app, event)
            updatePlotsInSensFsp(app);
        end

        % Button pushed function: FspApproximateSteadyStateButton
        function FspApproximateSteadyStateButtonPushed(app, event)
            app.FspAutoExpandCheckBox.Value = 0;
            runFsp(app,'steady_state');
            updatePlotsInFsp(app);
            figure(app.UIFigure);
        end

        % Button pushed function: SsaExportExcelButton
        function SsaExportExcelButtonPushed(app, event)
            exportSsaData(app);
        end

        % Button pushed function: IncreaseFontSizeData, 
        % ...and 5 other components
        function IncreaseFontRxnPushed(app, event)
            increaseGlobalFontSize(app);
        end

        % Button pushed function: DecreaseFontSizeData, 
        % ...and 5 other components
        function DecreaseFontRxnPushed(app, event)
            decreaseGlobalFontSize(app);
        end

        % Callback function
        function incr(app, event)

        end

        % Callback function
        function SensMarginalPlotProbButtonPushed(app, event)
            plotMarginalinSensTab(app);
        end

        % Callback function
        function SensMarginalPlotDerivativeButtonPushed(app, event)
            plotSensinSensTab(app);
        end

        % Callback function
        function PlotMarginalsButtonPushed(app, event)
            makeTimeMarginalsSensTab(app);
        end

        % Callback function
        function PlotSensitivityButtonPushed(app, event)
            makeTimeSensitivitySensTab(app);
        end

        % Callback function
        function PlotJointDistributionsButton_2Pushed(app, event)
            makeJointDistributionsSens(app);
        end

        % Callback function
        function PlotMeanandVarianceButton_2Pushed(app, event)
            makeMeanAndVarianceSens(app);
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            if isempty(app.DataLoadingAndFittingTabOutputs.fitOptions)
                ssit.parest.changeFitType(app);
            end
            ssit.parest.PropEditor(app.DataLoadingAndFittingTabOutputs.fitOptions,'props');
        end

        % Value changed function: FittingAlgorithmDropDown
        function FittingAlgorithmDropDownValueChanged(app, event)
            ssit.parest.changeFitType(app);
        end

        % Callback function
        function LoadFitWithAlternateNameButtonValueChanged(app, event)
            ssit.parest.loadBackgroundFits(app);
        end

        % Callback function
        function RunMomentsButtonPushed(app, event)
            runMoments(app);
            plotMomentsInGui(app);
        end

        % Callback function
        function UpdatePlotButtonPushed(app, event)
            plotMomentsInGui(app);
        end

        % Button pushed function: GraphPosteriorDistributionsButton
        function GraphPosteriorDistributionsButtonPushed(app, event)
            ssit.parest.FittingGraphs(app.DataLoadingAndFittingTabOutputs,app.ReactionsTabOutputs.parameters,'props');
        end

        % Callback function
        function NameTableCellEdit(app, event)
            editCheckBoxNames(app);
        end

        % Callback function
        function LinkDataandModelButtonPushed(app, event)
            marginalizeDataGui(app);
            selectallButtonPushed(app, event);
            LoadandConstrainDataButtonPushed(app);
        end

        % Value changed function: ParEstFitTimesList
        function ParEstFitTimesListValueChanged(app, event)
            values = app.ParEstFitTimesList.Value;
            fitTimes = zeros(1,length(values));
            for i=1:length(values)
                fitTimes(i) = eval(values{i});
            end
            app.DataLoadingAndFittingTabOutputs.fittingOptions.fit_times = fitTimes;
            
            items = app.ParEstFitTimesList.Items;
            dataTimes = zeros(1,length(items));
            for i=1:length(items)
                dataTimes(i) = eval(items{i});
            end
            app.DataLoadingAndFittingTabOutputs.fittingOptions.dataTimes = dataTimes;

            maxFitTime = max(fitTimes);
            allTimes = sort(unique([(fitTimes),(str2num(app.FspPrintTimesField.Value))]));
            allTimes = allTimes(allTimes<=maxFitTime);
            app.FspPrintTimesField.Value = ['[',num2str(allTimes),']'];

            app.SSITModel.fittingOptions.timesToFit = zeros(1,length(app.ParEstFitTimesList.Items),'logical');
            app.SSITModel.fittingOptions.timesToFit(app.ParEstFitTimesList.ValueIndex) = true;
        end

        % Button pushed function: SetPriorButton
        function SetPriorButtonPushed(app, event)
            ssit.parest.PropEditor(app.DataLoadingAndFittingTabOutputs.priorOptions,'props');
        end

        % Value changed function: PriorTypeDropDown
        function PriorTypeDropDownValueChanged(app, event)
            ssit.parest.SetPriors(app);
        end

        % Button pushed function: selectallButton
        function selectallButtonPushed(app, event)
            app.ParEstFitTimesList.Value = app.ParEstFitTimesList.Items;
            % ParEstFitTimesListValueChanged(app, event);
        end

        % Value changed function: RefreshResultsButton
        function RefreshResultsButtonValueChanged(app, event)
            GG = dir(app.DataLoadingAndFittingTabOutputs.backgroundFitDir);
            J = contains({GG.name},'Results')&contains({GG.name},'.mat');
            fNames = {GG(J).name};
            app.LoadPreviousResultsDropDown.Items = {};
            app.LoadPreviousResultsDropDown.Items{1} = 'Select';
            app.LoadPreviousResultsDropDown.Items{2} = 'Best';
            app.DataLoadingAndFittingTabOutputs.completedFits = {};
            if isempty(fNames)
                warning('No Previous Fits in this Directory');
                return
            end
            for i=1:length(fNames)
                % This needs to be fixed to work with the merged models.
                load(['',app.DataLoadingAndFittingTabOutputs.backgroundFitDir,'/',fNames{i},''],'Loc_app_str')
                app.LoadPreviousResultsDropDown.Items{i+2} = [fNames{i}(1:end-4),...
                    ' log(L) = ',sprintf('%0.8g',Loc_app_str.DataLoadingAndFittingTabOutputs.J_LogLk)];
                app.DataLoadingAndFittingTabOutputs.completedFits{i+2,1} = fNames{i};
                app.DataLoadingAndFittingTabOutputs.completedFits{i+2,2} = Loc_app_str.DataLoadingAndFittingTabOutputs.J_LogLk;
            end
            maxL = max([app.DataLoadingAndFittingTabOutputs.completedFits{2:end,2}]);
            app.LoadPreviousResultsDropDown.Items{2} = ['Select Best: log(L) =',sprintf('%0.8g',maxL)];

        end

        % Value changed function: LoadPreviousResultsDropDown
        function LoadPreviousResultsDropDownValueChanged(app, event)
            J = find(strcmp(app.LoadPreviousResultsDropDown.Items,app.LoadPreviousResultsDropDown.Value));

            if J==2
                [~,j] = max([app.DataLoadingAndFittingTabOutputs.completedFits{2:end,2}]);
                J = j+2;
            end
            choice = app.DataLoadingAndFittingTabOutputs.completedFits{J,1};
            ssit.parest.loadBackgroundFits(app,choice)

        end

        % Callback function
        function InitializeButtonPushed(app, event)
            ssit.parest.ModelMergeInitiate(app);
            app.AddButton.Enable = 1;
        end

        % Callback function
        function AddButtonPushed(app, event)
            ssit.parest.ModelMergeAdd(app);
            app.MergeButton.Enable = 1;
        end

        % Callback function
        function MergeButtonPushed(app, event)
            ssit.parest.ModelMergeMerge(app);
            app.MergedModelDropDown.Enable = 1;
        end

        % Button pushed function: LoadSBMLModelButton
        function LoadSBMLModelButtonPushed(app, event)
            loadModelFromSBML(app)
        end

        % Callback function
        function SaveCurrentProjectButtonPushed(app, event)
            ssit.parest.SaveCurrentProject(app)
        end

        % Callback function
        function MergedModelDropDownValueChanged(app, event)
            ssit.parest.ChangeModelForPlot(app)
            LoadandConstrainDataButtonPushed(app)
        end

        % Callback function
        function ResetButtonPushed(app, event)
            try
                app.DataLoadingAndFittingTabOutputs = rmfield(app.DataLoadingAndFittingTabOutputs,'ModelMerge');
            catch
            end
            app.fit_parameters_table.Data = {};
            app.fit_parameters_table.Data(:,1:2) = app.ModelParameterTable.Data;
            app.fit_parameters_table.Data(:,3) = {'y'};
            app.MergeModelConstraints.Data = {};
            app.MergedModelDropDown.Enable = 0;
            app.AddButton.Enable = 0;
            app.MergeButton.Enable  = 0;
            app.ResetButton.Enable  = 0;
            app.ModelList.Text = 'No merged models';

        end

        % Callback function
        function MergeModelConstraintsCellEdit(app, event)
            app.ModelList.Text = app.DataLoadingAndFittingTabOutputs.ModelMerge.FileName;
            app.ModelList.Text(end+1) = {'Model not yet merged! Press the merge button.'};
        end

        % Callback function
        function SelectFolderandFileforBackgroundFittingButtonValueChanged(app, event)
            [FN,PN] = uiputfile('*.mat','Choose or Create file for Fitting Results','Untitled.mat');
            app.DataLoadingAndFittingTabOutputs.backgroundFitDir=PN;
            app.DataLoadingAndFittingTabOutputs.backgroundFitFile=FN;
        end

        % Button pushed function: CreateNewNStateModelButton
        function CreateNewNStateModelButtonPushed(app, event)
            NStateModelMaker(app);
        end

        % Callback function
        function OpenFitPlotsButtonPushed(app, event)
            makeSeparatePlotOfData(app);
        end

        % Callback function: FIMMetricorParameterDropDown, 
        % ...and 1 other component
        function ComputeFIMandPlotInformationvsTimeButtonPushed(app, event)
            if isempty(app.FspConstraintTable.Data)
                FspDefaultButtonPushed(app);
            end
            runComputeFim(app,strcmp(app.PlotStlyeDropDown.Value,'New Information vs. time'));
        end

        % Button pushed function: 
        % ManuallyAllocateMeasurementsperTimePointButton
        function ManuallyAllocateMeasurementsperTimePointButtonPushed(app, event)
            FIMManuallyAllocateMeasurements(app)
        end

        % Callback function: EstimateMLEUncertaintyButton, 
        % ...and 3 other components
        function EstimateMLEUncertaintyButtonPushed(app, event)
            FIMEstimateUncertainty(app)
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            FIMOptimize(app)
        end

        % Callback function
        function ListofMeasurementTimesEditFieldValueChanged(app, event)
            updateTimeSliderFsp(app,event)
        end

        % Callback function
        function PlotRedundancyvsTimeButtonPushed(app, event)

        end

        % Button pushed function: SpecifyPriorButton
        function SpecifyPriorButtonPushed(app, event)
            FIMSpecifyPrior(app)
        end

        % Callback function
        function LoadSavedPriorButtonPushed(app, event)
            FIMLoadPrior(app)
        end

        % Button pushed function: AddProbabilisticDistortionEffectsButton
        function AddProbabilisticDistortionEffectsButtonPushed(app, event)
            FIMLaunchDistortion(app)
        end

        % Value changed function: ModelUncertaintyDropDown
        function ModelUncertaintyDropDownValueChanged(app, event)
            app.FIMTabOutputs.FIMMatrices=[];
            if strcmp(app.ModelUncertaintyDropDown.Value,'MC Sample Over Prior')
                app.SpecifyPriorButton.Enable = 1;
                app.FIMNumMC.Enable = 1;
            else
                app.SpecifyPriorButton.Enable = 0;
                app.FIMNumMC.Enable = 0;
            end
        end

        % Button pushed function: LaunchMultiDataFittingToolButton
        function LaunchMultiDataFittingToolButtonPushed(app, event)
            SSITMergeModels(app)
        end

        % Value changed function: DistortionTypeDropDown
        function DistortionTypeDropDownValueChanged(app, event)
            % app.DistortionTypeDropDown.Value = event.Source.Value;
            ssit.pdo.changePdoType(app)
            try 
                UpdatePDOParametersButtonPushed(app)
            catch
            end
            [~,app.SSITModel.pdoOptions.PDO] = ssit.pdo.generatePDO(app);
            
            % Reset prior to none.
            app.PriorTypeDropDown.Value = 'None';
            ssit.parest.SetPriors(app);
        end

        % Button pushed function: SetDistortionParametersButton
        function SetDistortionParametersButtonPushed(app, event)
            app.SSITModel.pdoOptions.PDO = [];
            disp('Please use the GUI to enter the distortion operator parameters.')
            ssit.parest.PropEditor(app.FIMTabOutputs.PDOProperties,'props',{'Parameter','Value'},0);
        end

        % Callback function: ShowDistortionPlotButton,
        % SolutionTimeDropDown, 
        % ...and 1 other component
        function ShowDistortionPlotButtonPushed(app, event)
            ssit.pdo.showPdoPlot(app)
        end

        % Button pushed function: UpdatePDOParametersButton
        function UpdatePDOParametersButtonPushed(app, event)
            app.pdo_parameters_table.Data = {};
            if isnumeric(app.FIMTabOutputs.PDOProperties.props.ParameterGuess)
                paramsPDO = app.FIMTabOutputs.PDOProperties.props.ParameterGuess;
            else
                paramsPDO = eval(app.FIMTabOutputs.PDOProperties.props.ParameterGuess);
            end
            for i = 1:length(paramsPDO)
                app.pdo_parameters_table.Data{end+1,1} = ['p_PDO_',num2str(i)];
                app.pdo_parameters_table.Data{end,2} = paramsPDO(i);
                app.pdo_parameters_table.Data{end,3} = 'n';
            end         
        end

        % Value changed function: HoldOnCheckBox
        function HoldOnCheckBoxValueChanged(app, event)
            if app.HoldOnCheckBox.Value
                hold(app.plotFIMvsTime,"on");
            else
                hold(app.plotFIMvsTime,"off");
            end
        end

        % Button pushed function: LaunchFSPModelReductionToolButton
        function LaunchFSPModelReductionToolButtonPushed(app, event)
            FSPModelReducer(app)
        end

        % Value changed function: FspInitCondField, SensInitCondField, 
        % ...and 1 other component
        function SsaInitCondFieldValueChanged(app, event)
            value = event.Source.Value;
            app.SsaInitCondField.Value=value;
            app.FspInitCondField.Value=value;
            app.SensInitCondField.Value=value;
            app.FIMTabOutputs.CellsPerTimePoint=[];
            app.FIMTabOutputs.FIMMatrices=[];
            app.FIMTabOutputs.NcOptimized=[];
            app.SensFspTabOutputs.solutions=[];
        end

        % Value changed function: PlotStlyeDropDown
        function PlotStlyeDropDownValueChanged(app, event)
            pars = app.ReactionsTabOutputs.parameters;
            switch(app.PlotStlyeDropDown.Value)
                case 'Information vs. time'
                    FIMMetrics = {'Trace'};
%                     FIMMetrics(end+1:end+size(pars,1)) = pars(:,1);
                    app.FIMMetricorParameterDropDown.Items = FIMMetrics;
                    app.FIMMetricorParameterDropDown.Value = 'Trace';
                case 'New Information vs. time'
                    FIMMetrics = {'Determinant','Smallest Eigenvalue','Trace'};
                    FIMMetrics(end+1:end+size(pars,1)) = pars(:,1);
                    app.FIMMetricorParameterDropDown.Items = FIMMetrics;
                    app.FIMMetricorParameterDropDown.Value = 'Determinant';
            end
            
        end

        % Button pushed function: 
        % ExportFSPDatatoWorkspaceForManualPlotsButton
        function ExportFSPDatatoWorkspaceForManualPlotsButtonPushed(app, event)
            exportFSPResults(app);
        end

        % Button pushed function: 
        % ExportSensitivityDatatoWorkspaceForManualPlotsButton
        function ExportSensitivityDatatoWorkspaceForManualPlotsButtonPushed(app, event)
            exportSensResults(app);
        end

        % Value changed function: SpeciestoShowListBox
        function SpeciestoShowListBoxValueChanged(app, event)
            plotSsaInGui(app)
        end

        % Value changed function: AddSpeciesButton
        function AddSpeciesButtonValueChanged(app, event)
            prompt = {'Species name:','Initial value:'};
            dlgtitle = 'New Species';
            dims = [1 40];  % [height width] of input field
            defaultInput = {'x1','0'};
            newSpecies = inputdlg(prompt, dlgtitle, dims, defaultInput);
            app.SSITModel=app.SSITModel.addSpecies(newSpecies{1},round(eval(newSpecies{2})));
            app.SpeciesTable.Data(end+1,1) = newSpecies(1);
            app.SpeciesTable.Data(end,2) = {eval(newSpecies{2})};
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated.'; % This updates the text by the Update Model Button to notify the user that the model parameters have not been updated
            app.UnsavedChanges = true;

        end

        % Value changed function: AddInputButton
        function AddInputButtonValueChanged(app, event)
            prompt = {'Signal name:','Signal expression:'};
            dlgtitle = 'New Input Signal';
            dims = [1 40];  % [height width] of input field
            nSig = size(app.ModelInputTable.Data,1)+1;
            defaultInput = {['I',num2str(nSig)],'A*sin(Omega*t)'};
            newSignal = inputdlg(prompt, dlgtitle, dims, defaultInput);
           
            app.ModelInputTable.Data(nSig,1) = newSignal(1);
            app.ModelInputTable.Data(nSig,2) = newSignal(2);
            app.SSITModel.inputExpressions(end+1,1) = newSignal(1);
            app.SSITModel.inputExpressions(end,2) = newSignal(2);            
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated!';
            app.DeleteInputDropDown.Enable = "on";
            app.DeleteInputDropDown.Items(end+1) = app.ModelInputTable.Data(nSig,1);
            app.UnsavedChanges = true;
        end

        % Value changed function: ModelAbout
        function ModelAboutValueChanged(app, event)
            % Update information in model when next saved.
            app.SSITModel.description = app.ModelAbout.Value;
        end

        % Value changed function: ChooseSSITModel
        function ChooseSSITModelValueChanged(app, event)
            ChooseSSITModelValue(app);
        end

        % Cell edit callback: SpeciesTable
        function SpeciesTableCellEdit(app, event)
            for i=1:size(app.SpeciesTable.Data,1)
                app.SSITModel.initialCondition(i) = app.SpeciesTable.Data{i,2};
            end
            app.SsaInitCondField.Value = append('[',num2str(app.SSITModel.initialCondition'),']');
            app.FspInitCondField.Value = append('[',num2str(app.SSITModel.initialCondition'),']');
            app.SensInitCondField.Value = append('[',num2str(app.SSITModel.initialCondition'),']');

        end

        % Value changed function: DeleteReactionDropDown
        function DeleteReactionDropDownValueChanged(app, event)
            value = app.DeleteReactionDropDown.Value;
            J = ones(size(app.ModelReactionTable.Data,1),1,'logical');
            iRxn = eval(value(2:end));
            J(iRxn) = 0;
            app.ModelReactionTable.Data = app.ModelReactionTable.Data(J,:);
            J = [true;J];
            app.DeleteReactionDropDown.Items = {'Select'};
            for iRxn = 1:size(app.ModelReactionTable.Data,1)
                app.ModelReactionTable.Data{iRxn,1} = ['R',num2str(iRxn)];
                app.DeleteReactionDropDown.Items{iRxn+1} = ['R',num2str(iRxn)];
            end
            app.DeleteReactionDropDown.Value = app.DeleteReactionDropDown.Items{1};
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated.'; % This updates the text by the Update Model Button to notify the user that the model parameters have not been updated
            app.UnsavedChanges = true;
        end

        % Value changed function: DeleteSpeciesDropDown
        function DeleteSpeciesDropDownValueChanged(app, event)
            value = app.DeleteSpeciesDropDown.Value;
            J = strcmp(app.SSITModel.species,value);
            app.SSITModel.species=app.SSITModel.species(~J);
            app.SpeciesTable.Data = app.SpeciesTable.Data(~J,:);
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated.'; % This updates the text by the Update Model Button to notify the user that the model parameters have not been updated
            app.DeleteSpeciesDropDown.Items = ['Select';app.SSITModel.species];
            app.UnsavedChanges = true;
        end

        % Value changed function: DeleteInputDropDown
        function DeleteInputDropDownValueChanged(app, event)
            value = app.DeleteInputDropDown.Value;
            J = ones(size(app.ModelInputTable.Data,1),1,'logical');
            for i = 1:length(J)
                if strcmp(app.ModelInputTable.Data{i,1},value)
                    J(i) = 0;
                end
            end
            app.ModelInputTable.Data = app.ModelInputTable.Data(J,:); 
            app.DeleteInputDropDown.Value = app.DeleteInputDropDown.Items{1};
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has NOT been updated.'; % This updates the text by the Update Model Button to notify the user that the model parameters have not been updated
            app.UnsavedChanges = true;
        end

        % Value changed function: ChooseSSITModel_2
        function ChooseSSITModel_2ValueChanged(app, event)
            value = app.ChooseSSITModel_2.Value;
            app.ChooseSSITModel.Value = value;
            ChooseSSITModelValueChanged(app,event);
            
        end

        % Button pushed function: ClearConstraintsButton
        function ClearConstraintsButtonPushed(app, event)
            clearDataConstraints(app);
        end

        % Value changed function: DataConstrChoice1, DataConstrChoice2, 
        % ...and 4 other components
        function DataConstrChoice1ValueChanged(app, event)
            CreateDataConstaintOptions(app);
        end

        % Button pushed function: SolveandPlotButton
        function SolveandPlotButtonPushed(app, event)
            app.SSITModel.solutionScheme = 'fsp';
            [~,~,app.SSITModel] = app.SSITModel.solve;
            app.SSITModel.makeFitPlot
        end

        % Value changed function: RenameSpeciesDropDown
        function RenameSpeciesDropDownValueChanged(app, event)
            value = app.RenameSpeciesDropDown.Value;
            if strcmp(value,'Select')
                return  % no species selected.
            end
            prompt = {'New Species Name:'};
            dlgtitle = ['Enter new name for species',value];
            dims = [1 50];  % [height width] of input field
            defaultInput = {value};
            newname = inputdlg(prompt, dlgtitle, dims, defaultInput);
            J = strcmp(app.SSITModel.species,newname{1});
            if sum(J)>=1
                error('There is already another species with that name. ')
            elseif sum(J)==0
                J2 = strcmp(app.SSITModel.parameters(:,1),newname{1});
                if sum(J2)>0
                    disp('Warning -- there is a parameter by that name.  It will be removed and replaced with this new species.')
                end

                app.SSITModel.species(strcmp(app.SSITModel.species,value)) = newname;
                nRxn = length(app.SSITModel.propensityFunctions);
                for iRxn = 1:nRxn
                    app.SSITModel.propensityFunctions{iRxn} = strrep(app.SSITModel.propensityFunctions{iRxn},value,newname{1});
                    eqn =  str2sym(app.SSITModel.propensityFunctions{iRxn}); eqn =  subs(eqn,value,newname{1}); str = char(eqn);
                    app.SSITModel.propensityFunctions{iRxn} = str;
                    app.ModelReactionTable.Data{iRxn,4} = str;
                end
                ModelReactionTableCellEdit(app, event)
                updateAppFromSSIT(app,false,true);
            end

        end

        % Button pushed function: ModelSaveButton_2
        function ModelSaveButton_2Pushed(app, event)
            eval([app.ModelFile.modelName,'=app.SSITModel;'])
            saveGUI(app); % Call function to add GUI elements to save file if desired.
            if isfile(app.ModelFile.fileName)
                save(app.ModelFile.fileName,app.ModelFile.modelName,'-append');
            else
                save(app.ModelFile.fileName,app.ModelFile.modelName);
            end          
            info = dir(app.ModelFile.fileName);
            app.FileModelLabel.Text = {['File: ',app.ModelFile.fileName];...
                ['Model: ',app.ModelFile.modelName];...
                ['Last Saved: ',info.date]};        
        end

        % Button pushed function: NewModelButton
        function NewModelButtonPushed(app, event)
            app.ModelFile.fileName = 'GUIModels/NewModel.mat';
            app.ModelFile.modelName = 'BlankModel';
            app.SSITModel = SSIT('Empty');
            updateAppFromSSIT(app,false,true);
        end

        % Callback function
        function ModelLoadButton_2Pushed(app, event)
            
        end

        % Value changed function: UseHybridModelSwitch
        function UseHybridModelSwitchValueChanged(app, event)
            switch app.UseHybridModelSwitch.Value
                case 'On'
                    app.SSITModel.useHybrid = true;
                    app.ObservableSpeciesListBox_2.Visible = true;
                    if app.SSITModel.useHybrid&&~isempty(app.SSITModel.hybridOptions.upstreamODEs)
                        app.ObservableSpeciesListBox_2.Value = app.SSITModel.hybridOptions.upstreamODEs;
                    else
                        app.ObservableSpeciesListBox_2.Value = {};
                    end                        
                case 'Off'
                    app.SSITModel.useHybrid = false;
                    app.ObservableSpeciesListBox_2.Visible = false;
                    app.ObservableSpeciesListBox_2.Value = {};
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ModelLoadingandBuildingTab = uitab(app.TabGroup);
            app.ModelLoadingandBuildingTab.Tooltip = {'Use this tab to enter or change model.'};
            app.ModelLoadingandBuildingTab.Title = 'Model Loading and Building';
            app.ReactionTextAxes = uiaxes(app.ModelLoadingandBuildingTab);
            app.ReactionTextAxes.XTick = [];
            app.ReactionTextAxes.YTick = [];
            app.ModelReactionTable = uitable(app.ModelLoadingandBuildingTab);
            app.ModelReactionTable.ColumnName = {'Rxn Num.'; 'Reactants'; 'Products'; 'Propensity'};
            app.ModelReactionTable.RowName = {};
            app.ModelReactionTable.ColumnEditable = [false true true true];
            app.ModelReactionTable.CellEditCallback = createCallbackFcn(app, @ModelReactionTableCellEdit, true);
            app.ModelReactionTable.Tooltip = {'List number of reactants consumed or produced (e.g. ''rna(2)'' corresponds to 2 molecules of species ''rna''). Separate multiple reactant/products with comma.'; ''; 'You may use arbitrary parameter names and regular expressions (e.g. ''cos'' ''exp''  ''log''). '; ''; 'Free parameters that start with ''I'' (e.g. ''I1'' or  ''In2'') will be treated as inputs signals.'};
            app.ModelAddReactionButton = uibutton(app.ModelLoadingandBuildingTab, 'state');
            app.ModelAddReactionButton.ValueChangedFcn = createCallbackFcn(app, @ModelAddReactionButtonValueChanged, true);
            app.ModelAddReactionButton.Tooltip = {'Use this button to add another reaction to the model.'};
            app.ModelAddReactionButton.Text = 'Add Reaction';
            app.ModelhasnotbeenupdatedLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.ModelhasnotbeenupdatedLabel.Text = 'Model has not been updated.';
            app.ModelUpdateButton = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.ModelUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ModelUpdateButtonPushed, true);
            app.ModelUpdateButton.Tooltip = {'Press this button to update model with new parameters or inputs.'};
            app.ModelUpdateButton.Text = 'Update Model';
            app.ModelParameterTable = uitable(app.ModelLoadingandBuildingTab);
            app.ModelParameterTable.ColumnName = {'Name'; 'Value'};
            app.ModelParameterTable.RowName = {};
            app.ModelParameterTable.ColumnEditable = [false true];
            app.ModelParameterTable.CellEditCallback = createCallbackFcn(app, @ModelParameterTableCellEdit, true);
            app.ModelParameterTable.Tooltip = {'Adjust constant parameters here.'};
            app.ParametersLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.ParametersLabel.Text = 'Parameters';
            app.ModelInputTable = uitable(app.ModelLoadingandBuildingTab);
            app.ModelInputTable.ColumnName = {'Name'; 'Value'};
            app.ModelInputTable.RowName = {};
            app.ModelInputTable.ColumnEditable = [false true];
            app.ModelInputTable.CellEditCallback = createCallbackFcn(app, @ModelInputTableCellEdit, true);
            app.ModelInputTable.Tooltip = {'Adjust form and parameters of input signals here as functions of time (e.g ''k*(1+sin(t)'' ). Any new symbolic variables (e.g. ''k'' ) will be added to the list of free parameters above.'};
            app.DefineTimeVaryingInputSignalsLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.DefineTimeVaryingInputSignalsLabel.Text = 'Define Time-Varying Input Signals';
            app.ModelFolderLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.ModelFolderLabel.Text = 'Model Folder';
            app.ModelUsePresetExampleTypeDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.ModelUsePresetExampleTypeDropDown.Items = {'Other'};
            app.ModelUsePresetExampleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelUsePresetExampleTypeDropDownValueChanged, true);
            app.ModelUsePresetExampleTypeDropDown.Tooltip = {'Use this to select a directory with pre-made models.'};
            app.ModelUsePresetExampleTypeDropDown.Value = 'Other';
            app.ModelLoadButton = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.ModelLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelLoadButtonPushed, true);
            app.ModelLoadButton.Tooltip = {'Press this button to load a saved model.'};
            app.ModelLoadButton.Text = 'Load Model';
            app.ModelAbout = uitextarea(app.ModelLoadingandBuildingTab);
            app.ModelAbout.ValueChangedFcn = createCallbackFcn(app, @ModelAboutValueChanged, true);
            app.ModelAbout.Tooltip = {'Use this textbox to write information about model. (e.g., descriptions of species/reactions/inputs and citation information).'};
            app.ModelAbout.Value = {'Add model description here.'};
            app.ModelDropDownLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.ModelDropDownLabel.Text = 'Model ';
            app.ModelDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Tooltip = {'Use this to select a pre-made model.'};
            app.ModelDropDown.Value = {};
            app.IncreaseFontSizeRxn = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.IncreaseFontSizeRxn.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeRxn.Visible = 'off';
            app.IncreaseFontSizeRxn.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeRxn.Text = '+';
            app.DecreaseFontSizeRxn = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.DecreaseFontSizeRxn.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeRxn.Visible = 'off';
            app.DecreaseFontSizeRxn.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeRxn.Text = '-';
            app.FontSizeLabelRxn = uilabel(app.ModelLoadingandBuildingTab);
            app.FontSizeLabelRxn.Visible = 'off';
            app.FontSizeLabelRxn.Text = {'Font Size'; ''};
            app.SubFolderLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.SubFolderLabel.Text = 'Sub Folder';
            app.LoadSBMLModelButton = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.LoadSBMLModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSBMLModelButtonPushed, true);
            app.LoadSBMLModelButton.Tooltip = {'Press this to load a model from SBML.'};
            app.LoadSBMLModelButton.Text = 'Load SBML Model';
            app.DeleteReactionDropDown_2Label = uilabel(app.ModelLoadingandBuildingTab);
            app.DeleteReactionDropDown_2Label.Text = 'Delete Reaction';
            app.DeleteReactionDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.DeleteReactionDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteReactionDropDownValueChanged, true);
            app.DeleteReactionDropDown.Tooltip = {'Use this to delete a reaction from the model.'};
            app.AddSpeciesButton = uibutton(app.ModelLoadingandBuildingTab, 'state');
            app.AddSpeciesButton.ValueChangedFcn = createCallbackFcn(app, @AddSpeciesButtonValueChanged, true);
            app.AddSpeciesButton.Tooltip = {'Use this button to add another species to the model.'};
            app.AddSpeciesButton.Text = 'Add Species';
            app.DeleteSpeciesDropDownLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.DeleteSpeciesDropDownLabel.Text = 'Delete Species';
            app.DeleteSpeciesDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.DeleteSpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteSpeciesDropDownValueChanged, true);
            app.DeleteSpeciesDropDown.Tooltip = {'Use this to delete a species from the model. Deleted species will be replaced with constants if used in other reactions.'};
            app.AbouttheModelLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.AbouttheModelLabel.Text = 'About the Model ';
            app.AddInputButton = uibutton(app.ModelLoadingandBuildingTab, 'state');
            app.AddInputButton.ValueChangedFcn = createCallbackFcn(app, @AddInputButtonValueChanged, true);
            app.AddInputButton.Tooltip = {'Use this button to add another input signal to the model.'};
            app.AddInputButton.Text = 'Add Input';
            app.DeleteInputDropDownLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.DeleteInputDropDownLabel.Text = 'Delete Input';
            app.DeleteInputDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.DeleteInputDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteInputDropDownValueChanged, true);
            app.DeleteInputDropDown.Tooltip = {'Use this to delete an input from the model.'};
            app.ChooseSSITModel = uidropdown(app.ModelLoadingandBuildingTab);
            app.ChooseSSITModel.Items = {'Other'};
            app.ChooseSSITModel.ValueChangedFcn = createCallbackFcn(app, @ChooseSSITModelValueChanged, true);
            app.ChooseSSITModel.Visible = 'off';
            app.ChooseSSITModel.Tooltip = {'Choose which SSIT model to use.'};
            app.ChooseSSITModel.Value = 'Other';
            app.ChooseSSITModelLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.ChooseSSITModelLabel.Visible = 'off';
            app.ChooseSSITModelLabel.Text = 'Choose SSIT Model';
            app.ParametersLabel_3 = uilabel(app.ModelLoadingandBuildingTab);
            app.ParametersLabel_3.Text = 'Species';
            app.SpeciesTable = uitable(app.ModelLoadingandBuildingTab);
            app.SpeciesTable.ColumnName = {'Name'; 'Initiial Value'};
            app.SpeciesTable.RowName = {};
            app.SpeciesTable.ColumnEditable = [false true];
            app.SpeciesTable.CellEditCallback = createCallbackFcn(app, @SpeciesTableCellEdit, true);
            app.SpeciesTable.Tooltip = {'Adjust species initial conditions here.'};
            app.RenameSpeciesDropDownLabel = uilabel(app.ModelLoadingandBuildingTab);
            app.RenameSpeciesDropDownLabel.Text = 'Rename Species';
            app.RenameSpeciesDropDown = uidropdown(app.ModelLoadingandBuildingTab);
            app.RenameSpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @RenameSpeciesDropDownValueChanged, true);
            app.RenameSpeciesDropDown.Tooltip = {'Use this to rename a species in the model.'};
            app.NewModelButton = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.NewModelButton.ButtonPushedFcn = createCallbackFcn(app, @NewModelButtonPushed, true);
            app.NewModelButton.Tooltip = {'Press this to create a new model from scratch.'};
            app.NewModelButton.Text = 'New Model';
            app.RefreshFoldersButton = uibutton(app.ModelLoadingandBuildingTab, 'push');
            app.RefreshFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ModelUsePresetExampleTypeDropDownValueChanged, true);
            app.RefreshFoldersButton.Tooltip = {'Press this button to load a saved model.'};
            app.RefreshFoldersButton.Text = 'Refresh Folders';
            app.DistortionandHybridModelingTab = uitab(app.TabGroup);
            app.DistortionandHybridModelingTab.Title = 'Distortion and Hybrid Modeling';
            app.PDO_Axis = uiaxes(app.DistortionandHybridModelingTab);
            title(app.PDO_Axis, 'Probabilistic Distortion Operator')
            xlabel(app.PDO_Axis, '"True" Expression Level')
            ylabel(app.PDO_Axis, '"Observed" Expression Level')
            zlabel(app.PDO_Axis, 'Z')
            app.PDO_Axis2 = uiaxes(app.DistortionandHybridModelingTab);
            title(app.PDO_Axis2, 'Comparison of "True" and "Observed" Distributions')
            xlabel(app.PDO_Axis2, '"True" Expression Level')
            ylabel(app.PDO_Axis2, '"Observed" Expression Level')
            zlabel(app.PDO_Axis2, 'Z')
            app.IncludeDataDistortionsinModelLabel = uilabel(app.DistortionandHybridModelingTab);
            app.IncludeDataDistortionsinModelLabel.Text = 'Include Data Distortions in Model';
            app.SetDistortionParametersButton = uibutton(app.DistortionandHybridModelingTab, 'push');
            app.SetDistortionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetDistortionParametersButtonPushed, true);
            app.SetDistortionParametersButton.Enable = 'off';
            app.SetDistortionParametersButton.Text = 'Set Distortion Parameters';
            app.ShowDistortionPlotButton = uibutton(app.DistortionandHybridModelingTab, 'push');
            app.ShowDistortionPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDistortionPlotButtonPushed, true);
            app.ShowDistortionPlotButton.Enable = 'off';
            app.ShowDistortionPlotButton.Text = 'Show Distortion Plot';
            app.pdo_parameters_table = uitable(app.DistortionandHybridModelingTab);
            app.pdo_parameters_table.ColumnName = {'Name'; 'Value'; 'Fit? (y/n)'};
            app.pdo_parameters_table.RowName = {};
            app.pdo_parameters_table.ColumnEditable = [false true true];
            app.pdo_parameters_table.Visible = 'off';
            app.DistortionParameterGuessesandFitsLabel = uilabel(app.DistortionandHybridModelingTab);
            app.DistortionParameterGuessesandFitsLabel.Visible = 'off';
            app.DistortionParameterGuessesandFitsLabel.Text = 'Distortion Parameter Guesses and Fits';
            app.UpdatePDOParametersButton = uibutton(app.DistortionandHybridModelingTab, 'push');
            app.UpdatePDOParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePDOParametersButtonPushed, true);
            app.UpdatePDOParametersButton.Visible = 'off';
            app.UpdatePDOParametersButton.Text = 'Update';
            app.DistortionTypeDropDown_2Label = uilabel(app.DistortionandHybridModelingTab);
            app.DistortionTypeDropDown_2Label.Text = 'Distortion Type';
            app.DistortionTypeDropDown = uidropdown(app.DistortionandHybridModelingTab);
            app.DistortionTypeDropDown.Items = {'None', 'Binomial', 'Binomial - State Dependent', 'Binomial - Parametrized', 'Poisson', 'Poisson - State Dependent', 'Poisson - Parametrized', 'Binning', 'Custom Function'};
            app.DistortionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistortionTypeDropDownValueChanged, true);
            app.DistortionTypeDropDown.Value = 'None';
            app.SpeciesDropDownLabel = uilabel(app.DistortionandHybridModelingTab);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.DistortionandHybridModelingTab);
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDistortionPlotButtonPushed, true);
            app.SolutionTimeDropDownLabel = uilabel(app.DistortionandHybridModelingTab);
            app.SolutionTimeDropDownLabel.Text = 'Solution Time';
            app.SolutionTimeDropDown = uidropdown(app.DistortionandHybridModelingTab);
            app.SolutionTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDistortionPlotButtonPushed, true);
            app.HybridModelOptionsLabel = uilabel(app.DistortionandHybridModelingTab);
            app.HybridModelOptionsLabel.Text = 'Hybrid Model Options:';
            app.UseHybridModelLabel = uilabel(app.DistortionandHybridModelingTab);
            app.UseHybridModelLabel.Text = 'Use Hybrid Model:';
            app.UseHybridModelSwitch = uiswitch(app.DistortionandHybridModelingTab, 'slider');
            app.UseHybridModelSwitch.ValueChangedFcn = createCallbackFcn(app, @UseHybridModelSwitchValueChanged, true);
            app.ObservableSpeciesListBox_2Label = uilabel(app.DistortionandHybridModelingTab);
            app.ObservableSpeciesListBox_2Label.Text = 'Observable Species:';
            app.ObservableSpeciesListBox_2 = uilistbox(app.DistortionandHybridModelingTab);
            app.ObservableSpeciesListBox_2.Multiselect = 'on';
            app.ObservableSpeciesListBox_2.Value = {'Item 1'};
            app.UpstreamSpeciesListBox_2Label = uilabel(app.DistortionandHybridModelingTab);
            app.UpstreamSpeciesListBox_2Label.Text = 'Upstream Species:';
            app.UpstreamSpeciesListBox = uilistbox(app.DistortionandHybridModelingTab);
            app.UpstreamSpeciesListBox.Multiselect = 'on';
            app.UpstreamSpeciesListBox.Value = {'Item 1'};
            app.StochasticSimulationTab = uitab(app.TabGroup);
            app.StochasticSimulationTab.Tooltip = {'Use this tab to generate stochastic trajectories for the model dynamics.'};
            app.StochasticSimulationTab.Title = 'Stochastic Simulation';
            app.SsaTrajAxes = uiaxes(app.StochasticSimulationTab);
            title(app.SsaTrajAxes, 'Trajectories')
            xlabel(app.SsaTrajAxes, 'Time')
            ylabel(app.SsaTrajAxes, 'Response')
            app.SsaHistAxes = uiaxes(app.StochasticSimulationTab);
            title(app.SsaHistAxes, 'Histogram')
            xlabel(app.SsaHistAxes, 'Molecule Count')
            ylabel(app.SsaHistAxes, 'Probability')
            app.NumberofSimulationsEditFieldLabel = uilabel(app.StochasticSimulationTab);
            app.NumberofSimulationsEditFieldLabel.Text = 'Number of Simulations';
            app.SsaNumSimField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SsaNumSimField.Tooltip = {'Number of SSA Simulations to run.'};
            app.SsaNumSimField.Value = 100;
            app.SsaRunButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaRunButton.ButtonPushedFcn = createCallbackFcn(app, @SsaRunButtonPushed, true);
            app.SsaRunButton.Tooltip = {'Individual trajectories for SSA'};
            app.SsaRunButton.Text = 'Run SSA';
            app.InitialConditionsLabel = uilabel(app.StochasticSimulationTab);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.SsaInitCondField = uieditfield(app.StochasticSimulationTab, 'text');
            app.SsaInitCondField.ValueChangedFcn = createCallbackFcn(app, @SsaInitCondFieldValueChanged, true);
            app.SsaInitCondField.Tooltip = {'Initial concentrations of each species.  See Model tab for order of species.'};
            app.SsaInitCondField.Value = '[1,0,0]';
            app.PrintTimesEditFieldLabel = uilabel(app.StochasticSimulationTab);
            app.PrintTimesEditFieldLabel.Text = 'Print Times';
            app.PrintTimesEditField = uieditfield(app.StochasticSimulationTab, 'text');
            app.PrintTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SensPrintTimesEditFieldValueChanged, true);
            app.PrintTimesEditField.Tooltip = {'SSA time evaluations'; ''; 'Input= [start:step:end]'};
            app.PrintTimesEditField.Value = '[0:0.1:10]';
            app.showLabel = uilabel(app.StochasticSimulationTab);
            app.showLabel.Text = 'show:';
            app.SsaRunStatus = uilabel(app.StochasticSimulationTab);
            app.SsaRunStatus.Text = 'Not yet run.';
            app.SsaUpdatePlotsButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaUpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.SsaUpdatePlotsButton.Visible = 'off';
            app.SsaUpdatePlotsButton.Text = 'Update Plots';
            app.timeSliderLabel = uilabel(app.StochasticSimulationTab);
            app.timeSliderLabel.Text = 'time';
            app.SsaTimeSlider = uislider(app.StochasticSimulationTab);
            app.SsaTimeSlider.Limits = [0 10];
            app.SsaTimeSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.SsaTimeSlider.Tooltip = {'Slider to choose time for displaying SSA Histogram.'};
            app.SsaExportFiguresButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SsaExportFiguresButtonPushed, true);
            app.SsaExportFiguresButton.Tooltip = {'Export both figures at the current time'};
            app.SsaExportFiguresButton.Text = 'Export Figures';
            app.SsaExportTrajectoriesButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaExportTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @SsaExportTrajectoriesButtonPushed, true);
            app.SsaExportTrajectoriesButton.Tooltip = {'Export Trajectory plot'};
            app.SsaExportTrajectoriesButton.Text = 'Export Trajectories';
            app.SsaExportHistogramButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaExportHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @SsaExportHistogramButtonPushed, true);
            app.SsaExportHistogramButton.Tooltip = {'Export Histogram Plot at the current time'};
            app.SsaExportHistogramButton.Text = 'Export Histogram';
            app.SsaSolutionsattimeLabel = uilabel(app.StochasticSimulationTab);
            app.SsaSolutionsattimeLabel.Text = 'Solutions at time: NA';
            app.XmaxLabel = uilabel(app.StochasticSimulationTab);
            app.XmaxLabel.Text = 'Xmax';
            app.SSAXmaxEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSAXmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SSAXmaxEditFieldValueChanged, true);
            app.SSAXmaxEditField.Tooltip = {'Xmax value for SSA trajectories'};
            app.YmaxLabel = uilabel(app.StochasticSimulationTab);
            app.YmaxLabel.Text = 'Ymax';
            app.SSAYmaxEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSAYmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SSAYmaxEditFieldValueChanged, true);
            app.SSAYmaxEditField.Tooltip = {'Ymax value for SSA trajectories'};
            app.XminLabel = uilabel(app.StochasticSimulationTab);
            app.XminLabel.Text = 'Xmin';
            app.SSAXminEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSAXminEditField.ValueChangedFcn = createCallbackFcn(app, @SSAXminEditFieldValueChanged, true);
            app.SSAXminEditField.Tooltip = {'Xmin value for SSA trajectories'};
            app.YminLabel = uilabel(app.StochasticSimulationTab);
            app.YminLabel.Text = 'Ymin';
            app.SSAYminEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSAYminEditField.ValueChangedFcn = createCallbackFcn(app, @SSA_hist_YmaxEditFieldValueChanged, true);
            app.SSAYminEditField.Tooltip = {'Yminvalue for SSA trajectories'};
            app.SsaParallelCheckBox = uicheckbox(app.StochasticSimulationTab);
            app.SsaParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SsaParallelCheckBoxValueChanged, true);
            app.SsaParallelCheckBox.Tooltip = {'Evaluate SSA in parallel. Good for large times and many simulations.'};
            app.SsaParallelCheckBox.Text = 'Parallel';
            app.SsaNonParallelCheckBox = uicheckbox(app.StochasticSimulationTab);
            app.SsaNonParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SsaNonParallelCheckBoxValueChanged, true);
            app.SsaNonParallelCheckBox.Tooltip = {'Evaluate SSA in series.'};
            app.SsaNonParallelCheckBox.Text = 'Non-Parallel';
            app.SsaNonParallelCheckBox.Value = true;
            app.XmaxLabel_2 = uilabel(app.StochasticSimulationTab);
            app.XmaxLabel_2.Text = 'Xmax';
            app.SSA_hist_XmaxEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSA_hist_XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SSA_hist_YmaxEditFieldValueChanged, true);
            app.SSA_hist_XmaxEditField.Tooltip = {'Xmax value for SSA trajectories'};
            app.YmaxLabel_2 = uilabel(app.StochasticSimulationTab);
            app.YmaxLabel_2.Text = 'Ymax';
            app.SSA_hist_YmaxEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSA_hist_YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @SSA_hist_YmaxEditFieldValueChanged, true);
            app.SSA_hist_YmaxEditField.Tooltip = {'Ymax value for SSA trajectories'};
            app.XminLabel_2 = uilabel(app.StochasticSimulationTab);
            app.XminLabel_2.Text = 'Xmin';
            app.SSA_hist_XminEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSA_hist_XminEditField.ValueChangedFcn = createCallbackFcn(app, @SSA_hist_YmaxEditFieldValueChanged, true);
            app.SSA_hist_XminEditField.Tooltip = {'Xmin value for SSA trajectories'};
            app.YminLabel_2 = uilabel(app.StochasticSimulationTab);
            app.YminLabel_2.Text = 'Ymin';
            app.SSA_hist_YminEditField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SSA_hist_YminEditField.ValueChangedFcn = createCallbackFcn(app, @SSA_hist_YmaxEditFieldValueChanged, true);
            app.SSA_hist_YminEditField.Tooltip = {'Yminvalue for SSA trajectories'};
            app.NSimsEditFieldLabel = uilabel(app.StochasticSimulationTab);
            app.NSimsEditFieldLabel.Text = 'N Sims:';
            app.SsaNumSimToPlotField = uieditfield(app.StochasticSimulationTab, 'numeric');
            app.SsaNumSimToPlotField.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.SsaNumSimToPlotField.Tooltip = {'Number of SSA Simulations to show in trajectory plot.'};
            app.SsaNumSimToPlotField.Value = 100;
            app.SignalUpdateRateEditFieldLabel = uilabel(app.StochasticSimulationTab);
            app.SignalUpdateRateEditFieldLabel.Enable = 'off';
            app.SignalUpdateRateEditFieldLabel.Text = 'Signal Update Rate';
            app.SsaSignalUpdateRateField = uieditfield(app.StochasticSimulationTab, 'text');
            app.SsaSignalUpdateRateField.Enable = 'off';
            app.SsaSignalUpdateRateField.Tooltip = {'Rate to update signal level (for time varying systems only).'};
            app.SsaSignalUpdateRateField.Value = '1';
            app.SsaExportExcelButton = uibutton(app.StochasticSimulationTab, 'push');
            app.SsaExportExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SsaExportExcelButtonPushed, true);
            app.SsaExportExcelButton.Tooltip = {'Export trajectories to Excel datasheet '};
            app.SsaExportExcelButton.Text = 'Export to Excel';
            app.DecreaseFontSizeSSA = uibutton(app.StochasticSimulationTab, 'push');
            app.DecreaseFontSizeSSA.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeSSA.Visible = 'off';
            app.DecreaseFontSizeSSA.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeSSA.Text = '-';
            app.IncreaseFontSizeSsa = uibutton(app.StochasticSimulationTab, 'push');
            app.IncreaseFontSizeSsa.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeSsa.Visible = 'off';
            app.IncreaseFontSizeSsa.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeSsa.Text = '+';
            app.FontSizeLabelSsa = uilabel(app.StochasticSimulationTab);
            app.FontSizeLabelSsa.Visible = 'off';
            app.FontSizeLabelSsa.Tooltip = {'Increase global font size'};
            app.FontSizeLabelSsa.Text = {'Font Size'; ''};
            app.SsaShowOdeCheckBox = uicheckbox(app.StochasticSimulationTab);
            app.SsaShowOdeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.SsaShowOdeCheckBox.Tooltip = {'Show ODE solution in plots.'};
            app.SsaShowOdeCheckBox.Text = 'Show ODE';
            app.SpeciestoShowListBoxLabel = uilabel(app.StochasticSimulationTab);
            app.SpeciestoShowListBoxLabel.Text = 'Species to Show';
            app.SpeciestoShowListBox = uilistbox(app.StochasticSimulationTab);
            app.SpeciestoShowListBox.Items = {'x1', 'x2', 'x3'};
            app.SpeciestoShowListBox.Multiselect = 'on';
            app.SpeciestoShowListBox.ValueChangedFcn = createCallbackFcn(app, @SpeciestoShowListBoxValueChanged, true);
            app.SpeciestoShowListBox.Tooltip = {'Choose which species to show in plots.'};
            app.SpeciestoShowListBox.Value = {'x1'};
            app.SsaGPUCheckBox = uicheckbox(app.StochasticSimulationTab);
            app.SsaGPUCheckBox.Tooltip = {'Evaluate SSA in series.'};
            app.SsaGPUCheckBox.Text = 'GPU';
            app.FiniteStateProjectionTab = uitab(app.TabGroup);
            app.FiniteStateProjectionTab.Tooltip = {'Use this tab to solve for the time varying probabiity distributions for your model.'};
            app.FiniteStateProjectionTab.Title = 'Finite State Projection';
            app.FspAxes = uiaxes(app.FiniteStateProjectionTab);
            title(app.FspAxes, 'Marginals')
            xlabel(app.FspAxes, 'Species Count')
            ylabel(app.FspAxes, 'Probability')
            app.FspRunButtom = uibutton(app.FiniteStateProjectionTab, 'push');
            app.FspRunButtom.ButtonPushedFcn = createCallbackFcn(app, @FspRunButtomPushed, true);
            app.FspRunButtom.Tooltip = {'Run FSP calculation with current constraints. If no constraints'; ' runs with default constraints.'};
            app.FspRunButtom.Text = 'Run FSP';
            app.FspInitCondField = uieditfield(app.FiniteStateProjectionTab, 'text');
            app.FspInitCondField.ValueChangedFcn = createCallbackFcn(app, @SsaInitCondFieldValueChanged, true);
            app.FspInitCondField.Tooltip = {'Initial conditions for FSP. See ''Model'' tab for species order.'};
            app.FspInitCondField.Value = '[1,0,0]';
            app.FspRunningStatus = uilabel(app.FiniteStateProjectionTab);
            app.FspRunningStatus.Text = 'Not yet run.';
            app.FspUpdatePlotButton = uibutton(app.FiniteStateProjectionTab, 'push');
            app.FspUpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButton_2Pushed, true);
            app.FspUpdatePlotButton.Tooltip = {'Update FSP Marginal plot'};
            app.FspUpdatePlotButton.Text = 'Update Plots';
            app.ErrorToleranceEditFieldLabel = uilabel(app.FiniteStateProjectionTab);
            app.ErrorToleranceEditFieldLabel.Text = 'Error Tolerance';
            app.FspErrorTolField = uieditfield(app.FiniteStateProjectionTab, 'numeric');
            app.FspErrorTolField.Tooltip = {'Error Tolerance for FSP (e.g., 0.001).'};
            app.FspErrorTolField.Value = 0.001;
            app.PrintTimesEditField_2Label = uilabel(app.FiniteStateProjectionTab);
            app.PrintTimesEditField_2Label.Text = 'Print Times';
            app.FspPrintTimesField = uieditfield(app.FiniteStateProjectionTab, 'text');
            app.FspPrintTimesField.ValueChangedFcn = createCallbackFcn(app, @SensPrintTimesEditFieldValueChanged, true);
            app.FspPrintTimesField.Tooltip = {'Time evaluation for FSP solution'; ''; 'input[start:step:stop]'};
            app.FspPrintTimesField.Value = '[0:0.1:10]';
            app.timeSlider_2Label = uilabel(app.FiniteStateProjectionTab);
            app.timeSlider_2Label.Text = 'time';
            app.FspTimeSlider = uislider(app.FiniteStateProjectionTab);
            app.FspTimeSlider.Limits = [0 10];
            app.FspTimeSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButton_2Pushed, true);
            app.FspTimeSlider.ValueChangingFcn = createCallbackFcn(app, @UpdatePlotsButton_2Pushed, true);
            app.FspTimeSlider.Tooltip = {'Time slider for FSP marginal plot'};
            app.InitialConditionLabel = uilabel(app.FiniteStateProjectionTab);
            app.InitialConditionLabel.Text = 'Initial Condition';
            app.FspConstraintTable = uitable(app.FiniteStateProjectionTab);
            app.FspConstraintTable.ColumnName = {'Constraint'; ' '; 'Bound'};
            app.FspConstraintTable.RowName = {};
            app.FspConstraintTable.ColumnEditable = [true false true];
            app.FspConstraintTable.CellEditCallback = createCallbackFcn(app, @FspConstraintTableCellEdit, true);
            app.FspConstraintTable.Tooltip = {'Contraints for FSP solution. Both the constraint functions and the bounds can be editted.'};
            app.FspConstraintsLabel = uilabel(app.FiniteStateProjectionTab);
            app.FspConstraintsLabel.Text = 'FSP Constraints';
            app.FspDefaultButton = uibutton(app.FiniteStateProjectionTab, 'push');
            app.FspDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @FspDefaultButtonPushed, true);
            app.FspDefaultButton.Tooltip = {'Return to default constraits.'};
            app.FspDefaultButton.Text = 'Default';
            app.FspAddConstraintButton = uibutton(app.FiniteStateProjectionTab, 'push');
            app.FspAddConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @FspAddConstraintButtonPushed, true);
            app.FspAddConstraintButton.Tooltip = {'Add new constraint to define FSP statespace.'};
            app.FspAddConstraintButton.Text = 'Add Constraint';
            app.FspSolutionAtTimeLabel = uilabel(app.FiniteStateProjectionTab);
            app.FspSolutionAtTimeLabel.Text = 'Solutions at time: NA';
            app.FspMarginalsPanel = uipanel(app.FiniteStateProjectionTab);
            app.FspMarginalsPanel.Title = 'Marginals';
            app.SelectSpeciesLabel = uilabel(app.FspMarginalsPanel);
            app.SelectSpeciesLabel.Text = 'Select Species';
            app.FspPlotMarginalsButton = uibutton(app.FspMarginalsPanel, 'push');
            app.FspPlotMarginalsButton.ButtonPushedFcn = createCallbackFcn(app, @FspPlotMarginalsButtonPushed, true);
            app.FspPlotMarginalsButton.Tooltip = {'Export FSP marginal plot at current time'};
            app.FspPlotMarginalsButton.Text = 'Plot Marginals';
            app.SpeciestoShowListBoxMargFSP = uilistbox(app.FspMarginalsPanel);
            app.SpeciestoShowListBoxMargFSP.Items = {'x1', 'x2', 'x3'};
            app.SpeciestoShowListBoxMargFSP.Multiselect = 'on';
            app.SpeciestoShowListBoxMargFSP.Value = {'x1'};
            app.ChoosePlotsLabel = uilabel(app.FiniteStateProjectionTab);
            app.ChoosePlotsLabel.Text = 'Choose Plots';
            app.FspMarginalsOverTimePanel = uipanel(app.FiniteStateProjectionTab);
            app.FspMarginalsOverTimePanel.Title = 'Marginals Over Time';
            app.InputTimePointsLabel = uilabel(app.FspMarginalsOverTimePanel);
            app.InputTimePointsLabel.Text = 'Input Time Points';
            app.FspPlotMarginalsOverTimeButton = uibutton(app.FspMarginalsOverTimePanel, 'push');
            app.FspPlotMarginalsOverTimeButton.ButtonPushedFcn = createCallbackFcn(app, @FspPlotMarginalsOverTimeButtonPushed, true);
            app.FspPlotMarginalsOverTimeButton.Tooltip = {'export marginal over time'};
            app.FspPlotMarginalsOverTimeButton.Text = 'Plot Marginals ';
            app.FspMarginalVecField = uieditfield(app.FspMarginalsOverTimePanel, 'text');
            app.FspMarginalVecField.Tooltip = {'movie time points'};
            app.FspMarginalVecField.Value = '[0:1:10]';
            app.SelectSpeciesLabel_2 = uilabel(app.FspMarginalsOverTimePanel);
            app.SelectSpeciesLabel_2.Text = 'Select Species';
            app.FspMarginalTimeCreateMovieCheckBox = uicheckbox(app.FspMarginalsOverTimePanel);
            app.FspMarginalTimeCreateMovieCheckBox.Text = 'Create Movie';
            app.SpeciestoShowListBoxMargFSPvT = uilistbox(app.FspMarginalsOverTimePanel);
            app.SpeciestoShowListBoxMargFSPvT.Items = {'x1', 'x2', 'x3'};
            app.SpeciestoShowListBoxMargFSPvT.Multiselect = 'on';
            app.SpeciestoShowListBoxMargFSPvT.Value = {'x1'};
            app.FspMeanandVariancePanel = uipanel(app.FiniteStateProjectionTab);
            app.FspMeanandVariancePanel.Title = 'Mean and Variance';
            app.SelectSpeciesLabel_3 = uilabel(app.FspMeanandVariancePanel);
            app.SelectSpeciesLabel_3.Text = 'Select Species';
            app.SelectPlotsLabel_2 = uilabel(app.FspMeanandVariancePanel);
            app.SelectPlotsLabel_2.Text = 'Select Plots';
            app.FspMeanVarShowVarianceCheckBox = uicheckbox(app.FspMeanandVariancePanel);
            app.FspMeanVarShowVarianceCheckBox.Tooltip = {'select to plot standard deviations'};
            app.FspMeanVarShowVarianceCheckBox.Text = 'Show Variance';
            app.FspMeanVarPlotButton = uibutton(app.FspMeanandVariancePanel, 'push');
            app.FspMeanVarPlotButton.ButtonPushedFcn = createCallbackFcn(app, @FspMeanVarPlotButtonPushed, true);
            app.FspMeanVarPlotButton.Tooltip = {'export selected figures'};
            app.FspMeanVarPlotButton.Text = 'Plot Mean and Variance';
            app.FspMeanVarShowOdeCheckBox = uicheckbox(app.FspMeanandVariancePanel);
            app.FspMeanVarShowOdeCheckBox.Tooltip = {'select to plot ODE solution'};
            app.FspMeanVarShowOdeCheckBox.Text = 'Show ODE';
            app.SpeciestoShowListBoxMeans = uilistbox(app.FspMeanandVariancePanel);
            app.SpeciestoShowListBoxMeans.Items = {'x1', 'x2', 'x3'};
            app.SpeciestoShowListBoxMeans.Multiselect = 'on';
            app.SpeciestoShowListBoxMeans.Value = {'x1'};
            app.FspJointDistributionPanel = uipanel(app.FiniteStateProjectionTab);
            app.FspJointDistributionPanel.Title = 'Joint Distribution';
            app.SelectSpeciesLabel_4 = uilabel(app.FspJointDistributionPanel);
            app.SelectSpeciesLabel_4.Text = 'Select Species';
            app.SelectPlotsLabel_3 = uilabel(app.FspJointDistributionPanel);
            app.SelectPlotsLabel_3.Text = 'Select Plots';
            app.FspContourCheckBox = uicheckbox(app.FspJointDistributionPanel);
            app.FspContourCheckBox.ValueChangedFcn = createCallbackFcn(app, @FspContourCheckBoxValueChanged, true);
            app.FspContourCheckBox.Tooltip = {'select to plot contour of joint distributions'};
            app.FspContourCheckBox.Text = 'Contour';
            app.FspMeshCheckBox = uicheckbox(app.FspJointDistributionPanel);
            app.FspMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @FspMeshCheckBoxValueChanged, true);
            app.FspMeshCheckBox.Tooltip = {'select to create mesh of joint distributions'};
            app.FspMeshCheckBox.Text = 'Mesh';
            app.PlotJointDistributionsButton = uibutton(app.FspJointDistributionPanel, 'push');
            app.PlotJointDistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotJointDistributionsButtonPushed, true);
            app.PlotJointDistributionsButton.Tooltip = {'export above selected plots'};
            app.PlotJointDistributionsButton.Text = 'Plot Joint Distributions';
            app.FspJointCreateMovCheckBox = uicheckbox(app.FspJointDistributionPanel);
            app.FspJointCreateMovCheckBox.Tooltip = {'select to create movie of joint distributions'};
            app.FspJointCreateMovCheckBox.Text = 'Create Movie';
            app.JointSp1 = uilistbox(app.FspJointDistributionPanel);
            app.JointSp1.Items = {'x1', 'x2', 'x3'};
            app.JointSp1.Value = 'x1';
            app.JointSp2 = uilistbox(app.FspJointDistributionPanel);
            app.JointSp2.Items = {'x1', 'x2', 'x3'};
            app.JointSp2.Value = 'x1';
            app.vsLabel = uilabel(app.FspJointDistributionPanel);
            app.vsLabel.Text = 'vs.';
            app.XminEditFieldLabel = uilabel(app.FiniteStateProjectionTab);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.FSPXminEditField = uieditfield(app.FiniteStateProjectionTab, 'numeric');
            app.FSPXminEditField.ValueChangedFcn = createCallbackFcn(app, @FSPXminEditFieldValueChanged, true);
            app.FSPXminEditField.Tooltip = {'Xmin for FSP marginal plot'};
            app.XmaxEditFieldLabel = uilabel(app.FiniteStateProjectionTab);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.FSPXmaxEditField = uieditfield(app.FiniteStateProjectionTab, 'numeric');
            app.FSPXmaxEditField.ValueChangedFcn = createCallbackFcn(app, @FSPXmaxEditFieldValueChanged, true);
            app.FSPXmaxEditField.Tooltip = {'Xmax for FSP marginal plot'};
            app.YminEditFieldLabel = uilabel(app.FiniteStateProjectionTab);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.FSPYminEditField = uieditfield(app.FiniteStateProjectionTab, 'numeric');
            app.FSPYminEditField.ValueChangedFcn = createCallbackFcn(app, @FSPYminEditFieldValueChanged, true);
            app.FSPYminEditField.Tooltip = {'Ymin for FSP marginal plot'};
            app.YmaxEditFieldLabel = uilabel(app.FiniteStateProjectionTab);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.FSPYmaxEditField = uieditfield(app.FiniteStateProjectionTab, 'numeric');
            app.FSPYmaxEditField.ValueChangedFcn = createCallbackFcn(app, @FSPYmaxEditFieldValueChanged, true);
            app.FSPYmaxEditField.Tooltip = {'Ymax for FSP marginal plot'};
            app.FspAutoExpandCheckBox = uicheckbox(app.FiniteStateProjectionTab);
            app.FspAutoExpandCheckBox.Tooltip = {'When checked, the FSP solution will expand to achieve the specified tolerence.'};
            app.FspAutoExpandCheckBox.Text = 'Automatically Expand FSP';
            app.FspAutoExpandCheckBox.Value = true;
            app.FspApproximateSteadyStateButton = uibutton(app.FiniteStateProjectionTab, 'push');
            app.FspApproximateSteadyStateButton.ButtonPushedFcn = createCallbackFcn(app, @FspApproximateSteadyStateButtonPushed, true);
            app.FspApproximateSteadyStateButton.Enable = 'off';
            app.FspApproximateSteadyStateButton.Visible = 'off';
            app.FspApproximateSteadyStateButton.Tooltip = {'Approximate FSP steady-state using current statespace and SVD.'};
            app.FspApproximateSteadyStateButton.Text = 'Approximate Steady State';
            app.FspPiecewiseCheckBox = uicheckbox(app.FiniteStateProjectionTab);
            app.FspPiecewiseCheckBox.Tooltip = {'The piecewise constant FSP approximation assumes that the input signals are constant between solution times.  This allows to use a much faster and more accurate expokit version for solving when signals are slow to vary.'};
            app.FspPiecewiseCheckBox.Text = 'Use Piecewise Constant FSP Approximation';
            app.DecreaseFontSizeFsp = uibutton(app.FiniteStateProjectionTab, 'push');
            app.DecreaseFontSizeFsp.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeFsp.Visible = 'off';
            app.DecreaseFontSizeFsp.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeFsp.Text = '-';
            app.IncreaseFontSizeFsp = uibutton(app.FiniteStateProjectionTab, 'push');
            app.IncreaseFontSizeFsp.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeFsp.Visible = 'off';
            app.IncreaseFontSizeFsp.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeFsp.Text = '+';
            app.FontSizeLabel_3 = uilabel(app.FiniteStateProjectionTab);
            app.FontSizeLabel_3.Visible = 'off';
            app.FontSizeLabel_3.Text = {'Font Size'; ''};
            app.ODEToleranceDropDownLabel = uilabel(app.FiniteStateProjectionTab);
            app.ODEToleranceDropDownLabel.Text = 'ODE Tolerance';
            app.FspIntegratorTolerance = uidropdown(app.FiniteStateProjectionTab);
            app.FspIntegratorTolerance.Items = {'Relaxed', 'Moderate', 'Strict'};
            app.FspIntegratorTolerance.Tooltip = {'ODE tolerance to use for time varying CME integration.'};
            app.FspIntegratorTolerance.Value = 'Relaxed';
            app.ExportFSPDatatoWorkspaceForManualPlotsButton = uibutton(app.FiniteStateProjectionTab, 'push');
            app.ExportFSPDatatoWorkspaceForManualPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFSPDatatoWorkspaceForManualPlotsButtonPushed, true);
            app.ExportFSPDatatoWorkspaceForManualPlotsButton.Text = 'Export FSP Data to Workspace For Manual Plots';
            app.SpeciestoShowListBox_2Label = uilabel(app.FiniteStateProjectionTab);
            app.SpeciestoShowListBox_2Label.Text = 'Species to Show';
            app.SpeciestoShowListBox_2 = uilistbox(app.FiniteStateProjectionTab);
            app.SpeciestoShowListBox_2.Items = {'x1', 'x2', 'x3'};
            app.SpeciestoShowListBox_2.Multiselect = 'on';
            app.SpeciestoShowListBox_2.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotsButton_2Pushed, true);
            app.SpeciestoShowListBox_2.Value = {'x1'};
            app.initApproxSS = uicheckbox(app.FiniteStateProjectionTab);
            app.initApproxSS.Text = 'Use Steady State as Initial Condition';
            app.SensitivityAnalysisTab = uitab(app.TabGroup);
            app.SensitivityAnalysisTab.Tooltip = {'Use this tab to perform sensitivity analysis with respect to model parameters.'};
            app.SensitivityAnalysisTab.Title = 'Sensitivity Analysis';
            app.SensProbAxes = uiaxes(app.SensitivityAnalysisTab);
            title(app.SensProbAxes, 'Marginals')
            xlabel(app.SensProbAxes, 'Species Count')
            ylabel(app.SensProbAxes, 'Probability')
            app.SensDerivativeAxes = uiaxes(app.SensitivityAnalysisTab);
            title(app.SensDerivativeAxes, 'Sensitivity')
            xlabel(app.SensDerivativeAxes, 'Species Count')
            ylabel(app.SensDerivativeAxes, 'Sensitivity')
            app.SelectAnalysisTypeforSensitivityButtonGroup = uibuttongroup(app.SensitivityAnalysisTab);
            app.SelectAnalysisTypeforSensitivityButtonGroup.Title = 'Select Analysis Type for Sensitivity';
            app.SensitivityFunctionButton = uitogglebutton(app.SelectAnalysisTypeforSensitivityButtonGroup);
            app.SensitivityFunctionButton.Text = 'Sensitivity Function';
            app.SensitivityFunctionButton.Value = true;
            app.FiniteDifferenceButton = uitogglebutton(app.SelectAnalysisTypeforSensitivityButtonGroup);
            app.FiniteDifferenceButton.Text = 'Finite Difference';
            app.SensParameterSelectionTable = uitable(app.SensitivityAnalysisTab);
            app.SensParameterSelectionTable.ColumnName = {'Parameter'; 'Value'; 'Include? (y/n)'};
            app.SensParameterSelectionTable.RowName = {};
            app.SensParameterSelectionTable.ColumnEditable = [false true true];
            app.SensParameterSelectionTable.CellEditCallback = createCallbackFcn(app, @SensParameterSelectionTableCellEdit, true);
            app.SelectParameterstobeIncludedinSensitivityAnalysisLabel = uilabel(app.SensitivityAnalysisTab);
            app.SelectParameterstobeIncludedinSensitivityAnalysisLabel.Text = 'Select Parameters to be Included in Sensitivity Analysis';
            app.NoteforValuesTextArea = uitextarea(app.SensitivityAnalysisTab);
            app.NoteforValuesTextArea.Editable = 'off';
            app.NoteforValuesTextArea.Value = {'Changes in values here only effect the Sensitivity Analysis.'};
            app.SensInitCondField = uieditfield(app.SensitivityAnalysisTab, 'text');
            app.SensInitCondField.ValueChangedFcn = createCallbackFcn(app, @SsaInitCondFieldValueChanged, true);
            app.SensInitCondField.Value = '[1,0,0]';
            app.InitialConditionLabel_2 = uilabel(app.SensitivityAnalysisTab);
            app.InitialConditionLabel_2.Text = 'Initial Condition';
            app.ErrorToleranceEditField_2Label = uilabel(app.SensitivityAnalysisTab);
            app.ErrorToleranceEditField_2Label.Text = 'Error Tolerance';
            app.SensErrorTolEditField = uieditfield(app.SensitivityAnalysisTab, 'numeric');
            app.SensErrorTolEditField.Value = 0.001;
            app.PrintTimesEditField_3Label = uilabel(app.SensitivityAnalysisTab);
            app.PrintTimesEditField_3Label.Text = 'Print Times';
            app.SensPrintTimesEditField = uieditfield(app.SensitivityAnalysisTab, 'text');
            app.SensPrintTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SensPrintTimesEditFieldValueChanged, true);
            app.SensPrintTimesEditField.Value = '[0:0.1:10]';
            app.TimeSelectorSliderLabel = uilabel(app.SensitivityAnalysisTab);
            app.TimeSelectorSliderLabel.Text = 'Time Selector';
            app.SensPlotTimeSlider = uislider(app.SensitivityAnalysisTab);
            app.SensPlotTimeSlider.Limits = [0 10];
            app.SensPlotTimeSlider.ValueChangedFcn = createCallbackFcn(app, @SensPlotTimeSliderValueChanged, true);
            app.SensRunButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.SensRunButton.ButtonPushedFcn = createCallbackFcn(app, @SensRunButtonPushed, true);
            app.SensRunButton.Text = 'Run Sensitivity';
            app.DisplaySpeciesPanel = uipanel(app.SensitivityAnalysisTab);
            app.DisplaySpeciesPanel.Title = 'Display Species';
            app.SpeciesForSensPlot = uilistbox(app.DisplaySpeciesPanel);
            app.SpeciesForSensPlot.Items = {'x1', 'x2', 'x3'};
            app.SpeciesForSensPlot.Multiselect = 'on';
            app.SpeciesForSensPlot.ValueChangedFcn = createCallbackFcn(app, @SensPlotTimeSliderValueChanged, true);
            app.SpeciesForSensPlot.Value = {'x1'};
            app.withrespecttoparameterDropDownLabel = uilabel(app.SensitivityAnalysisTab);
            app.withrespecttoparameterDropDownLabel.Text = 'with respect to parameter';
            app.SensParDropDown = uidropdown(app.SensitivityAnalysisTab);
            app.SensParDropDown.ItemsData = {'1, 2, 3, 4'};
            app.SensParDropDown.ValueChangedFcn = createCallbackFcn(app, @SensParDropDownValueChanged, true);
            app.SensParDropDown.Value = '1, 2, 3, 4';
            app.DecreaseFontSizeSens = uibutton(app.SensitivityAnalysisTab, 'push');
            app.DecreaseFontSizeSens.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeSens.Visible = 'off';
            app.DecreaseFontSizeSens.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeSens.Text = '-';
            app.IncreaseFontSizeSens = uibutton(app.SensitivityAnalysisTab, 'push');
            app.IncreaseFontSizeSens.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeSens.Visible = 'off';
            app.IncreaseFontSizeSens.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeSens.Text = '+';
            app.FontSizeLabel_4 = uilabel(app.SensitivityAnalysisTab);
            app.FontSizeLabel_4.Visible = 'off';
            app.FontSizeLabel_4.Text = {'Font Size'; ''};
            app.ExportSensitivityDatatoWorkspaceForManualPlotsButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.ExportSensitivityDatatoWorkspaceForManualPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSensitivityDatatoWorkspaceForManualPlotsButtonPushed, true);
            app.ExportSensitivityDatatoWorkspaceForManualPlotsButton.Text = 'Export Sensitivity Data to Workspace For Manual Plots';
            app.FisherInformationTab = uitab(app.TabGroup);
            app.FisherInformationTab.Title = 'Fisher Information';
            app.plotFIMvsTime = uiaxes(app.FisherInformationTab);
            title(app.plotFIMvsTime, 'Information versus measurement time.')
            xlabel(app.plotFIMvsTime, 'Time')
            ylabel(app.plotFIMvsTime, 'Information')
            zlabel(app.plotFIMvsTime, 'Z')
            app.FIMEllipseAxes = uiaxes(app.FisherInformationTab);
            title(app.FIMEllipseAxes, 'Expected MLE Joint Uncertainty')
            zlabel(app.FIMEllipseAxes, 'Z')
            app.DecreaseFontSizeData_2 = uibutton(app.FisherInformationTab, 'push');
            app.DecreaseFontSizeData_2.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeData_2.Visible = 'off';
            app.DecreaseFontSizeData_2.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeData_2.Text = '-';
            app.IncreaseFontSizeData_2 = uibutton(app.FisherInformationTab, 'push');
            app.IncreaseFontSizeData_2.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeData_2.Visible = 'off';
            app.IncreaseFontSizeData_2.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeData_2.Text = '+';
            app.FontSizeLabel_8 = uilabel(app.FisherInformationTab);
            app.FontSizeLabel_8.Visible = 'off';
            app.FontSizeLabel_8.Text = {'Font Size'; ''};
            app.ListofMeasurementTimesEditFieldLabel = uilabel(app.FisherInformationTab);
            app.ListofMeasurementTimesEditFieldLabel.Text = 'List of Measurement Times';
            app.ListofMeasurementTimesEditField = uieditfield(app.FisherInformationTab, 'text');
            app.ListofMeasurementTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SensPrintTimesEditFieldValueChanged, true);
            app.ListofMeasurementTimesEditField.Value = '[0:0.1:10]';
            app.AddProbabilisticDistortionEffectsButton = uibutton(app.FisherInformationTab, 'push');
            app.AddProbabilisticDistortionEffectsButton.ButtonPushedFcn = createCallbackFcn(app, @AddProbabilisticDistortionEffectsButtonPushed, true);
            app.AddProbabilisticDistortionEffectsButton.Enable = 'off';
            app.AddProbabilisticDistortionEffectsButton.Visible = 'off';
            app.AddProbabilisticDistortionEffectsButton.Text = 'Add Probabilistic Distortion Effects';
            app.NoneLabel = uilabel(app.FisherInformationTab);
            app.NoneLabel.Visible = 'off';
            app.NoneLabel.Text = '- None';
            app.FIMMetricorParameterDropDownLabel = uilabel(app.FisherInformationTab);
            app.FIMMetricorParameterDropDownLabel.Text = 'FIM Metric (or Parameter)';
            app.FIMMetricorParameterDropDown = uidropdown(app.FisherInformationTab);
            app.FIMMetricorParameterDropDown.Items = {'Determinant', 'Smallest Eigenvalue', 'Trace'};
            app.FIMMetricorParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ComputeFIMandPlotInformationvsTimeButtonPushed, true);
            app.FIMMetricorParameterDropDown.Value = 'Determinant';
            app.ObservableSpeciesListBoxLabel = uilabel(app.FisherInformationTab);
            app.ObservableSpeciesListBoxLabel.Text = 'Observable Species';
            app.ObservableSpeciesListBox = uilistbox(app.FisherInformationTab);
            app.ObservableSpeciesListBox.Items = {'x1', 'x2', 'x3'};
            app.ObservableSpeciesListBox.Multiselect = 'on';
            app.ObservableSpeciesListBox.Value = {'x1'};
            app.ManuallyAllocateMeasurementsperTimePointButton = uibutton(app.FisherInformationTab, 'push');
            app.ManuallyAllocateMeasurementsperTimePointButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyAllocateMeasurementsperTimePointButtonPushed, true);
            app.ManuallyAllocateMeasurementsperTimePointButton.Text = 'Manually Allocate Measurements per Time Point';
            app.orLabel = uilabel(app.FisherInformationTab);
            app.orLabel.Text = 'or';
            app.OptimizeButton = uibutton(app.FisherInformationTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.foratotalofLabel = uilabel(app.FisherInformationTab);
            app.foratotalofLabel.Text = 'for a total of';
            app.CellsEditFieldLabel = uilabel(app.FisherInformationTab);
            app.CellsEditFieldLabel.Text = 'Cells';
            app.CellsEditField = uieditfield(app.FisherInformationTab, 'numeric');
            app.CellsEditField.Limits = [1 Inf];
            app.CellsEditField.Value = 1000;
            app.FIMParameter1 = uidropdown(app.FisherInformationTab);
            app.FIMParameter1.ValueChangedFcn = createCallbackFcn(app, @EstimateMLEUncertaintyButtonPushed, true);
            app.FIMParameter2 = uidropdown(app.FisherInformationTab);
            app.FIMParameter2.ValueChangedFcn = createCallbackFcn(app, @EstimateMLEUncertaintyButtonPushed, true);
            app.EstimateFisherInformationLabel = uilabel(app.FisherInformationTab);
            app.EstimateFisherInformationLabel.Text = 'Estimate Fisher Information';
            app.Label = uilabel(app.FisherInformationTab);
            app.Label.Text = '';
            app.ParametersLabel_2 = uilabel(app.FisherInformationTab);
            app.ParametersLabel_2.Text = 'Parameters';
            app.SpecifyPriorButton = uibutton(app.FisherInformationTab, 'push');
            app.SpecifyPriorButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyPriorButtonPushed, true);
            app.SpecifyPriorButton.Enable = 'off';
            app.SpecifyPriorButton.Text = 'Specify Prior';
            app.PlotInformationvsTimeButton = uibutton(app.FisherInformationTab, 'push');
            app.PlotInformationvsTimeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeFIMandPlotInformationvsTimeButtonPushed, true);
            app.PlotInformationvsTimeButton.Tooltip = {'This will plot the chosen FIM metric versus time. Note that for many experiments the FIM may not be invertible.'};
            app.PlotInformationvsTimeButton.Text = 'Plot Information vs. Time';
            app.EstimateMLEUncertaintyButton = uibutton(app.FisherInformationTab, 'push');
            app.EstimateMLEUncertaintyButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateMLEUncertaintyButtonPushed, true);
            app.EstimateMLEUncertaintyButton.Text = 'Estimate MLE Uncertainty';
            app.FIMLogTransformCheckBox = uicheckbox(app.FisherInformationTab);
            app.FIMLogTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateMLEUncertaintyButtonPushed, true);
            app.FIMLogTransformCheckBox.Text = 'Show COV in Log Space';
            app.PlotStlyeDropDownLabel = uilabel(app.FisherInformationTab);
            app.PlotStlyeDropDownLabel.Text = 'Plot Stlye';
            app.PlotStlyeDropDown = uidropdown(app.FisherInformationTab);
            app.PlotStlyeDropDown.Items = {'Information vs. time', 'New Information vs. time'};
            app.PlotStlyeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotStlyeDropDownValueChanged, true);
            app.PlotStlyeDropDown.Value = 'New Information vs. time';
            app.NumberMCSamplesoverPriorEditFieldLabel = uilabel(app.FisherInformationTab);
            app.NumberMCSamplesoverPriorEditFieldLabel.Text = 'Number MC Samples over Prior';
            app.FIMNumMC = uieditfield(app.FisherInformationTab, 'numeric');
            app.FIMNumMC.Limits = [1 1000];
            app.FIMNumMC.RoundFractionalValues = 'on';
            app.FIMNumMC.Editable = 'off';
            app.FIMNumMC.Enable = 'off';
            app.FIMNumMC.Tooltip = {'Enter the number of Monte Carlos to use when sampling over the prior. Note that the sensitivity calculation will need to be solved once for each sample.'};
            app.FIMNumMC.Value = 10;
            app.ModelUncertaintyDropDownLabel = uilabel(app.FisherInformationTab);
            app.ModelUncertaintyDropDownLabel.Text = 'Model Uncertainty';
            app.ModelUncertaintyDropDown = uidropdown(app.FisherInformationTab);
            app.ModelUncertaintyDropDown.Items = {'Use Fixed Model', 'MC Sample Over Prior'};
            app.ModelUncertaintyDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelUncertaintyDropDownValueChanged, true);
            app.ModelUncertaintyDropDown.Value = 'Use Fixed Model';
            app.HoldOnCheckBox = uicheckbox(app.FisherInformationTab);
            app.HoldOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldOnCheckBoxValueChanged, true);
            app.HoldOnCheckBox.Text = 'Hold On';
            app.DataLoadingandFittingTab = uitab(app.TabGroup);
            app.DataLoadingandFittingTab.Tooltip = {'Use this tab to load experimental data and attempt to fit your model to these data.'};
            app.DataLoadingandFittingTab.Title = 'Data Loading and Fitting';
            app.LoadandConstrainDataButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.LoadandConstrainDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandConstrainDataButtonPushed, true);
            app.LoadandConstrainDataButton.Text = 'Load and Constrain Data ';
            app.fit_parameters_table = uitable(app.DataLoadingandFittingTab);
            app.fit_parameters_table.ColumnName = {'Name'; 'Value'; 'Fit? (y/n)'};
            app.fit_parameters_table.RowName = {};
            app.fit_parameters_table.ColumnEditable = [false true true];
            app.DecreaseFontSizeData = uibutton(app.DataLoadingandFittingTab, 'push');
            app.DecreaseFontSizeData.ButtonPushedFcn = createCallbackFcn(app, @DecreaseFontRxnPushed, true);
            app.DecreaseFontSizeData.Visible = 'off';
            app.DecreaseFontSizeData.Tooltip = {'Decrease global font size'};
            app.DecreaseFontSizeData.Text = '-';
            app.IncreaseFontSizeData = uibutton(app.DataLoadingandFittingTab, 'push');
            app.IncreaseFontSizeData.ButtonPushedFcn = createCallbackFcn(app, @IncreaseFontRxnPushed, true);
            app.IncreaseFontSizeData.Visible = 'off';
            app.IncreaseFontSizeData.Tooltip = {'Increase global font size'};
            app.IncreaseFontSizeData.Text = '+';
            app.FontSizeLabel_6 = uilabel(app.DataLoadingandFittingTab);
            app.FontSizeLabel_6.Visible = 'off';
            app.FontSizeLabel_6.Text = {'Font Size'; ''};
            app.SpeciestoshowinplotsPanel = uipanel(app.DataLoadingandFittingTab);
            app.SpeciestoshowinplotsPanel.Title = 'Species to show in plots';
            app.SpeciestoshowinplotsPanel.Visible = 'off';
            app.SpeciesForFitPlot = uilistbox(app.SpeciestoshowinplotsPanel);
            app.SpeciesForFitPlot.Items = {'x1', 'x2', 'x3'};
            app.SpeciesForFitPlot.Multiselect = 'on';
            app.SpeciesForFitPlot.Value = {'x1'};
            app.DataLoadingPanel = uipanel(app.DataLoadingandFittingTab);
            app.DataLoadingPanel.Title = 'Data Loading ';
            app.ParEstLoadDataButton = uibutton(app.DataLoadingPanel, 'push');
            app.ParEstLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ParEstLoadDataButtonPushed, true);
            app.ParEstLoadDataButton.Text = 'Load Data';
            app.TimesforFITTINGLabel = uilabel(app.DataLoadingandFittingTab);
            app.TimesforFITTINGLabel.Text = 'Times for FITTING';
            app.ParEstFitTimesList = uilistbox(app.DataLoadingandFittingTab);
            app.ParEstFitTimesList.Multiselect = 'on';
            app.ParEstFitTimesList.ValueChangedFcn = createCallbackFcn(app, @ParEstFitTimesListValueChanged, true);
            app.ParEstFitTimesList.Value = {'Item 1'};
            app.selectallButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.selectallButton.ButtonPushedFcn = createCallbackFcn(app, @selectallButtonPushed, true);
            app.selectallButton.Text = 'select all';
            app.ParameterGuessesandFitsLabel = uilabel(app.DataLoadingandFittingTab);
            app.ParameterGuessesandFitsLabel.Text = 'Parameter Guesses and Fits';
            app.FitModelButton = uibutton(app.DataLoadingandFittingTab, 'state');
            app.FitModelButton.ValueChangedFcn = createCallbackFcn(app, @FitModelButtonValueChanged, true);
            app.FitModelButton.Text = 'Fit Model';
            app.FittingAlgorithmDropDownLabel = uilabel(app.DataLoadingandFittingTab);
            app.FittingAlgorithmDropDownLabel.Text = 'Fitting Algorithm';
            app.FittingAlgorithmDropDown = uidropdown(app.DataLoadingandFittingTab);
            app.FittingAlgorithmDropDown.Items = {'fminsearch', 'MetropolisHastings'};
            app.FittingAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingAlgorithmDropDownValueChanged, true);
            app.FittingAlgorithmDropDown.Value = 'fminsearch';
            app.ParameterFittingandUncertaintyQuantificationLabel = uilabel(app.DataLoadingandFittingTab);
            app.ParameterFittingandUncertaintyQuantificationLabel.Text = 'Parameter Fitting and Uncertainty Quantification';
            app.SettingsButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.GraphPosteriorDistributionsButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.GraphPosteriorDistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphPosteriorDistributionsButtonPushed, true);
            app.GraphPosteriorDistributionsButton.Enable = 'off';
            app.GraphPosteriorDistributionsButton.Text = 'Graph Posterior Distributions';
            app.SuppressFSPExpansionfasterbutmaybelessaccurateCheckBox = uicheckbox(app.DataLoadingandFittingTab);
            app.SuppressFSPExpansionfasterbutmaybelessaccurateCheckBox.Text = 'Suppress FSP Expansion (faster but may be less accurate)';
            app.SetPriorButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.SetPriorButton.ButtonPushedFcn = createCallbackFcn(app, @SetPriorButtonPushed, true);
            app.SetPriorButton.Text = 'Set Prior';
            app.PriorTypeDropDownLabel = uilabel(app.DataLoadingandFittingTab);
            app.PriorTypeDropDownLabel.Text = 'Prior Type';
            app.PriorTypeDropDown = uidropdown(app.DataLoadingandFittingTab);
            app.PriorTypeDropDown.Items = {'None', 'LogNormal', 'Normal', ''};
            app.PriorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PriorTypeDropDownValueChanged, true);
            app.PriorTypeDropDown.Value = 'None';
            app.LinkSpeciesLabel = uilabel(app.DataLoadingandFittingTab);
            app.LinkSpeciesLabel.Text = 'Link Species';
            app.DataSpecies1 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies1.Items = {'-'};
            app.DataSpecies1.Value = '-';
            app.Species1Label = uilabel(app.DataLoadingandFittingTab);
            app.Species1Label.Text = 'Species 1';
            app.DataSpecies2 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies2.Items = {'-'};
            app.DataSpecies2.Value = '-';
            app.Species2Label = uilabel(app.DataLoadingandFittingTab);
            app.Species2Label.Text = 'Species 2';
            app.DataSpecies3 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies3.Items = {'-'};
            app.DataSpecies3.Value = '-';
            app.Species3Label = uilabel(app.DataLoadingandFittingTab);
            app.Species3Label.Text = 'Species 3';
            app.DataSpecies4 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies4.Items = {'-'};
            app.DataSpecies4.Value = '-';
            app.Species4Label = uilabel(app.DataLoadingandFittingTab);
            app.Species4Label.Text = 'Species 4';
            app.DataSpecies5 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies5.Items = {'-'};
            app.DataSpecies5.Value = '-';
            app.Species5Label = uilabel(app.DataLoadingandFittingTab);
            app.Species5Label.Text = 'Species 5';
            app.DataSpecies6 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies6.Items = {'-'};
            app.DataSpecies6.Value = '-';
            app.Species6Label = uilabel(app.DataLoadingandFittingTab);
            app.Species6Label.Text = 'Species 6';
            app.DataSpecies7 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies7.Items = {'-'};
            app.DataSpecies7.Value = '-';
            app.Species7Label = uilabel(app.DataLoadingandFittingTab);
            app.Species7Label.Text = 'Species 7';
            app.DataTime = uidropdown(app.DataLoadingandFittingTab);
            app.LinkTimeLabel = uilabel(app.DataLoadingandFittingTab);
            app.LinkTimeLabel.Text = 'Link time';
            app.ConstrainDataLabel = uilabel(app.DataLoadingandFittingTab);
            app.ConstrainDataLabel.Text = 'Constrain Data';
            app.DataConstrChoice1 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice1.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical1 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical1.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical1.Value = 'is';
            app.DataConstrText1 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.AndOr2 = uidropdown(app.DataLoadingandFittingTab);
            app.AndOr2.Items = {'-', 'and', 'or'};
            app.AndOr2.Value = '-';
            app.DataConstrChoice2 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice2.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical2 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical2.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical2.Value = 'is';
            app.DataConstrText2 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.AndOr3 = uidropdown(app.DataLoadingandFittingTab);
            app.AndOr3.Items = {'-', 'and', 'or'};
            app.AndOr3.Value = '-';
            app.DataConstrChoice3 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice3.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical3 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical3.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical3.Value = 'is';
            app.DataConstrText3 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.AndOr4 = uidropdown(app.DataLoadingandFittingTab);
            app.AndOr4.Items = {'-', 'and', 'or'};
            app.AndOr4.Value = '-';
            app.DataConstrChoice4 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice4.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical4 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical4.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical4.Value = 'is';
            app.DataConstrText4 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.AndOr5 = uidropdown(app.DataLoadingandFittingTab);
            app.AndOr5.Items = {'-', 'and', 'or'};
            app.AndOr5.Value = '-';
            app.DataConstrChoice5 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice5.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical5 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical5.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical5.Value = 'is';
            app.DataConstrText5 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.AndOr6 = uidropdown(app.DataLoadingandFittingTab);
            app.AndOr6.Items = {'-', 'and', 'or'};
            app.AndOr6.Value = '-';
            app.DataConstrChoice6 = uidropdown(app.DataLoadingandFittingTab);
            app.DataConstrChoice6.ValueChangedFcn = createCallbackFcn(app, @DataConstrChoice1ValueChanged, true);
            app.DataLogical6 = uidropdown(app.DataLoadingandFittingTab);
            app.DataLogical6.Items = {'is', 'isnot', '>', '<', '>=', '<='};
            app.DataLogical6.Value = 'is';
            app.DataConstrText6 = uieditfield(app.DataLoadingandFittingTab, 'text');
            app.ClearConstraintsButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.ClearConstraintsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConstraintsButtonPushed, true);
            app.ClearConstraintsButton.Text = 'Clear Constraints';
            app.FieldsinDataTextAreaLabel = uilabel(app.DataLoadingandFittingTab);
            app.FieldsinDataTextAreaLabel.Text = 'Fields in Data';
            app.FieldsinDataTextArea = uitextarea(app.DataLoadingandFittingTab);
            app.FieldsinDataTextArea.Value = {'None - need to load data first.'};
            app.TotalCellsInDataLabel = uilabel(app.DataLoadingandFittingTab);
            app.TotalCellsInDataLabel.Text = 'Total Cells In Data:';
            app.NumberafterConstraintsLabel = uilabel(app.DataLoadingandFittingTab);
            app.NumberafterConstraintsLabel.Text = 'Number after Constraints:';
            app.SolveandPlotButton = uibutton(app.DataLoadingandFittingTab, 'push');
            app.SolveandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SolveandPlotButtonPushed, true);
            app.SolveandPlotButton.Text = 'Solve and Plot';
            app.DataSpecies8 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies8.Items = {'-'};
            app.DataSpecies8.Value = '-';
            app.Species8Label = uilabel(app.DataLoadingandFittingTab);
            app.Species8Label.Text = 'Species 8';
            app.DataSpecies9 = uidropdown(app.DataLoadingandFittingTab);
            app.DataSpecies9.Items = {'-'};
            app.DataSpecies9.Value = '-';
            app.Species9Label = uilabel(app.DataLoadingandFittingTab);
            app.Species9Label.Text = 'Species 9';
            app.DataFileNoneSelectedLabel = uilabel(app.DataLoadingandFittingTab);
            app.DataFileNoneSelectedLabel.Text = 'Data File: <None Selected>';
            app.ModelSaveButton = uibutton(app.RightPanel, 'push');
            app.ModelSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveButtonPushed, true);
            app.ModelSaveButton.Tooltip = {'Press this button to save your current model and parameters in a new file or model name.'};
            app.ModelSaveButton.Text = 'Save Model';
            app.ModelSaveButton_2 = uibutton(app.RightPanel, 'push');
            app.ModelSaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveButton_2Pushed, true);
            app.ModelSaveButton_2.Tooltip = {'Press this button to save using the existing file and model names to the right.'};
            app.ModelSaveButton_2.Text = 'Quick Save';
            app.FileModelLabel = uilabel(app.RightPanel);
            app.FileModelLabel.Text = {'File:'; 'Model:'};
            app.IncludeGUIDatainSaveSwitchLabel = uilabel(app.RightPanel);
            app.IncludeGUIDatainSaveSwitchLabel.Text = 'Include GUI Data in Save';
            app.IncludeGUIDatainSaveSwitch = uiswitch(app.RightPanel, 'slider');
            app.IncludeGUIDatainSaveSwitch.Tooltip = {'Including GUI data in save takes longer and more space to save, but will lead to faster and easier launch times.'};
            app.IncludeGUIDatainSaveSwitch.Value = 'On';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SSITLOGO.jpg');
            app.FitinBackgroundmaclinuxonlyButton = uibutton(app.UIFigure, 'state');
            app.FitinBackgroundmaclinuxonlyButton.ValueChangedFcn = createCallbackFcn(app, @FitinBackgroundmaclinuxonlyButtonValueChanged, true);
            app.FitinBackgroundmaclinuxonlyButton.Visible = 'off';
            app.FitinBackgroundmaclinuxonlyButton.Text = 'Fit in Background (mac/linux only)';
            app.RunFitsinBackgroundLabel = uilabel(app.UIFigure);
            app.RunFitsinBackgroundLabel.Visible = 'off';
            app.RunFitsinBackgroundLabel.Text = 'Run Fits in Background';
            app.LoadPreviousResultsDropDownLabel = uilabel(app.UIFigure);
            app.LoadPreviousResultsDropDownLabel.Visible = 'off';
            app.LoadPreviousResultsDropDownLabel.Text = 'Load Previous Results';
            app.LoadPreviousResultsDropDown = uidropdown(app.UIFigure);
            app.LoadPreviousResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadPreviousResultsDropDownValueChanged, true);
            app.LoadPreviousResultsDropDown.Visible = 'off';
            app.RefreshResultsButton = uibutton(app.UIFigure, 'state');
            app.RefreshResultsButton.ValueChangedFcn = createCallbackFcn(app, @RefreshResultsButtonValueChanged, true);
            app.RefreshResultsButton.Visible = 'off';
            app.RefreshResultsButton.Text = 'Refresh Results';
            app.FitResultsNameLabel = uilabel(app.UIFigure);
            app.FitResultsNameLabel.Visible = 'off';
            app.FitResultsNameLabel.Text = 'Fit Results Name:';
            app.FitResultsNameText = uilabel(app.UIFigure);
            app.FitResultsNameText.Visible = 'off';
            app.FitResultsNameText.Text = 'Untitled';
            app.LoadPreviousProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadPreviousProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ModelLoadButtonPushed, true);
            app.LoadPreviousProjectButton.Text = 'Load Previous Project';
            app.loadapreviouscombinationLabel = uilabel(app.UIFigure);
            app.loadapreviouscombinationLabel.Text = '<-- load a previous combination';
            app.ofmodelanddataLabel = uilabel(app.UIFigure);
            app.ofmodelanddataLabel.Text = ' of model and data.';
            app.LaunchMultiDataFittingToolButton = uibutton(app.UIFigure, 'push');
            app.LaunchMultiDataFittingToolButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMultiDataFittingToolButtonPushed, true);
            app.LaunchMultiDataFittingToolButton.Visible = 'off';
            app.LaunchMultiDataFittingToolButton.Text = 'Launch Multi-Data Fitting Tool';
            app.ChooseSSITModel_2 = uidropdown(app.UIFigure);
            app.ChooseSSITModel_2.Items = {'Other'};
            app.ChooseSSITModel_2.ValueChangedFcn = createCallbackFcn(app, @ChooseSSITModel_2ValueChanged, true);
            app.ChooseSSITModel_2.Visible = 'off';
            app.ChooseSSITModel_2.Value = 'Other';
            app.ChooseSSITModelLabel_2 = uilabel(app.UIFigure);
            app.ChooseSSITModelLabel_2.Visible = 'off';
            app.ChooseSSITModelLabel_2.Text = 'Choose SSIT Model';
            app.CreateNewNStateModelButton = uibutton(app.UIFigure, 'push');
            app.CreateNewNStateModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewNStateModelButtonPushed, true);
            app.CreateNewNStateModelButton.Enable = 'off';
            app.CreateNewNStateModelButton.Visible = 'off';
            app.CreateNewNStateModelButton.Text = 'Create New N-State Model';
            app.LaunchFSPModelReductionToolButton = uibutton(app.UIFigure, 'push');
            app.LaunchFSPModelReductionToolButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchFSPModelReductionToolButtonPushed, true);
            app.LaunchFSPModelReductionToolButton.Enable = 'off';
            app.LaunchFSPModelReductionToolButton.Visible = 'off';
            app.LaunchFSPModelReductionToolButton.Text = 'Launch FSP Model Reduction Tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSITGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end