classdef SSITMergeModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FittingResultsPanel             matlab.ui.container.Panel
        ParEstPlotTimesDropDown_2       matlab.ui.control.DropDown
        TimeatwhichtoSHOWplotLabel_2    matlab.ui.control.Label
        ParEstPlotTimesDropDown         matlab.ui.control.DropDown
        TimeatwhichtoSHOWplotLabel      matlab.ui.control.Label
        MergedModelDropDown2            matlab.ui.control.DropDown
        MergedModelDropDown_2Label      matlab.ui.control.Label
        MergedModelDropDown             matlab.ui.control.DropDown
        MergedModelDropDownLabel        matlab.ui.control.Label
        data_histogram_plot             matlab.ui.control.UIAxes
        data_histogram_plot2            matlab.ui.control.UIAxes
        ParameterFittingandUncertaintyQuantificationPanel  matlab.ui.container.Panel
        SuppressFSPExpansion            matlab.ui.control.CheckBox
        RefreshResultsButton            matlab.ui.control.Button
        FitinBackgroundmaclinuxonlyButton  matlab.ui.control.Button
        PriorTypeDropDown               matlab.ui.control.DropDown
        PriorTypeDropDownLabel          matlab.ui.control.Label
        LoadPreviousResultsDropDown     matlab.ui.control.DropDown
        LoadPreviousResultsDropDownLabel  matlab.ui.control.Label
        FittingAlgorithmDropDown        matlab.ui.control.DropDown
        FittingAlgorithmDropDownLabel   matlab.ui.control.Label
        SpecifyPrioronParametersButton  matlab.ui.control.Button
        GraphPosteriorDistributionsButton  matlab.ui.control.Button
        RunFitsinBackgroundLabel        matlab.ui.control.Label
        SettingsButton                  matlab.ui.control.Button
        FitModelButton                  matlab.ui.control.StateButton
        fit_parameters_table            matlab.ui.control.Table
        ParameterGuessesandFitsLabel    matlab.ui.control.Label
        ProjectSpecificationPanel       matlab.ui.container.Panel
        AddModel                        matlab.ui.control.Button
        ModelDataSelectedforMergedModelLabel  matlab.ui.control.Label
        ModelList                       matlab.ui.control.TextArea
        ProjectName                     matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        SaveProject                     matlab.ui.control.Button
        NewMergeProject                 matlab.ui.control.Button
        LoadMergeProject                matlab.ui.control.Button
        SilimarityTableLabel            matlab.ui.control.Label
        MergeModelConstraints           matlab.ui.control.Table
        MergeModels                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        ProjectFileInfo = struct(...
            'ProjName','UntitledMergedProject',...
            'fn',[],...
            'pn',[],...
            'fullFileName',[]);
        
        SSITapp = []; % Description
        
        DataLoadingAndFittingTabOutputs= struct( ...
            'boundIndex', [], ... % Stores the previous length of bounds to check if something needs to be deleted
            'S_Params', [],... % Stores the parameters function of the Sensitivity analysis has '.nm' for name and '.va' for value
            'S_Inputs', [],... % Stores the new values for the time-varying functions of the Sensitivity analysis has '.nm' for name and '.va' for value
            'S_logical', [],... % Stores a logical vector of which parameters and inputs are included in the Sensitivity analysis
            'S_Propens', [],... % Stores the propensity functions of the Sensitivity Analysis evaluated at the specified values
            'J_LogLk', [],...
            'dataTable', [],... % Table of data loaded by the user.
            'fittingOptions', [],...
            'dataMatrix', [],... % all data loaded by user in matrix format.
            'filteredDataMatrix', [],... % data matrix after filtering for chosen conditions.
            'dataTensor', [] ,... % data in tensor format (Time, x1, x2, x3)
            'fitResults', [] ,...
            'fitOptions', [] ,...    % options for fitting codes (structure depends on optimizer).
            'priorOptions', [], ...  % options for prior (structure depends on the prior type)
            'backgroundFitDir','BackgroundFits',...
            'backgroundFitFile','Untitled',...
            'mergeFitResults',[])
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SSITapp)
            app.SSITapp = SSITapp;
        end

        % Button pushed function: NewMergeProject
        function NewMergeProjectButtonPushed(app, event)
            ssit.parest.ModelMergeInitiate(app,app.SSITapp);
        end

        % Button pushed function: AddModel
        function AddModelButtonPushed(app, event)
            ssit.parest.ModelMergeAdd(app,app.SSITapp);
        end

        % Button pushed function: MergeModels
        function MergeModelsButtonPushed(app, event)
            ssit.parest.ModelMergeMerge(app,app.SSITapp)
        end

        % Value changed function: PriorTypeDropDown
        function PriorTypeDropDownValueChanged(app, event)
            ssit.parest.SetPriors(app);
        end

        % Button pushed function: SpecifyPrioronParametersButton
        function SpecifyPrioronParametersButtonPushed(app, event)
            ssit.parest.PropEditor(app.DataLoadingAndFittingTabOutputs.priorOptions,'props');
        end

        % Button pushed function: LoadMergeProject
        function LoadMergeProjectButtonPushed(app, event)
            ssit.parest.LoadProject(app,app.SSITapp);
        end

        % Button pushed function: SaveProject
        function SaveProjectButtonPushed(app, event)
            ssit.parest.SaveProject(app,app.SSITapp);
        end

        % Value changed function: FittingAlgorithmDropDown
        function FittingAlgorithmDropDownValueChanged(app, event)
            ssit.parest.changeFitType(app);
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            if isempty(app.DataLoadingAndFittingTabOutputs.fitOptions)
                ssit.parest.changeFitType(app);
            end
            ssit.parest.PropEditor(app.DataLoadingAndFittingTabOutputs.fitOptions,'props');
        end

        % Button pushed function: GraphPosteriorDistributionsButton
        function GraphPosteriorDistributionsButtonPushed(app, event)
            ssit.parest.FittingGraphs(app.DataLoadingAndFittingTabOutputs,app.ReactionsTabOutputs.parameters,'props');
        end

        % Callback function
        function FitinBackgroundButtonValueChanged(app, event)

        end

        % Button pushed function: FitinBackgroundmaclinuxonlyButton
        function FitinBackgroundmaclinuxonlyButtonPushed(app, event)
            fitFileStr = [app.ProjectFileInfo.ProjName,datestr(now,'yyyy_mm_dd_HH_MM_SS')];
            fitFileName = [app.ProjectFileInfo.pn,fitFileStr,'Settings.mat'];
            resultName = [app.ProjectFileInfo.pn,fitFileStr,'Results.mat'];
            ssit.parest.fitMergedModel2Data(app,app.SSITapp,fitFileName,[],true);
            ssit.parest.runBackgroundFit(fitFileName,resultName,true);
        end

        % Button pushed function: RefreshResultsButton
        function RefreshResultsButtonPushed(app, event)
            GG = dir(app.DataLoadingAndFittingTabOutputs.backgroundFitDir);
            J = contains({GG.name},'Results')&contains({GG.name},'.mat');
            fNames = {GG(J).name};
            app.LoadPreviousResultsDropDown.Items = {};
            app.LoadPreviousResultsDropDown.Items{1} = 'Select';
            app.LoadPreviousResultsDropDown.Items{2} = 'Best';
            app.DataLoadingAndFittingTabOutputs.completedFits = {};
            if isempty(fNames)
                warning('No Previous Fits in this Directory');
                return
            end
            for i=1:length(fNames)
                % This needs to be fixed to work with the merged models.
                load(['',app.DataLoadingAndFittingTabOutputs.backgroundFitDir,fNames{i},''],'Loc_app_str')
                app.LoadPreviousResultsDropDown.Items{i+2} = [fNames{i}(1:end-4),...
                    ' log(L) = ',sprintf('%0.8g',Loc_app_str.DataLoadingAndFittingTabOutputs.J_LogLk)];
                app.DataLoadingAndFittingTabOutputs.completedFits{i+2,1} = fNames{i};
                app.DataLoadingAndFittingTabOutputs.completedFits{i+2,2} = Loc_app_str.DataLoadingAndFittingTabOutputs.J_LogLk;
            end
            maxL = max([app.DataLoadingAndFittingTabOutputs.completedFits{2:end,2}]);
            app.LoadPreviousResultsDropDown.Items{2} = ['Select Best: log(L) =',sprintf('%0.8g',maxL)];
        end

        % Value changed function: LoadPreviousResultsDropDown
        function LoadPreviousResultsDropDownValueChanged(app, event)
            J = find(strcmp(app.LoadPreviousResultsDropDown.Items,app.LoadPreviousResultsDropDown.Value));
            if J==2
                [~,j] = max([app.DataLoadingAndFittingTabOutputs.completedFits{2:end,2}]);
                J = j+2;
            end
            choice = app.DataLoadingAndFittingTabOutputs.completedFits{J,1};
            ssit.parest.loadBackgroundFits(app,choice)
        end

        % Value changed function: FitModelButton
        function FitModelButtonValueChanged(app, event)
            fitFileStr = [app.ProjectFileInfo.ProjName,datestr(now,'yyyy_mm_dd_HH_MM_SS')];
            [FitResults] = ssit.parest.makeMergeModelPlots(app,app.SSITapp);       
            ssit.parest.fitMergedModel2Data(app,app.SSITapp,[],...
                [app.ProjectFileInfo.pn,fitFileStr]);
            if strcmp(app.FittingAlgorithmDropDown.Value,'Metropolis Hastings')
                set(app.GraphPosteriorDistributionsButton,'Enable','on');
            else
                set(app.GraphPosteriorDistributionsButton,'Enable','off');
            end
            app.ProjectFileInfo.fullFitName = [app.ProjectFileInfo.pn,fitFileStr];
            
            app.DataLoadingAndFittingTabOutputs.mergeFitResults=[];
            ssit.parest.makeMergeModelPlots(app,app.SSITapp);       
        end

        % Value changed function: MergedModelDropDown, 
        % ...and 3 other components
        function ParEstPlotTimesDropDownValueChanged(app, event)
            ssit.parest.makeMergeModelPlots(app,app.SSITapp);
        end

        % Cell edit callback: fit_parameters_table
        function fit_parameters_tableCellEdit(app, event)
            app.DataLoadingAndFittingTabOutputs.mergeFitResults=[];
        end

        % Value changed function: SuppressFSPExpansion
        function SuppressFSPExpansionValueChanged(app, event)
            ssit.parest.ModelMergeMerge(app,app.SSITapp,false,[],false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectSpecificationPanel = uipanel(app.UIFigure);
            app.ProjectSpecificationPanel.Title = 'Project Specification';
            app.MergeModels = uibutton(app.ProjectSpecificationPanel, 'push');
            app.MergeModels.ButtonPushedFcn = createCallbackFcn(app, @MergeModelsButtonPushed, true);
            app.MergeModels.Enable = 'off';
            app.MergeModels.Text = 'Generate Merged Model';
            app.MergeModelConstraints = uitable(app.ProjectSpecificationPanel);
            app.MergeModelConstraints.ColumnName = {'Parameter'; 'Similarity Constraint Strength'};
            app.MergeModelConstraints.RowName = {};
            app.MergeModelConstraints.ColumnEditable = [false true];
            app.MergeModelConstraints.Tooltip = {'Please enter the strength of constraints to be placed on parameters that are found in multiple models (use ''inf'' for parameters that must be fixed and ''0'' for parameters that are unconstrained).'};
            app.SilimarityTableLabel = uilabel(app.ProjectSpecificationPanel);
            app.SilimarityTableLabel.Text = 'Similarity Constraints on Shared Parameters';
            app.LoadMergeProject = uibutton(app.ProjectSpecificationPanel, 'push');
            app.LoadMergeProject.ButtonPushedFcn = createCallbackFcn(app, @LoadMergeProjectButtonPushed, true);
            app.LoadMergeProject.Text = 'Load Previous Multi-Model Project';
            app.NewMergeProject = uibutton(app.ProjectSpecificationPanel, 'push');
            app.NewMergeProject.ButtonPushedFcn = createCallbackFcn(app, @NewMergeProjectButtonPushed, true);
            app.NewMergeProject.Text = 'Create New Multi-Model Project';
            app.SaveProject = uibutton(app.ProjectSpecificationPanel, 'push');
            app.SaveProject.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectButtonPushed, true);
            app.SaveProject.Text = 'Save Multi-Model Project';
            app.ProjectNameEditFieldLabel = uilabel(app.ProjectSpecificationPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectName = uieditfield(app.ProjectSpecificationPanel, 'text');
            app.ProjectName.Value = 'Untitled';
            app.ModelList = uitextarea(app.ProjectSpecificationPanel);
            app.ModelDataSelectedforMergedModelLabel = uilabel(app.ProjectSpecificationPanel);
            app.ModelDataSelectedforMergedModelLabel.Text = 'Model/Data Selected for Merged Model:';
            app.AddModel = uibutton(app.ProjectSpecificationPanel, 'push');
            app.AddModel.ButtonPushedFcn = createCallbackFcn(app, @AddModelButtonPushed, true);
            app.AddModel.Text = 'Add Model/Data to Project';
            app.ParameterFittingandUncertaintyQuantificationPanel = uipanel(app.UIFigure);
            app.ParameterFittingandUncertaintyQuantificationPanel.Title = 'Parameter Fitting and Uncertainty Quantification';
            app.ParameterGuessesandFitsLabel = uilabel(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.ParameterGuessesandFitsLabel.Text = 'Parameter Guesses and Fits';
            app.fit_parameters_table = uitable(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.fit_parameters_table.ColumnName = {'Name'; 'Value'; 'Fit? (y/n)'};
            app.fit_parameters_table.RowName = {};
            app.fit_parameters_table.ColumnEditable = [false true true];
            app.fit_parameters_table.CellEditCallback = createCallbackFcn(app, @fit_parameters_tableCellEdit, true);
            app.FitModelButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'state');
            app.FitModelButton.ValueChangedFcn = createCallbackFcn(app, @FitModelButtonValueChanged, true);
            app.FitModelButton.Text = 'Fit Model';
            app.SettingsButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.RunFitsinBackgroundLabel = uilabel(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.RunFitsinBackgroundLabel.Text = 'Run Fits in Background';
            app.GraphPosteriorDistributionsButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'push');
            app.GraphPosteriorDistributionsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphPosteriorDistributionsButtonPushed, true);
            app.GraphPosteriorDistributionsButton.Enable = 'off';
            app.GraphPosteriorDistributionsButton.Text = 'Graph Posterior Distributions';
            app.SpecifyPrioronParametersButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'push');
            app.SpecifyPrioronParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyPrioronParametersButtonPushed, true);
            app.SpecifyPrioronParametersButton.Text = 'Specify Prior on Parameters';
            app.FittingAlgorithmDropDownLabel = uilabel(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.FittingAlgorithmDropDownLabel.Text = 'Fitting Algorithm';
            app.FittingAlgorithmDropDown = uidropdown(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.FittingAlgorithmDropDown.Items = {'fminsearch', 'particle swarm', 'simulated annealing', 'genetic algorithm', 'Metropolis Hastings'};
            app.FittingAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingAlgorithmDropDownValueChanged, true);
            app.FittingAlgorithmDropDown.Value = 'fminsearch';
            app.LoadPreviousResultsDropDownLabel = uilabel(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.LoadPreviousResultsDropDownLabel.Text = 'Load Previous Results';
            app.LoadPreviousResultsDropDown = uidropdown(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.LoadPreviousResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadPreviousResultsDropDownValueChanged, true);
            app.PriorTypeDropDownLabel = uilabel(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.PriorTypeDropDownLabel.Text = 'Prior Type';
            app.PriorTypeDropDown = uidropdown(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.PriorTypeDropDown.Items = {'None', 'LogNormal', 'Normal', ''};
            app.PriorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PriorTypeDropDownValueChanged, true);
            app.PriorTypeDropDown.Value = 'None';
            app.FitinBackgroundmaclinuxonlyButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'push');
            app.FitinBackgroundmaclinuxonlyButton.ButtonPushedFcn = createCallbackFcn(app, @FitinBackgroundmaclinuxonlyButtonPushed, true);
            app.FitinBackgroundmaclinuxonlyButton.Text = 'Fit in Background (mac/linux only)';
            app.RefreshResultsButton = uibutton(app.ParameterFittingandUncertaintyQuantificationPanel, 'push');
            app.RefreshResultsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshResultsButtonPushed, true);
            app.RefreshResultsButton.Text = 'Refresh Results';
            app.SuppressFSPExpansion = uicheckbox(app.ParameterFittingandUncertaintyQuantificationPanel);
            app.SuppressFSPExpansion.ValueChangedFcn = createCallbackFcn(app, @SuppressFSPExpansionValueChanged, true);
            app.SuppressFSPExpansion.Text = 'Suppress FSP Expansion (faster but may be less accurate)';
            app.FittingResultsPanel = uipanel(app.UIFigure);
            app.FittingResultsPanel.Title = 'Fitting Results:';
            app.data_histogram_plot2 = uiaxes(app.FittingResultsPanel);
            title(app.data_histogram_plot2, 'Marginal Distributions of Data')
            xlabel(app.data_histogram_plot2, 'number of molecules')
            ylabel(app.data_histogram_plot2, 'probability density')
            app.data_histogram_plot = uiaxes(app.FittingResultsPanel);
            title(app.data_histogram_plot, 'Marginal Distributions of Data')
            xlabel(app.data_histogram_plot, 'number of molecules')
            ylabel(app.data_histogram_plot, 'probability density')
            app.MergedModelDropDownLabel = uilabel(app.FittingResultsPanel);
            app.MergedModelDropDownLabel.Text = 'Merged-Model';
            app.MergedModelDropDown = uidropdown(app.FittingResultsPanel);
            app.MergedModelDropDown.Items = {'Default'};
            app.MergedModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ParEstPlotTimesDropDownValueChanged, true);
            app.MergedModelDropDown.Enable = 'off';
            app.MergedModelDropDown.Tooltip = {'Use this to select which model/data set to show in results.'};
            app.MergedModelDropDown.Value = 'Default';
            app.MergedModelDropDown_2Label = uilabel(app.FittingResultsPanel);
            app.MergedModelDropDown_2Label.Text = 'Merged-Model';
            app.MergedModelDropDown2 = uidropdown(app.FittingResultsPanel);
            app.MergedModelDropDown2.Items = {'Default'};
            app.MergedModelDropDown2.ValueChangedFcn = createCallbackFcn(app, @ParEstPlotTimesDropDownValueChanged, true);
            app.MergedModelDropDown2.Enable = 'off';
            app.MergedModelDropDown2.Tooltip = {'Use this to select which model/data set to show in results.'};
            app.MergedModelDropDown2.Value = 'Default';
            app.TimeatwhichtoSHOWplotLabel = uilabel(app.FittingResultsPanel);
            app.TimeatwhichtoSHOWplotLabel.Text = 'Time at which to SHOW plot:';
            app.ParEstPlotTimesDropDown = uidropdown(app.FittingResultsPanel);
            app.ParEstPlotTimesDropDown.Items = {'empty'};
            app.ParEstPlotTimesDropDown.ValueChangedFcn = createCallbackFcn(app, @ParEstPlotTimesDropDownValueChanged, true);
            app.ParEstPlotTimesDropDown.Value = 'empty';
            app.TimeatwhichtoSHOWplotLabel_2 = uilabel(app.FittingResultsPanel);
            app.TimeatwhichtoSHOWplotLabel_2.Text = 'Time at which to SHOW plot:';
            app.ParEstPlotTimesDropDown_2 = uidropdown(app.FittingResultsPanel);
            app.ParEstPlotTimesDropDown_2.Items = {'empty'};
            app.ParEstPlotTimesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParEstPlotTimesDropDownValueChanged, true);
            app.ParEstPlotTimesDropDown_2.Value = 'empty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSITMergeModels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end